classdef anova_prep_MRSI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Anova_Panel                 matlab.ui.container.Panel
        NameEditFieldLabel          matlab.ui.control.Label
        CancelButton                matlab.ui.control.Button
        ProceedButton               matlab.ui.control.Button
        Statistics_Image            matlab.ui.control.Image
        Toolbox_Image               matlab.ui.control.Image
        Preparation_Panel           matlab.ui.container.Panel
        Factor_name_A_EditField     matlab.ui.control.EditField
        Factor_name_B_EditField     matlab.ui.control.EditField
        ndFactornameEditFieldLabel  matlab.ui.control.Label
        stFactornameEditFieldLabel  matlab.ui.control.Label
        Ngroups_A_Spinner           matlab.ui.control.Spinner
        Ngroups_B_Spinner           matlab.ui.control.Spinner
        NgroupsSpinner_2Label       matlab.ui.control.Label
        NgroupsSpinnerLabel         matlab.ui.control.Label
        factor_A_Button             matlab.ui.control.StateButton
        factor_B_Button             matlab.ui.control.StateButton
        GroupEditFieldLabel         matlab.ui.control.Label
        Anova_Label                 matlab.ui.control.Label
        Previous_Button             matlab.ui.control.Button
        GroupEditField              matlab.ui.control.NumericEditField
        Next_Button                 matlab.ui.control.Button
        Group_name_EditField        matlab.ui.control.EditField
        UITable                     matlab.ui.control.Table
    end

    
    properties (Access = private)
        StatisticsApp                           % Statistics app, mainapp
        prog_d                                  % StatisticsApp waiting
        Table_study                             % Study table to process ANOVA stats
        base_colors                             % Initial groups colors
        colors_factor_A = {[172,240,164]/255}   % Colors of group A
        colors_factor_B = {[172,240,164]/255}   % Colors of group B
        group_factor_A                          % Group number for factor A
        group_factor_B                          % Group number for factor B
        names_group_factor_A = {'Group 1'}      % Name of the groups for factor A
        names_group_factor_B = {'Group 1'}      % Name of the groups for factor B
    end
    
    methods (Access = private)
        
        % Load statistics study, name, region and side
        function table_study = load_stat_struct(app,stat_struct)
            study_name = {stat_struct.study_name}.';
            region = {stat_struct.region}.';
            side = {stat_struct.side}.';
            name = {stat_struct.name}.';
            table_study = table(study_name,region,side,name, ...
                'VariableNames',{'Rat study','Region','Side','Name'});
            app.Table_study = table_study;
        end
        
        % Update Previous and Next Buttons
        function update_group(app)
            if or(app.factor_A_Button.Value,app.factor_B_Button.Value)
                app.Next_Button.Enable = "on";
            else
                app.Next_Button.Enable = "off";
            end
            if and(app.factor_A_Button.Value, ...
                    app.GroupEditField.Value == app.Ngroups_A_Spinner.Value)
                app.Next_Button.Enable = 'off';
            elseif and(app.factor_B_Button.Value, ...
                    app.GroupEditField.Value == app.Ngroups_B_Spinner.Value)
                app.Next_Button.Enable = 'off';
            end
            if app.GroupEditField.Value == 1
                app.Previous_Button.Enable = 'off';
            end
        end
        
        % Apply a first filtering of the data between different groups
        function first_filtering(app)
            % Factor 1/A
            fac1 = app.Table_study{:,1};
            [grp_fac1, id_fac1] = findgroups(fac1);
            app.group_factor_A = grp_fac1;
            app.names_group_factor_A = id_fac1;
            app.Ngroups_A_Spinner.Value = length(id_fac1);
            while length(app.colors_factor_A) < length(id_fac1)
                l = length(app.colors_factor_A);
                if l < length(app.base_colors)
                    app.colors_factor_A{l+1} = app.base_colors{l+1}/255;
                else
                    app.colors_factor_A{end+1} = [randi(156,1) + 99,randi(156,1) + 99,randi(156,1) + 99]/255;
                end
            end
            % Factor 2/B
            fac2 = app.Table_study{:,2};
            [grp_fac2, id_fac2] = findgroups(fac2);
            app.group_factor_B = grp_fac2;
            app.names_group_factor_B = id_fac2;
            app.Ngroups_B_Spinner.Value = length(id_fac2);
            while length(app.colors_factor_B) < length(id_fac2)
                l = length(app.colors_factor_B);
                if l < length(app.base_colors)
                    app.colors_factor_B{l+1} = app.base_colors{l+1}/255;
                else
                    app.colors_factor_B{end+1} = [randi(156,1) + 99,randi(156,1) + 99,randi(156,1) + 99]/255;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, statapp, stat_struct, prog_d)
            app.StatisticsApp = statapp;
            app.prog_d = prog_d;
            app.base_colors = {[172,240,164],[220,245,251],[114,181,182]};
            app.Table_study = app.load_stat_struct(stat_struct);
            app.UITable.Data = app.Table_study;
            app.group_factor_A = ones(size(app.Table_study,1),1);
            app.group_factor_B = ones(size(app.Table_study,1),1);
            app.first_filtering();
            app.UITable.RowName = "numbered";
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            if ~isempty(indices)
                idx_row = indices(1);
                if app.factor_A_Button.Value
                    app.group_factor_A(idx_row) = app.GroupEditField.Value;
                    for i = 1:size(app.Table_study,1)
                        s = uistyle('BackgroundColor',app.colors_factor_A{app.group_factor_A(i)});
                        addStyle(app.UITable,s,'row',i)
                    end
                elseif app.factor_B_Button.Value
                    app.group_factor_B(idx_row) = app.GroupEditField.Value;
                    for i = 1:size(app.Table_study,1)
                        s = uistyle('BackgroundColor',app.colors_factor_B{app.group_factor_B(i)});
                        addStyle(app.UITable,s,'row',i)
                    end
                else
                    if ~isempty(app.UITable.StyleConfigurations)
                        app.UITable.removeStyle(1:size(app.UITable.StyleConfigurations,1))
                    end
                end
                myTable = app.UITable.Data;
                temp = {'DUMMY'};
                app.UITable.Data = temp;
                app.UITable.Data = myTable;
            end
        end

        % Value changed function: factor_A_Button
        function factor_A_ButtonValueChanged(app, event)
            value = app.factor_A_Button.Value;
            if app.factor_B_Button.Value
                app.factor_B_Button.Value = 0;
                app.GroupEditField.Value = 1;
                app.Previous_Button.Enable = 'off';
                app.Next_Button.Enable = 'off';
            end
            app.update_group()
            if value
                app.Group_name_EditField.Editable = 'on';
                app.Group_name_EditField.Enable = 'on';
                app.Group_name_EditField.Value = app.names_group_factor_A{app.GroupEditField.Value};
                for i = 1:size(app.Table_study,1)
                    s = uistyle('BackgroundColor',app.colors_factor_A{app.group_factor_A(i)});
                    addStyle(app.UITable,s,'row',i)
                end
            else
                app.GroupEditField.Value = 1;
                app.Group_name_EditField.Editable = 'off';
                app.Group_name_EditField.Enable = 'off';
                app.Group_name_EditField.Value = '';
                if ~isempty(app.UITable.StyleConfigurations)
                    app.UITable.removeStyle(1:size(app.UITable.StyleConfigurations,1))
                end
            end
        end

        % Value changed function: Ngroups_A_Spinner
        function Ngroups_A_SpinnerValueChanged(app, event)
            value = app.Ngroups_A_Spinner.Value;
            app.update_group();
            if value > size(app.Table_study,1)
                while length(app.colors_factor_A) ~= app.Ngroups_A_Spinner.Value
                    app.Ngroups_A_Spinner.Value = app.Ngroups_A_Spinner.Value - 1;
                end
            else
                if length(app.colors_factor_A) < value
                    while length(app.colors_factor_A) < value
                        app.names_group_factor_A{end+1} = ['Group ',num2str(length(app.colors_factor_A)+1)];
                        l = length(app.colors_factor_A);
                        if l < length(app.base_colors)
                            app.colors_factor_A{l+1} = app.base_colors{l+1}/255;
                        else
                            app.colors_factor_A{end+1} = [randi(156,1) + 99,randi(156,1) + 99,randi(156,1) + 99]/255;
                        end
                    end
                else
                    while length(app.colors_factor_A) ~= value
                        app.names_group_factor_A(end) = [];
                        app.colors_factor_A(end) = [];
                    end
                    idx_groups = find(app.group_factor_A > value);
                    app.group_factor_A(idx_groups) = 1;
                end
            end
            assignin("base",'colors_f1',app.colors_factor_A)
        end

        % Value changed function: factor_B_Button
        function factor_B_ButtonValueChanged(app, event)
            value = app.factor_B_Button.Value;
            if app.factor_A_Button.Value
                app.factor_A_Button.Value = 0;
                app.GroupEditField.Value = 1;
                app.Previous_Button.Enable = 'off';
                app.Next_Button.Enable = 'off';
            end
            app.update_group()
            if value
                app.Group_name_EditField.Editable = 'on';
                app.Group_name_EditField.Enable = 'on';
                app.Group_name_EditField.Value = app.names_group_factor_B{app.GroupEditField.Value};
                for i = 1:size(app.Table_study,1)
                    s = uistyle('BackgroundColor',app.colors_factor_B{app.group_factor_B(i)});
                    addStyle(app.UITable,s,'row',i)
                end
            else
                app.Group_name_EditField.Editable = 'off';
                app.Group_name_EditField.Enable = 'off';
                app.Group_name_EditField.Value = '';
                app.GroupEditField.Value = 1;
                if ~isempty(app.UITable.StyleConfigurations)
                    app.UITable.removeStyle(1:size(app.UITable.StyleConfigurations,1))
                end
            end
        end

        % Value changed function: Ngroups_B_Spinner
        function Ngroups_B_SpinnerValueChanged(app, event)
            value = app.Ngroups_B_Spinner.Value;
            app.update_group();
            if value > size(app.Table_study,1)
                while length(app.colors_factor_B) ~= app.Ngroups_B_Spinner.Value
                    app.Ngroups_B_Spinner.Value = app.Ngroups_B_Spinner.Value - 1;
                end
            else
                if length(app.colors_factor_B) < value
                    while length(app.colors_factor_B) < value
                        app.names_group_factor_B{end+1} = ['Group ',num2str(length(app.colors_factor_B)+1)];
                        l = length(app.colors_factor_B);
                        if l < length(app.base_colors)
                            app.colors_factor_B{l+1} = app.base_colors{l+1}/255;
                        else
                            app.colors_factor_B{end+1} = [randi(156,1) + 99,randi(156,1) + 99,randi(156,1) + 99]/255;
                        end
                    end
                else
                    while length(app.colors_factor_B) ~= value
                        app.names_group_factor_B(end) = [];
                        app.colors_factor_B(end) = [];
                    end
                    idx_groups = find(app.group_factor_B > value);
                    app.group_factor_B(idx_groups) = 1;
                end
            end
        end

        % Button pushed function: Previous_Button
        function Previous_ButtonPushed(app, event)
            app.GroupEditField.Value = app.GroupEditField.Value - 1;
            if app.factor_A_Button.Value
                app.Group_name_EditField.Value = app.names_group_factor_A{app.GroupEditField.Value};
            end
            if app.factor_B_Button.Value
                app.Group_name_EditField.Value = app.names_group_factor_B{app.GroupEditField.Value};
            end
            if and(app.factor_A_Button.Value, ...
                    app.Ngroups_A_Spinner.Value > 1)
                app.Next_Button.Enable = 'on';
            elseif and(app.factor_B_Button.Value, ...
                    app.Ngroups_B_Spinner.Value > 1)
                app.Next_Button.Enable = 'on';
            else
                app.Next_Button.Enable = 'off';
            end
            if app.GroupEditField.Value == 1
                app.Previous_Button.Enable = 'off';
            else
                app.Previous_Button.Enable = "on";
            end
        end

        % Button pushed function: Next_Button
        function Next_ButtonPushed(app, event)
            app.GroupEditField.Value = app.GroupEditField.Value + 1;
            if app.factor_A_Button.Value
                app.Group_name_EditField.Value = app.names_group_factor_A{app.GroupEditField.Value};
            end
            if app.factor_B_Button.Value
                app.Group_name_EditField.Value = app.names_group_factor_B{app.GroupEditField.Value};
            end
            app.Previous_Button.Enable = 'on';
            if and(app.factor_A_Button.Value, ...
                    app.GroupEditField.Value == app.Ngroups_A_Spinner.Value)
                app.Next_Button.Enable = 'off';
            elseif and(app.factor_B_Button.Value, ...
                    app.GroupEditField.Value == app.Ngroups_B_Spinner.Value)
                app.Next_Button.Enable = 'off';
            else
                app.Next_Button.Enable = "on";
            end
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            if ~or(isempty(app.Factor_name_A_EditField.Value),isempty(app.Factor_name_B_EditField.Value))
                conf = uiconfirm(app.UIFigure,'Do you want to still change your groups ?','ANOVA 2 ways', ...
                    'Options',{'Yes','No'},'DefaultOption','No');
                if strcmp(conf,'No')
                    % Update Statistics App public properties
                    app.StatisticsApp.ok_ANOVA = 1;
                    app.StatisticsApp.group_factor_A = app.group_factor_A;
                    app.StatisticsApp.group_factor_B = app.group_factor_B;
                    app.StatisticsApp.name_factor_A = app.Factor_name_A_EditField.Value;
                    app.StatisticsApp.name_factor_B = app.Factor_name_B_EditField.Value;
                    app.StatisticsApp.names_group_factor_A = app.names_group_factor_A;
                    app.StatisticsApp.names_group_factor_B = app.names_group_factor_B;
                    delete(app.prog_d)
                    delete(app)
                end
            else
                uialert(app.UIFigure,'Please give a name to each factor','ANOVA preparation','Icon','warning')
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.StatisticsApp.ok_ANOVA = 0;
            delete(app.prog_d)
            delete(app)
        end

        % Value changed function: Group_name_EditField
        function Group_name_EditFieldValueChanged(app, event)
            value = app.Group_name_EditField.Value;
            if app.factor_A_Button.Value
                app.names_group_factor_A{app.GroupEditField.Value} = value;
            else
                app.names_group_factor_B{app.GroupEditField.Value} = value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ANOVA statistics';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Stats.png');
            app.Anova_Panel = uipanel(app.UIFigure);
            app.UITable = uitable(app.Anova_Panel);
            app.UITable.ColumnName = {'Rat study'; 'Region'; 'Side'; 'Name'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UITable.Multiselect = 'off';
            app.Group_name_EditField = uieditfield(app.Anova_Panel, 'text');
            app.Group_name_EditField.ValueChangedFcn = createCallbackFcn(app, @Group_name_EditFieldValueChanged, true);
            app.Group_name_EditField.Enable = 'off';
            app.Next_Button = uibutton(app.Anova_Panel, 'push');
            app.Next_Button.ButtonPushedFcn = createCallbackFcn(app, @Next_ButtonPushed, true);
            app.Next_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'right_arrow.png');
            app.Next_Button.Enable = 'off';
            app.Next_Button.Text = '';
            app.GroupEditField = uieditfield(app.Anova_Panel, 'numeric');
            app.GroupEditField.Limits = [1 Inf];
            app.GroupEditField.RoundFractionalValues = 'on';
            app.GroupEditField.ValueDisplayFormat = '%.0f';
            app.GroupEditField.Editable = 'off';
            app.GroupEditField.Value = 1;
            app.Previous_Button = uibutton(app.Anova_Panel, 'push');
            app.Previous_Button.ButtonPushedFcn = createCallbackFcn(app, @Previous_ButtonPushed, true);
            app.Previous_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'left_arrow.png');
            app.Previous_Button.Enable = 'off';
            app.Previous_Button.Text = '';
            app.Anova_Label = uilabel(app.Anova_Panel);
            app.Anova_Label.Text = 'ANOVA statistics';
            app.GroupEditFieldLabel = uilabel(app.Anova_Panel);
            app.GroupEditFieldLabel.Text = 'Group';
            app.Preparation_Panel = uipanel(app.Anova_Panel);
            app.factor_B_Button = uibutton(app.Preparation_Panel, 'state');
            app.factor_B_Button.ValueChangedFcn = createCallbackFcn(app, @factor_B_ButtonValueChanged, true);
            app.factor_B_Button.Text = '2nd factor';
            app.factor_A_Button = uibutton(app.Preparation_Panel, 'state');
            app.factor_A_Button.ValueChangedFcn = createCallbackFcn(app, @factor_A_ButtonValueChanged, true);
            app.factor_A_Button.Text = '1st factor';
            app.NgroupsSpinnerLabel = uilabel(app.Preparation_Panel);
            app.NgroupsSpinnerLabel.Text = 'N groups';
            app.NgroupsSpinner_2Label = uilabel(app.Preparation_Panel);
            app.NgroupsSpinner_2Label.Text = 'N groups';
            app.Ngroups_B_Spinner = uispinner(app.Preparation_Panel);
            app.Ngroups_B_Spinner.Limits = [1 Inf];
            app.Ngroups_B_Spinner.RoundFractionalValues = 'on';
            app.Ngroups_B_Spinner.ValueDisplayFormat = '%.0f';
            app.Ngroups_B_Spinner.ValueChangedFcn = createCallbackFcn(app, @Ngroups_B_SpinnerValueChanged, true);
            app.Ngroups_B_Spinner.Value = 1;
            app.Ngroups_A_Spinner = uispinner(app.Preparation_Panel);
            app.Ngroups_A_Spinner.Limits = [1 Inf];
            app.Ngroups_A_Spinner.RoundFractionalValues = 'on';
            app.Ngroups_A_Spinner.ValueDisplayFormat = '%.0f';
            app.Ngroups_A_Spinner.ValueChangedFcn = createCallbackFcn(app, @Ngroups_A_SpinnerValueChanged, true);
            app.Ngroups_A_Spinner.Value = 1;
            app.stFactornameEditFieldLabel = uilabel(app.Preparation_Panel);
            app.stFactornameEditFieldLabel.Text = '1st Factor name';
            app.ndFactornameEditFieldLabel = uilabel(app.Preparation_Panel);
            app.ndFactornameEditFieldLabel.Text = '2nd Factor name';
            app.Factor_name_B_EditField = uieditfield(app.Preparation_Panel, 'text');
            app.Factor_name_B_EditField.Value = 'Region';
            app.Factor_name_A_EditField = uieditfield(app.Preparation_Panel, 'text');
            app.Factor_name_A_EditField.Value = 'Rat';
            app.Toolbox_Image = uiimage(app.Anova_Panel);
            app.Toolbox_Image.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Logo_MRS4Brain_toolbox_square_2.png');
            app.Statistics_Image = uiimage(app.Anova_Panel);
            app.Statistics_Image.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Stats.png');
            app.ProceedButton = uibutton(app.Anova_Panel, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Text = 'Proceed';
            app.CancelButton = uibutton(app.Anova_Panel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.NameEditFieldLabel = uilabel(app.Anova_Panel);
            app.NameEditFieldLabel.Enable = 'off';
            app.NameEditFieldLabel.Text = 'Name';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = anova_prep_MRSI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end