classdef conc_table_MRSI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        SaveButton               matlab.ui.control.Button
        SavenameEditField        matlab.ui.control.EditField
        SavenameEditFieldLabel   matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        ConcentrationtableLabel  matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
    end

    
    properties (Access = private)
        MainApp             % MRS4Brain toolbox mainapp
        all_results         % Results structure
        met_conc            % Concentration of each metabolite + statistics
        conc_type           % Type of concentration : Absolute or Relative
        save_name           % Name to save
        save_dir            % Directory to save
    end
    
    methods (Access = private)
        
        % Fill the results UITable
        function fill_table(app)
            if strcmp(app.conc_type,'Absolute')
                mean_conc = [app.met_conc.mean_conc_abs].';
                std_conc = [app.met_conc.std_conc_abs].';
            else
                mean_conc = [app.met_conc.mean_conc_rel].';
                std_conc = [app.met_conc.std_conc_rel].';
            end
            mean_conc = arrayfun(@(x) sprintf('%.2f',x),mean_conc,'un',0);
            std_conc = arrayfun(@(x) sprintf('%.2f',x),std_conc,'un',0);
            n_voxels = [app.met_conc.n_voxels].';
            conc_table_data = table({app.met_conc.met_names}.',mean_conc,std_conc,n_voxels,...
                'VariableNames',{'Metabolite','Mean','Std','N voxels'});
            app.UITable.Data = conc_table_data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, results, conc_type, save_dir)
            app.MainApp = mainapp;
            app.all_results = results;
            app.met_conc = results.met_conc;
            app.conc_type = conc_type;
            app.save_name = [results.region, '_', results.side,'_Slice_N',app.MainApp.MRSI_Slice_DropDown.Value];
            app.SavenameEditField.Value = app.save_name;
            app.save_dir = save_dir;
            app.fill_table();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.SavenameEditField.Value)
                uialert(app.UIFigure,'Please choose a name first', ...
                    'Concentration table','Icon','warning')
                return
            end
            results = app.all_results;
            save_filename = fullfile(app.save_dir,[app.SavenameEditField.Value,'.mat']);
            save(save_filename,"results");
        end

        % Value changed function: SavenameEditField
        function SavenameEditFieldValueChanged(app, event)
            app.save_name = app.SavenameEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MRS4Brain Toolbox';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'concentration_table.png');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Metabolite'; 'Mean'; 'Std'; 'N voxels'};
            app.UITable.RowName = {};
            app.ConcentrationtableLabel = uilabel(app.UIFigure);
            app.ConcentrationtableLabel.Text = 'Concentration table';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Logo_MRS4Brain_toolbox_square_2.png');
            app.Panel = uipanel(app.UIFigure);
            app.SavenameEditFieldLabel = uilabel(app.Panel);
            app.SavenameEditFieldLabel.Text = 'Save name';
            app.SavenameEditField = uieditfield(app.Panel, 'text');
            app.SavenameEditField.ValueChangedFcn = createCallbackFcn(app, @SavenameEditFieldValueChanged, true);
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = conc_table_MRSI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end