classdef custom_regions_MRSI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Image               matlab.ui.control.Image
        Panel               matlab.ui.container.Panel
        SaveButton          matlab.ui.control.Button
        DeselectallButton   matlab.ui.control.Button
        SelectallButton     matlab.ui.control.Button
        CustomregionsLabel  matlab.ui.control.Label
        UITable             matlab.ui.control.Table
    end

    
    properties (Access = private)
        MainApp     % MainApp name
        table       % Table with checkbox, regions and sides of brain
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
            app.UIFigure.Position(1) = app.MainApp.UIFigure.Position(1) + ...
                app.MainApp.UIFigure.Position(3);
            app.UIFigure.Position(2) = app.MainApp.UIFigure.Position(2);
            app.table = app.MainApp.t_custom_regions;
            app.UITable.Data = app.table;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.MainApp.MRSI_display_custom_regions();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainApp.t_custom_regions = app.table;
            app.MainApp.MRSI_display_custom_regions();
            delete(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            app.table = app.UITable.Data;
            app.MainApp.t_custom_regions = app.table;
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            select_all = true(size(app.table,1),1);
            app.table{:,1} = select_all;
            app.UITable.Data = app.table;
            app.MainApp.t_custom_regions = app.table;
        end

        % Button pushed function: DeselectallButton
        function DeselectallButtonPushed(app, event)
            deselect_all = false(size(app.table,1),1);
            app.table{:,1} = deselect_all;
            app.UITable.Data = app.table;
            app.MainApp.t_custom_regions = app.table;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MRS4Brain Toolbox';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Logo_MRS4Brain_toolbox_square_2.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'On/Off'; 'Brain region'; 'Side'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.CustomregionsLabel = uilabel(app.UIFigure);
            app.CustomregionsLabel.Text = 'Custom regions';
            app.Panel = uipanel(app.UIFigure);
            app.SelectallButton = uibutton(app.Panel, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Text = 'Select all';
            app.DeselectallButton = uibutton(app.Panel, 'push');
            app.DeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectallButtonPushed, true);
            app.DeselectallButton.Text = 'Deselect all';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Logo_MRS4Brain_toolbox_square_2.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = custom_regions_MRSI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end