classdef display_options_MRSI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LCModelQCOnlyforSNRCheckBox     matlab.ui.control.CheckBox
        InterpolationSwitch             matlab.ui.control.Switch
        InterpolationSwitchLabel        matlab.ui.control.Label
        MaxconcentrationEditField       matlab.ui.control.NumericEditField
        MaxconcentrationEditFieldLabel  matlab.ui.control.Label
        Max_concentration_Button        matlab.ui.control.StateButton
        QualitycontrolsLabel            matlab.ui.control.Label
        Quality_control_Panel           matlab.ui.container.Panel
        FWHM_th_DropDown                matlab.ui.control.DropDown
        Mean_FWHM_EditField             matlab.ui.control.NumericEditField
        MeanFWHMEditFieldLabel          matlab.ui.control.Label
        SNR_th_DropDown                 matlab.ui.control.DropDown
        Mean_SNR_EditField              matlab.ui.control.NumericEditField
        MeanSNREditFieldLabel           matlab.ui.control.Label
        CRLB_limit_EditField            matlab.ui.control.NumericEditField
        ThresholdEditField_2Label_2     matlab.ui.control.Label
        SNRCheckBox                     matlab.ui.control.CheckBox
        Threshold_SNR_EditField         matlab.ui.control.NumericEditField
        FWHMCheckBox                    matlab.ui.control.CheckBox
        Threshold_FWHM_EditField        matlab.ui.control.NumericEditField
        Settings_image                  matlab.ui.control.Image
        DisplaysettingsLabel            matlab.ui.control.Label
        Toolbox_logo                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        MainApp     % MainApp name
    end
    
    methods (Access = public)
        
        % Update the maximal concentration mode (automatic and manual)
        function max_conc_mode(app)
            value = app.Max_concentration_Button.Value;
            app.MainApp.disp_opt.auto_max_conc = app.Max_concentration_Button.Value;
            if value
                app.Max_concentration_Button.Text = {'Automatic max', 'concentration'};
                app.MaxconcentrationEditField.Enable = 'off';
            else
                app.Max_concentration_Button.Text = {'Manual max', 'concentration'};
                app.MaxconcentrationEditField.Enable = 'on';
            end
        end
        
        function load_disp_opt(app)
            % SNR & FWHM & CRLB limit
            app.SNRCheckBox.Value = app.MainApp.disp_opt.SNR_qc;
            app.FWHMCheckBox.Value = app.MainApp.disp_opt.FWHM_qc;
            if app.MainApp.disp_opt.SNR_th_bool
                app.SNR_th_DropDown.Value = app.SNR_th_DropDown.Items{1};
                app.Threshold_SNR_EditField.Value = app.MainApp.disp_opt.SNR_min_th;
            else
                app.SNR_th_DropDown.Value = app.SNR_th_DropDown.Items{2};
                app.Threshold_SNR_EditField.Value = app.MainApp.disp_opt.SNR_min_val;
            end
            if app.MainApp.disp_opt.FWHM_th_bool
                app.FWHM_th_DropDown.Value = app.FWHM_th_DropDown.Items{1};
                app.Threshold_FWHM_EditField.Value = app.MainApp.disp_opt.FWHM_max_th;
            else
                app.FWHM_th_DropDown.Value = app.FWHM_th_DropDown.Items{2};
                app.Threshold_FWHM_EditField.Value = app.MainApp.disp_opt.FWHM_max_val;
            end
            app.CRLB_limit_EditField.Value = app.MainApp.disp_opt.CRLB_limit;

            % Mean SNR & FWHM
            if(app.LCModelQCOnlyforSNRCheckBox.Value)
                app.MainApp.disp_opt.LCModel_qc = 1;
                app.Mean_SNR_EditField.Value = app.MainApp.MRSI_mean_SNR;
                app.Mean_FWHM_EditField.Value = app.MainApp.MRSI_mean_FWHM;
            else
                app.MainApp.disp_opt.LCModel_qc = 0;
                app.Mean_SNR_EditField.Value = app.MainApp.avg_SNR;
                app.Mean_FWHM_EditField.Value = app.MainApp.avg_Lw;
            end
            
            % Auto max concentration
            app.Max_concentration_Button.Value = app.MainApp.disp_opt.auto_max_conc;
            app.MaxconcentrationEditField.Value = app.MainApp.disp_opt.max_conc;

            % Interpolation
            if app.MainApp.disp_opt.interpol_bool 
                app.InterpolationSwitch.Value = 'On';
            else
                app.InterpolationSwitch.Value = 'Off';
            end
        end
    end
 

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
            app.UIFigure.Position(1) = app.MainApp.UIFigure.Position(1) + ...
                app.MainApp.UIFigure.Position(3);
            app.UIFigure.Position(2) = app.MainApp.UIFigure.Position(2) + ...
                app.MainApp.UIFigure.Position(4) - app.UIFigure.Position(4);
            app.load_disp_opt();
            app.max_conc_mode();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            disp_opt = app.MainApp.disp_opt;
            save(fullfile(app.MainApp.app_path,'Others','Display_options.mat'),'disp_opt')
            delete(app)
        end

        % Value changed function: Max_concentration_Button
        function Max_concentration_ButtonValueChanged(app, event)
            app.max_conc_mode();
            % Display MRSI
            if app.MainApp.MRSI_done_bool
                app.MainApp.display_MRSI();
            end
        end

        % Value changed function: CRLB_limit_EditField, FWHMCheckBox, 
        % ...and 6 other components
        function Display_options_changed(app, event)
            % SNR & FWHM & CRLB_limit
            app.MainApp.disp_opt.SNR_qc = app.SNRCheckBox.Value;
            app.MainApp.disp_opt.FWHM_qc = app.FWHMCheckBox.Value;
            old_qc_value = app.MainApp.disp_opt.LCModel_qc;
            app.MainApp.disp_opt.LCModel_qc = app.LCModelQCOnlyforSNRCheckBox.Value;
            if app.MainApp.disp_opt.SNR_th_bool
                app.MainApp.disp_opt.SNR_min_th = app.Threshold_SNR_EditField.Value;
            else
                app.MainApp.disp_opt.SNR_min_val = app.Threshold_SNR_EditField.Value;
            end
            if app.MainApp.disp_opt.FWHM_th_bool
                app.MainApp.disp_opt.FWHM_max_th = app.Threshold_FWHM_EditField.Value;
            else
                app.MainApp.disp_opt.FWHM_max_val = app.Threshold_FWHM_EditField.Value;
            end
            app.MainApp.disp_opt.CRLB_limit = app.CRLB_limit_EditField.Value;

            % Interpolation
            if strcmp(app.InterpolationSwitch.Value,'On')
                app.MainApp.disp_opt.interpol_bool = true;
            else
                app.MainApp.disp_opt.interpol_bool = false;
            end
            
            % Auto max concentration
            app.MainApp.disp_opt.auto_max_conc = app.Max_concentration_Button.Value;
            app.MainApp.disp_opt.max_conc = app.MaxconcentrationEditField.Value;

            % Display MRSI
            if app.MainApp.MRSI_done_bool
                app.MainApp.display_MRSI();
            end

            %Reload Disp_opt
            if (old_qc_value~=app.MainApp.disp_opt.LCModel_qc)
                app.load_disp_opt();
            end

        end

        % Value changed function: SNR_th_DropDown
        function SNR_th_DropDownValueChanged(app, event)
            if strcmp(app.SNR_th_DropDown.Value,app.SNR_th_DropDown.Items{1})
                app.MainApp.disp_opt.SNR_th_bool = true;
                app.Threshold_SNR_EditField.Value = app.MainApp.disp_opt.SNR_min_th;
            else
                app.MainApp.disp_opt.SNR_th_bool = false;
                app.Threshold_SNR_EditField.Value = app.MainApp.disp_opt.SNR_min_val;
            end

            % Display MRSI
            if app.MainApp.MRSI_done_bool
                app.MainApp.display_MRSI();
            end
        end

        % Value changed function: FWHM_th_DropDown
        function FWHM_th_DropDownValueChanged(app, event)
            if strcmp(app.FWHM_th_DropDown.Value,app.FWHM_th_DropDown.Items{1})
                app.MainApp.disp_opt.FWHM_th_bool = true;
                app.Threshold_FWHM_EditField.Value = app.MainApp.disp_opt.FWHM_max_th;
            else
                app.MainApp.disp_opt.FWHM_th_bool = false;
                app.Threshold_FWHM_EditField.Value = app.MainApp.disp_opt.FWHM_max_val;
            end

            % Display MRSI
            if app.MainApp.MRSI_done_bool
                app.MainApp.display_MRSI();
            end
        end

        % Callback function: not associated with a component
        function LCModelQCOnlyforSNRCheckBoxValueChanged(app, event)
            value = app.LCModelQCOnlyforSNRCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MRS4Brain Toolbox';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'gears.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Toolbox_logo = uiimage(app.UIFigure);
            app.Toolbox_logo.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Logo_MRS4Brain_toolbox_square_2.png');
            app.DisplaysettingsLabel = uilabel(app.UIFigure);
            app.DisplaysettingsLabel.Text = 'Display settings';
            app.Settings_image = uiimage(app.UIFigure);
            app.Settings_image.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'gears.png');
            app.Quality_control_Panel = uipanel(app.UIFigure);
            app.Threshold_FWHM_EditField = uieditfield(app.Quality_control_Panel, 'numeric');
            app.Threshold_FWHM_EditField.Limits = [0 Inf];
            app.Threshold_FWHM_EditField.ValueChangedFcn = createCallbackFcn(app, @Display_options_changed, true);
            app.Threshold_FWHM_EditField.Value = 1.25;
            app.FWHMCheckBox = uicheckbox(app.Quality_control_Panel);
            app.FWHMCheckBox.ValueChangedFcn = createCallbackFcn(app, @Display_options_changed, true);
            app.FWHMCheckBox.Text = 'FWHM';
            app.FWHMCheckBox.Value = true;
            app.Threshold_SNR_EditField = uieditfield(app.Quality_control_Panel, 'numeric');
            app.Threshold_SNR_EditField.Limits = [0 Inf];
            app.Threshold_SNR_EditField.ValueChangedFcn = createCallbackFcn(app, @Display_options_changed, true);
            app.Threshold_SNR_EditField.Value = 0.75;
            app.SNRCheckBox = uicheckbox(app.Quality_control_Panel);
            app.SNRCheckBox.ValueChangedFcn = createCallbackFcn(app, @Display_options_changed, true);
            app.SNRCheckBox.Text = 'SNR';
            app.SNRCheckBox.Value = true;
            app.ThresholdEditField_2Label_2 = uilabel(app.Quality_control_Panel);
            app.ThresholdEditField_2Label_2.Text = 'Max CRLB limit [%]';
            app.CRLB_limit_EditField = uieditfield(app.Quality_control_Panel, 'numeric');
            app.CRLB_limit_EditField.Limits = [0 100];
            app.CRLB_limit_EditField.ValueChangedFcn = createCallbackFcn(app, @Display_options_changed, true);
            app.CRLB_limit_EditField.Tooltip = {'Maximum accepted CRLB'};
            app.CRLB_limit_EditField.Value = 30;
            app.MeanSNREditFieldLabel = uilabel(app.Quality_control_Panel);
            app.MeanSNREditFieldLabel.Text = 'Mean SNR';
            app.Mean_SNR_EditField = uieditfield(app.Quality_control_Panel, 'numeric');
            app.SNR_th_DropDown = uidropdown(app.Quality_control_Panel);
            app.SNR_th_DropDown.Items = {'Minimum threshold', 'Minimum SNR'};
            app.SNR_th_DropDown.ValueChangedFcn = createCallbackFcn(app, @SNR_th_DropDownValueChanged, true);
            app.SNR_th_DropDown.Value = 'Minimum threshold';
            app.MeanFWHMEditFieldLabel = uilabel(app.Quality_control_Panel);
            app.MeanFWHMEditFieldLabel.Text = 'Mean FWHM';
            app.Mean_FWHM_EditField = uieditfield(app.Quality_control_Panel, 'numeric');
            app.FWHM_th_DropDown = uidropdown(app.Quality_control_Panel);
            app.FWHM_th_DropDown.Items = {'Maximum threshold', 'Maximum FWHM'};
            app.FWHM_th_DropDown.ValueChangedFcn = createCallbackFcn(app, @FWHM_th_DropDownValueChanged, true);
            app.FWHM_th_DropDown.Value = 'Maximum threshold';
            app.QualitycontrolsLabel = uilabel(app.UIFigure);
            app.QualitycontrolsLabel.Text = 'Quality controls';
            app.Max_concentration_Button = uibutton(app.UIFigure, 'state');
            app.Max_concentration_Button.ValueChangedFcn = createCallbackFcn(app, @Max_concentration_ButtonValueChanged, true);
            app.Max_concentration_Button.Text = {'Automatic max'; 'concentration'};
            app.Max_concentration_Button.Value = true;
            app.MaxconcentrationEditFieldLabel = uilabel(app.UIFigure);
            app.MaxconcentrationEditFieldLabel.Text = 'Max concentration';
            app.MaxconcentrationEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxconcentrationEditField.Limits = [0.0001 Inf];
            app.MaxconcentrationEditField.ValueChangedFcn = createCallbackFcn(app, @Display_options_changed, true);
            app.MaxconcentrationEditField.Value = 15;
            app.InterpolationSwitchLabel = uilabel(app.UIFigure);
            app.InterpolationSwitchLabel.Text = 'Interpolation';
            app.InterpolationSwitch = uiswitch(app.UIFigure, 'slider');
            app.InterpolationSwitch.ValueChangedFcn = createCallbackFcn(app, @Display_options_changed, true);
            app.LCModelQCOnlyforSNRCheckBox = uicheckbox(app.UIFigure);
            app.LCModelQCOnlyforSNRCheckBox.ValueChangedFcn = createCallbackFcn(app, @Display_options_changed, true);
            app.LCModelQCOnlyforSNRCheckBox.Text = 'LCModel QC (Only for SNR)';
            app.LCModelQCOnlyforSNRCheckBox.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = display_options_MRSI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end