classdef load_SVS_DWS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        NamebaseEditFieldLabel  matlab.ui.control.Label
        NamebaseEditField       matlab.ui.control.EditField
        Data_folder_Button      matlab.ui.control.Button
        PDF_document_Button     matlab.ui.control.Button
        Update_Button           matlab.ui.control.Button
        Select_all_Button       matlab.ui.control.Button
        Deselect_all_Button     matlab.ui.control.Button
        Close_Button            matlab.ui.control.Button
        Load_data_Button        matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
    end

    
    properties (Access = private)
        MainApp             % MRS4Brain Toolbox mainapp
        data_folder_dir     % Folder of the experiments studied
        data_expnb          % Experiment numbers that have a SVS fid, ser or fid_proc.64
    end
    
    methods (Access = private)
        
        function check_directory(app)
            % Function to check the number of folders in the chosen Bruker
            % data folder
            sub_folders = dir(app.data_folder_dir);
            expnb = zeros(length(sub_folders),1);
            for i = 1:length(sub_folders)
                f_name = str2double(sub_folders(i).name);
                if ~isempty(f_name)
                    if ~isnan(f_name)
                        expnb(i) = f_name;
                    end
                end
            end
            expnb = sort(nonzeros(expnb));
            svs_nb = false(length(expnb),1);
            for i = 1:length(expnb)
                folder_expnb = fullfile(app.data_folder_dir,num2str(expnb(i)));
                ok = false;
                if isfile(fullfile(folder_expnb,'fid'))
                    ok = true;
                elseif isfile(fullfile(folder_expnb,'pdata','1','fid_proc.64'))
                    ok = true;
                elseif isfile(fullfile(folder_expnb,'ser'))
                    ok = true;
                end
                svs_nb(i) = ok;
            end
            expnb = nonzeros(expnb(svs_nb));
            if ~isempty(expnb)
                [~,study_name,~] = fileparts(app.data_folder_dir);
                app.data_expnb = expnb;
                exp_names = cell(length(expnb),1);
                for i = 1:length(expnb)
                    exp_names{i} = [study_name, '_', num2str(expnb(i))];
                end
                state = true(length(expnb),1);
                data_table = table(state,expnb,exp_names,'VariableNames',{'On/Off','N exp','Exp name'});
                app.UITable.Data = data_table;
                app.Select_all_Button.Enable = 'on';
                app.Deselect_all_Button.Enable = 'on';
                app.NamebaseEditField.Value = study_name;
            else
                uialert(app.UIFigure,'No FIDs in the folder','Loading data folder','Icon','warning');
                app.UITable.Data = [];
                app.Select_all_Button.Enable = 'off';
                app.Deselect_all_Button.Enable = 'off';
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
        end

        % Button pushed function: Data_folder_Button
        function Data_folder_ButtonPushed(app, event)
            % Choose a Study name folder that already exist
            d = uigetdir(app.data_folder_dir,"Choose a directory");
            figure(app.UIFigure)
            if d ~= 0
                if isfolder(d)
                    app.data_folder_dir = d;
                    app.PDF_document_Button.Enable = 'on';
                    app.check_directory();
                end
            end
        end

        % Button pushed function: PDF_document_Button
        function PDF_document_ButtonPushed(app, event)
            % Open the scan notes in the Bruker data folder
            [file, path] = uigetfile({'*.pdf','Scan notes'}, ...
                "Select the scan notes", ...
                app.data_folder_dir);
            figure(app.UIFigure)
            if file ~= 0
                open(fullfile(path,file));
            end
        end

        % Button pushed function: Update_Button
        function Update_ButtonPushed(app, event)
            if ~isempty(app.NamebaseEditField.Value)
                name_base = app.NamebaseEditField.Value;
                t_data = app.UITable.Data;
                expnb_data = t_data{:,2};
                names_data = t_data{:,3};
                for i = 1:size(t_data,1)
                    names_data{i} = [name_base, '_', num2str(expnb_data(i))];
                end
                t_data{:,3} = names_data;
                app.UITable.Data = t_data;
            end
        end

        % Button pushed function: Select_all_Button
        function Select_all_ButtonPushed(app, event)
            t_data = app.UITable.Data;
            t_data{:,1} = true(size(t_data,1),1);
            app.UITable.Data = t_data;
        end

        % Button pushed function: Deselect_all_Button
        function Deselect_all_ButtonPushed(app, event)
            t_data = app.UITable.Data;
            t_data{:,1} = false(size(t_data,1),1);
            app.UITable.Data = t_data;
        end

        % Button pushed function: Load_data_Button
        function Load_data_ButtonPushed(app, event)
            t_data = app.UITable.Data;
            idx_data = find(t_data{:,1});
            expnb_data = t_data{idx_data,2};
            names_data = t_data{idx_data,3};
            ok = 1;
            for i = 1:length(names_data)
                p = find(strcmp(names_data,names_data{i}));
                if length(p) > 1
                    ok = 0;
                    break;
                end
            end
            if ok
                folderexp(1:length(names_data),1) = {app.data_folder_dir};
                msg = app.MainApp.SVS_DWS_load(folderexp,expnb_data,names_data);
                conf = uiconfirm(app.UIFigure,{'Would you like to keep loading data ?',msg{1}},'Load SVS', ...
                    'Options',{'Yes','No'},'DefaultOption','No');
                if strcmp(conf,'No')
                    delete(app)
                end
            else
                uialert(app.UIFigure,['Two or more selected data' ...
                    ' have the same name'],'Load data','Icon','warning')
            end
        end

        % Button pushed function: Close_Button
        function Close_ButtonPushed(app, event)
            conf = uiconfirm(app.UIFigure,'Do you really want to close this window ?','Load SVS', ...
                'Options',{'Yes','No'},'DefaultOption','Yes');
            if strcmp(conf,'Yes')
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Load SVS/DWS';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'load.png');
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'On/Off'; 'N° exp'; 'Saved name'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [true false true];
            app.Load_data_Button = uibutton(app.GridLayout, 'push');
            app.Load_data_Button.ButtonPushedFcn = createCallbackFcn(app, @Load_data_ButtonPushed, true);
            app.Load_data_Button.Text = 'Load data';
            app.Close_Button = uibutton(app.GridLayout, 'push');
            app.Close_Button.ButtonPushedFcn = createCallbackFcn(app, @Close_ButtonPushed, true);
            app.Close_Button.Text = 'Close';
            app.Deselect_all_Button = uibutton(app.GridLayout, 'push');
            app.Deselect_all_Button.ButtonPushedFcn = createCallbackFcn(app, @Deselect_all_ButtonPushed, true);
            app.Deselect_all_Button.Enable = 'off';
            app.Deselect_all_Button.Text = 'Deselect all';
            app.Select_all_Button = uibutton(app.GridLayout, 'push');
            app.Select_all_Button.ButtonPushedFcn = createCallbackFcn(app, @Select_all_ButtonPushed, true);
            app.Select_all_Button.Enable = 'off';
            app.Select_all_Button.Text = 'Select all';
            app.Update_Button = uibutton(app.GridLayout, 'push');
            app.Update_Button.ButtonPushedFcn = createCallbackFcn(app, @Update_ButtonPushed, true);
            app.Update_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'update.png');
            app.Update_Button.Text = '';
            app.PDF_document_Button = uibutton(app.GridLayout, 'push');
            app.PDF_document_Button.ButtonPushedFcn = createCallbackFcn(app, @PDF_document_ButtonPushed, true);
            app.PDF_document_Button.Enable = 'off';
            app.PDF_document_Button.Text = 'PDF document';
            app.Data_folder_Button = uibutton(app.GridLayout, 'push');
            app.Data_folder_Button.ButtonPushedFcn = createCallbackFcn(app, @Data_folder_ButtonPushed, true);
            app.Data_folder_Button.Text = 'Data folder';
            app.NamebaseEditField = uieditfield(app.GridLayout, 'text');
            app.NamebaseEditFieldLabel = uilabel(app.GridLayout);
            app.NamebaseEditFieldLabel.Text = 'Name base';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = load_SVS_DWS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end