classdef plot_spectra_MRSI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        PlotWaterCheckBox          matlab.ui.control.CheckBox
        PlotRAWCheckBox            matlab.ui.control.CheckBox
        ScaleMinEditField          matlab.ui.control.NumericEditField
        ScaleMinEditFieldLabel     matlab.ui.control.Label
        ScaleMaxEditField          matlab.ui.control.NumericEditField
        ScaleMaxEditFieldLabel     matlab.ui.control.Label
        OwnFWHMEditField           matlab.ui.control.NumericEditField
        OwnFWHMEditFieldLabel      matlab.ui.control.Label
        OwnSNREditField            matlab.ui.control.NumericEditField
        OwnSNREditFieldLabel       matlab.ui.control.Label
        MRSI_BaselineButton        matlab.ui.control.StateButton
        MRSI_Slice_DropDown        matlab.ui.control.DropDown
        SliceDropDownLabel         matlab.ui.control.Label
        LCModelFWHMEditField       matlab.ui.control.NumericEditField
        LCModelFWHMEditFieldLabel  matlab.ui.control.Label
        LCModelSNREditField        matlab.ui.control.NumericEditField
        LCModelSNREditFieldLabel   matlab.ui.control.Label
        MRSI_DataButton            matlab.ui.control.StateButton
        MRSI_FitButton             matlab.ui.control.StateButton
        MRSI_ResidualsButton       matlab.ui.control.StateButton
        Image                      matlab.ui.control.Image
        Panel                      matlab.ui.container.Panel
        PosYEditField              matlab.ui.control.NumericEditField
        PosYEditFieldLabel         matlab.ui.control.Label
        PosXEditField              matlab.ui.control.NumericEditField
        PosXEditFieldLabel         matlab.ui.control.Label
        ChangevoxelButton          matlab.ui.control.StateButton
        Spectra_UIAxes             matlab.ui.control.UIAxes
        MRSI_grid_UIAxes           matlab.ui.control.UIAxes
        MRSI_map_UIAxes            matlab.ui.control.UIAxes
        MRI_image_UIAxes           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp             % Parent app (MRS4Brain toolbox mainapp)
        prog_d              % Progress dialogbox
        image_slice         % Current anatomical image
        metab_map           % Current metabolite map
        MRSI_experiment     % Current MRSIClass experiment
        MRSI_fit            % Current MRSI LCModel fitting
        MatSize             % MRSI Matrix Size
        slice_num           % Number of the current slice
        mask                % Mask of fitted voxels + current position
        mask_upd            % Updated mask
        current_spectra_pos % Current spectra position
        scale_max = 1
        scale_min = 0
    end
    
    
    methods (Access = private)
        
        function display_grid(app)
            % Axis settings
            cla(app.MRI_image_UIAxes)
            cla(app.MRSI_grid_UIAxes)

            % plot Background image slice
            im_MRI = imshow(app.image_slice,'Parent',app.MRI_image_UIAxes);
            im_MRI.AlphaData = 1; % change this value to change the background image transparency
            axis(app.MRI_image_UIAxes,'equal')

            % plot Metabolite map
            im_MRSI_map = imagesc(app.MRSI_map_UIAxes,app.metab_map);
            im_MRSI_map.AlphaData = 0.4; % change this value to change the background image transparency
            axis(app.MRSI_map_UIAxes,'equal')

            % plot Current mask
            xrange = [1 app.MatSize(1)]; % imagesc only needs the endpoints
            yrange = [1 app.MatSize(2)];
            dx = diff(xrange)/(app.MatSize(2)-1);
            dy = diff(yrange)/(app.MatSize(1)-1);
            xg = linspace(xrange(1)-dx/2,xrange(2)+dx/2,app.MatSize(2)+1);
            yg = linspace(yrange(1)-dy/2,yrange(2)+dy/2,app.MatSize(1)+1);
            im_MRSI_grid = imagesc(app.MRSI_grid_UIAxes,app.mask_upd);
            hold(app.MRSI_grid_UIAxes,'on')
            hm = mesh(app.MRSI_grid_UIAxes,xg,yg,zeros(app.MatSize(1) + 1,app.MatSize(2) + 1));
            hm.FaceColor = 'none';
            hm.EdgeColor = 'k';
            im_MRSI_grid.AlphaData = 0.3; % change this value to change the foreground image transparency
            axis(app.MRSI_grid_UIAxes,'equal')
            % Colormaps
            colormap(app.MRI_image_UIAxes,'gray')
            colormap(app.MRSI_map_UIAxes,'jet')
            clim(app.MRSI_map_UIAxes,[0,app.MainApp.max_clrb])
            mapcolors = [0.5 0.5 0.5; 0.1 0.1 0.1; 1 1 1];
            cmap = [mapcolors(1,:); ...
                mapcolors(2,:); ...
                mapcolors(3,:)];
            clim(app.MRSI_grid_UIAxes,[0,2])
            colormap(app.MRSI_grid_UIAxes,cmap)
        end
        
        function update_mask(app)
            % Method to update the mask with the changes applied
            B = app.current_spectra_pos;
            if and(B(1) <= app.MatSize(1),B(2) <= app.MatSize(2))
                app.mask_upd = app.mask;
                app.mask_upd(B(1),B(2)) = 2;
            end
            app.display_grid();
        end
        
        % Plot spectrum on given position
        function plot_spectra(app)
            B = app.current_spectra_pos;
            if and(B(1) <= app.MatSize(1),B(2) <= app.MatSize(2))
                if(~app.PlotRAWCheckBox.Value)
                    cla(app.Spectra_UIAxes)
                    hold(app.Spectra_UIAxes,'on')
                    if app.MRSI_DataButton.Value
                        plot(app.Spectra_UIAxes,app.MRSI_fit.ppm_scale, ...
                            app.MRSI_fit.data_pts_tot(:,B(1),B(2)),'k-','LineWidth',1);
                    end
                    if app.MRSI_FitButton.Value
                        plot(app.Spectra_UIAxes,app.MRSI_fit.ppm_scale, ...
                            app.MRSI_fit.fit_pts_tot(:,B(1),B(2)),'r-','LineWidth',1.5);
                    end
                    if app.MRSI_BaselineButton.Value
                        baseline = app.MRSI_fit.baseline_pts_tot(:,B(1),B(2)) + 0.25*min(app.MRSI_fit.fit_pts_tot(:,B(1),B(2)));
                        plot(app.Spectra_UIAxes,app.MRSI_fit.ppm_scale, ...
                            baseline,'Color',[0.9290 0.6940 0.1250],'LineWidth',1.5);
                    end
                    if app.MRSI_ResidualsButton.Value
                        residus = app.MRSI_fit.data_pts_tot(:,B(1),B(2)) - ...
                            app.MRSI_fit.fit_pts_tot(:,B(1),B(2)) + 5*max(app.MRSI_fit.fit_pts_tot(:,B(1),B(2)));
                        plot(app.Spectra_UIAxes,app.MRSI_fit.ppm_scale, ...
                            residus,'k-','LineWidth',1);
                    end
                    app.LCModelSNREditField.Value = app.MRSI_fit.SNR(B(1),B(2));
                    app.OwnSNREditField.Value = app.MRSI_experiment.SNR_map(app.slice_num,app.MatSize(1) + 1 - B(1),B(2));
                    app.OwnFWHMEditField.Value = app.MRSI_experiment.Linewidth_map(app.slice_num,app.MatSize(1) + 1 - B(1),B(2))/app.MRSI_experiment.acq_params.resfreq;
                    app.LCModelFWHMEditField.Value = app.MRSI_fit.FWHM(B(1),B(2));
                    xlim(app.Spectra_UIAxes,[min(app.MRSI_fit.ppm_scale),max(app.MRSI_fit.ppm_scale)]);
    
                    if(max(app.MRSI_fit.data_pts_tot(:,B(1),B(2)))>app.scale_max)
                        app.scale_max = max(app.MRSI_fit.data_pts_tot(:,B(1),B(2)));
                        app.ScaleMaxEditField.Value = max(app.MRSI_fit.data_pts_tot(:,B(1),B(2)));
                    end
                    if(min(app.MRSI_fit.data_pts_tot(:,B(1),B(2)))<app.scale_min)
                        app.scale_min = min(app.MRSI_fit.data_pts_tot(:,B(1),B(2)));
                        app.ScaleMinEditField.Value = min(app.MRSI_fit.data_pts_tot(:,B(1),B(2)));
                    end
                    ylim(app.Spectra_UIAxes,[app.scale_min,app.scale_max])
                    if app.MRSI_ResidualsButton.Value
                        ylim(app.Spectra_UIAxes,'auto');
                    end
                    % set(app.Spectra_UIAxes,'YTick',[]); %% TO REMOVE !!! FROM BA 05/03/2025
                else
                    if(app.PlotWaterCheckBox.Value)
                        MRSI_RAW_map_frr = squeeze(fftshift(fft(ifft(ifft(app.MRSI_experiment.ref_mat_tkkn(:,:,:,app.slice_num),[],3),[],2),[],1),1));
                    else
                        MRSI_RAW_map_frr = squeeze(fftshift(fft(ifft(ifft(app.MRSI_experiment.fid_mat_tkkn(:,:,:,app.slice_num),[],3),[],2),[],1),1));
                    end
                    cla(app.Spectra_UIAxes)
                    hold(app.Spectra_UIAxes,'on')
                    plot(app.Spectra_UIAxes,app.MRSI_experiment.acq_params.scale_ppm, ...
                            real(MRSI_RAW_map_frr(:,app.MatSize(1) + 1 - B(1),B(2))),'k-','LineWidth',1);
                    app.LCModelSNREditField.Value = app.MRSI_fit.SNR(B(1),B(2));
                    app.OwnSNREditField.Value = app.MRSI_experiment.SNR_map(app.slice_num,app.MatSize(1) + 1 - B(1),B(2));
                    app.OwnFWHMEditField.Value = app.MRSI_experiment.Linewidth_map(app.slice_num,app.MatSize(1) + 1 - B(1),B(2))/app.MRSI_experiment.acq_params.resfreq;
                    app.LCModelFWHMEditField.Value = app.MRSI_fit.FWHM(B(1),B(2));
                    if(app.PlotWaterCheckBox.Value)
                        xlim(app.Spectra_UIAxes,[0,10]);
                    else
                        xlim(app.Spectra_UIAxes,[min(app.MRSI_fit.ppm_scale),max(app.MRSI_fit.ppm_scale)]);
                    end
                    if(max(real(MRSI_RAW_map_frr(:,app.MatSize(1) + 1 - B(1),B(2))))>app.scale_max)
                        app.scale_max = max(real(MRSI_RAW_map_frr(:,app.MatSize(1) + 1 - B(1),B(2))));
                        app.ScaleMaxEditField.Value = max(real(MRSI_RAW_map_frr(:,app.MatSize(1) + 1 - B(1),B(2))));
                    end
                    if(min(real(MRSI_RAW_map_frr(:,app.MatSize(1) + 1 - B(1),B(2))))<app.scale_min)
                        app.scale_min = min(real(MRSI_RAW_map_frr(:,app.MatSize(1) + 1 - B(1),B(2))));
                        app.ScaleMinEditField.Value = min(real(MRSI_RAW_map_frr(:,app.MatSize(1) + 1 - B(1),B(2))));
                    end
                    ylim(app.Spectra_UIAxes,[app.scale_min,app.scale_max])
                    if app.MRSI_ResidualsButton.Value
                        ylim(app.Spectra_UIAxes,'auto');
                    end
                    % set(app.Spectra_UIAxes,'YTick',[]); %% TO REMOVE !!! FROM BA 05/03/2025
                end
            end
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, MRSI_exp, image_slice, slice_num, prog_d)
            % app properties
            app.MainApp = mainapp;
            app.prog_d = prog_d;
            app.image_slice = image_slice;
            app.MRSI_experiment = MRSI_exp;
            app.slice_num = str2double(slice_num);
            app.MatSize = MRSI_exp.acq_params.matrix_sz;
            app.ScaleMaxEditField.Value = app.scale_max;
            app.ScaleMinEditField.Value = app.scale_min;

            % Mask of quantified voxels
            if app.MainApp.MRSI_Job0CheckBox.Value
                % app.mask = double(logical(app.MRSI_experiment.Final_met_map(app.slice_num).SNR(:,:)));
                app.mask = double(squeeze(app.MRSI_experiment.Power_map(app.slice_num,:,:)));
                pow_rescale = app.mask-mean(mean(app.mask));
                app.mask = double(logical(pow_rescale>0));
            else
                temp_exp = squeeze(app.MRSI_experiment.Power_map(app.slice_num,:,:));
                app.mask = double(temp_exp(end:-1:1,:));
                pow_rescale = app.mask-mean(mean(app.mask));
                app.mask = double(logical(pow_rescale>0));
            end

            if app.MainApp.mri_headprone
                app.mask=app.mask(end:-1:1,:);
                app.image_slice = app.image_slice(end:-1:1,:);
            end

            app.mask_upd = app.mask;

            % MRSI LCModel fitting
            app.MRSI_fit = app.MRSI_experiment.Final_met_map(app.slice_num);
            if app.MainApp.MRSI_Job0CheckBox.Value
                app.MRSI_fit.data_pts_tot = app.MRSI_fit.data_pts_tot(:,:,:);
                app.MRSI_fit.fit_pts_tot = app.MRSI_fit.fit_pts_tot(:,:,:);
                app.MRSI_fit.baseline_pts_tot = app.MRSI_fit.baseline_pts_tot(:,:,:);
                app.MRSI_fit.SNR = app.MRSI_fit.SNR(:,:);
                app.MRSI_fit.FWHM = app.MRSI_fit.FWHM(:,:);
            else
                app.MRSI_fit.data_pts_tot = app.MRSI_fit.data_pts_tot(:,end:-1:1,:);
                app.MRSI_fit.fit_pts_tot = app.MRSI_fit.fit_pts_tot(:,end:-1:1,:);
                app.MRSI_fit.baseline_pts_tot = app.MRSI_fit.baseline_pts_tot(:,end:-1:1,:);
                app.MRSI_fit.SNR = app.MRSI_fit.SNR(end:-1:1,:);
                app.MRSI_fit.FWHM = app.MRSI_fit.FWHM(end:-1:1,:);
            end

            if app.MainApp.mri_headprone
                app.MRSI_fit.data_pts_tot = app.MRSI_fit.data_pts_tot(:,end:-1:1,:);
                app.MRSI_fit.fit_pts_tot = app.MRSI_fit.fit_pts_tot(:,end:-1:1,:);
                app.MRSI_fit.baseline_pts_tot = app.MRSI_fit.baseline_pts_tot(:,end:-1:1,:);
                app.MRSI_fit.SNR = app.MRSI_fit.SNR(end:-1:1,:);
                app.MRSI_fit.FWHM = app.MRSI_fit.FWHM(end:-1:1,:);
            end
            
            % Current metabolite map
            app.metab_map = app.MainApp.current_metab_map;

            % app position
            mainapp_corner_tr = app.MainApp.UIFigure.Position(3:4) + ...
                app.MainApp.UIFigure.Position(1:2);
            currentapp_corner_tr = app.UIFigure.Position(3:4);
            app.UIFigure.Position(1:2) = mainapp_corner_tr - currentapp_corner_tr;
            
            app.display_grid();

            app.MRSI_Slice_DropDown.Items = string(1:app.MainApp.MRSI_Number_slices_Spinner.Value);
            app.MRSI_Slice_DropDown.Value = slice_num;
            disableDefaultInteractivity(app.MRI_image_UIAxes)
            disableDefaultInteractivity(app.MRSI_grid_UIAxes)
        end

        % Value changed function: ChangevoxelButton
        function ChangevoxelButtonValueChanged(app, event)
            while app.ChangevoxelButton.Value
                p = drawpoint('Parent',app.MRSI_grid_UIAxes,'Deletable',1,'Visible','off');
                if app.ChangevoxelButton.Value
                    x = p.Position(1); y = p.Position(2);
                    delete(p)
                    B = floor([y,x] + [0.5,0.5]);
                    if and(B(1) <= app.MatSize(1),B(2) <= app.MatSize(2))
                        app.PosXEditField.Value = app.MatSize(1) + 1 - B(1);
                        app.PosYEditField.Value = B(2);
                        app.current_spectra_pos = B;
                        app.update_mask();
                        app.plot_spectra();
                    end
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.MainApp.MRSI_Spectra_Button.Enable = 'on';
            delete(app.prog_d)
            delete(app)
        end

        % Value changed function: MRSI_DataButton
        function MRSI_DataButtonValueChanged(app, event)
            % app.MRSI_ResidualsButton.Value = 0;
            app.plot_spectra()
        end

        % Value changed function: MRSI_FitButton
        function MRSI_FitButtonValueChanged(app, event)
            % app.MRSI_ResidualsButton.Value = 0;
            app.plot_spectra()
        end

        % Value changed function: MRSI_ResidualsButton
        function MRSI_ResidualsButtonValueChanged(app, event)
            % app.MRSI_DataButton.Value = 0;
            % app.MRSI_FitButton.Value = 0;
            app.plot_spectra()
        end

        % Value changed function: PosXEditField, PosYEditField
        function Edit_position_ValueChanged(app, event)
            Px = app.PosXEditField.Value;
            Py = app.PosYEditField.Value;
            B_temp = [Px,Py];
            if and(B_temp(1) <= app.MatSize(1),B_temp(2) <= app.MatSize(2))
                app.current_spectra_pos = B_temp;
                app.current_spectra_pos(1) = app.MatSize(1) + 1 - app.current_spectra_pos(1);
                app.update_mask();
                app.plot_spectra();
            else
                app.PosXEditField.Value = app.current_spectra_pos(1);
                app.PosYEditField.Value = app.current_spectra_pos(2);
            end
        end

        % Value changed function: MRSI_Slice_DropDown
        function MRSI_Slice_DropDownValueChanged(app, event)
            value = app.MRSI_Slice_DropDown.Value;
            if ~isempty(app.image_slice)
                if ~isempty(app.MRSI_experiment)
                    if ~isempty(app.MRSI_experiment.Final_met_map(str2double(value)))
                        % Set the new slice values on both the plot spectra
                        % and the main app window
                        app.slice_num = str2double(value);
                        app.MainApp.MRSI_Slice_DropDown.Value = value;
                        app.MatSize = app.MRSI_experiment.acq_params.matrix_sz;

                        % % Reset the MRI image
                        % min_slice = app.MainApp.Slices_number(app.slice_num) - app.MainApp.slice_range;
                        % max_slice = app.MainApp.Slices_number(app.slice_num) + app.MainApp.slice_range;
                        % Image_slice = rot90(squeeze(sum(double(app.MainApp.MRIReg_element.original_image(:,:,min_slice:max_slice)),3)),-1);
                        % Image_slice = uint8(Image_slice/max(max(Image_slice))*256);
                        % app.image_slice = Image_slice;

            
                        % Mask of quantified voxels
                        if app.MainApp.MRSI_Job0CheckBox.Value
                            app.mask = double(logical(squeeze(app.MRSI_experiment.Power_map(app.slice_num,:,:))));
                            pow_rescale = app.mask-mean(mean(app.mask));
                            app.mask = double(logical(pow_rescale>0));
                            
                        else
                            temp_exp = squeeze(app.MRSI_experiment.Power_map(app.slice_num,:,:));
                            app.mask = double(temp_exp(end:-1:1,:));
                            pow_rescale = app.mask-mean(mean(app.mask));
                            app.mask = double(logical(pow_rescale>0));
                        end

                        if app.MainApp.mri_headprone
                            app.mask=app.mask(end:-1:1,:);
                        end
            
                        app.mask_upd = app.mask;
            
                        % MRSI LCModel fitting
                        app.MRSI_fit = app.MRSI_experiment.Final_met_map(app.slice_num);
                        if app.MainApp.MRSI_Job0CheckBox.Value
                            app.MRSI_fit.data_pts_tot = app.MRSI_fit.data_pts_tot(:,:,:);
                            app.MRSI_fit.fit_pts_tot = app.MRSI_fit.fit_pts_tot(:,:,:);
                            app.MRSI_fit.baseline_pts_tot = app.MRSI_fit.baseline_pts_tot(:,:,:);
                            app.MRSI_fit.SNR = app.MRSI_fit.SNR(:,:);
                            app.MRSI_fit.FWHM = app.MRSI_fit.FWHM(:,:);
                        else
                            app.MRSI_fit.data_pts_tot = app.MRSI_fit.data_pts_tot(:,end:-1:1,:);
                            app.MRSI_fit.fit_pts_tot = app.MRSI_fit.fit_pts_tot(:,end:-1:1,:);
                            app.MRSI_fit.baseline_pts_tot = app.MRSI_fit.baseline_pts_tot(:,end:-1:1,:);
                            app.MRSI_fit.SNR = app.MRSI_fit.SNR(end:-1:1,:);
                            app.MRSI_fit.FWHM = app.MRSI_fit.FWHM(end:-1:1,:);
                        end

                        if app.MainApp.mri_headprone
                            app.MRSI_fit.data_pts_tot = app.MRSI_fit.data_pts_tot(:,end:-1:1,:);
                            app.MRSI_fit.fit_pts_tot = app.MRSI_fit.fit_pts_tot(:,end:-1:1,:);
                            app.MRSI_fit.baseline_pts_tot = app.MRSI_fit.baseline_pts_tot(:,end:-1:1,:);
                            app.MRSI_fit.SNR = app.MRSI_fit.SNR(end:-1:1,:);
                            app.MRSI_fit.FWHM = app.MRSI_fit.FWHM(end:-1:1,:);
                        end
                        
                        % Current metabolite map
                        app.MainApp.display_MRSI();
                        app.metab_map = app.MainApp.current_metab_map;
            
                        % app position
                        mainapp_corner_tr = app.MainApp.UIFigure.Position(3:4) + ...
                            app.MainApp.UIFigure.Position(1:2);
                        currentapp_corner_tr = app.UIFigure.Position(3:4);
                        app.UIFigure.Position(1:2) = mainapp_corner_tr - currentapp_corner_tr;
                        
                        app.display_grid();
            
                        disableDefaultInteractivity(app.MRI_image_UIAxes)
                        disableDefaultInteractivity(app.MRSI_grid_UIAxes)
                        app.display_grid()
                    else
                        uialert(app.UIFigure,'No existing metabolite map', ...
                            'Show Results','Icon','warning')
                    end
                end
            end
        end

        % Value changed function: MRSI_BaselineButton
        function MRSI_BaselineButtonValueChanged(app, event)
            % app.MRSI_BaselineButton.Value = 0;
            app.plot_spectra()
        end

        % Value changed function: ScaleMinEditField
        function ScaleMinEditFieldValueChanged(app, event)
            value = app.ScaleMinEditField.Value;
            app.scale_min = value;
            app.plot_spectra();
        end

        % Value changed function: ScaleMaxEditField
        function ScaleMaxEditFieldValueChanged(app, event)
            value = app.ScaleMaxEditField.Value;
            app.scale_max = value;
            app.plot_spectra();
        end

        % Value changed function: PlotRAWCheckBox
        function PlotRAWCheckBoxValueChanged(app, event)
            value = app.PlotRAWCheckBox.Value;
            if value
                app.MRSI_DataButton.Enable = 'off';
                app.MRSI_BaselineButton.Enable = 'off';
                app.MRSI_FitButton.Enable = 'off';
                app.MRSI_ResidualsButton.Enable = 'off';
                app.PlotWaterCheckBox.Enable = 'on';
            else
                app.MRSI_DataButton.Enable = 'on';
                app.MRSI_BaselineButton.Enable = 'on';
                app.MRSI_FitButton.Enable = 'on';
                app.MRSI_ResidualsButton.Enable = 'on';
                app.PlotWaterCheckBox.Enable = 'off';
            end

            app.plot_spectra();

        end

        % Value changed function: PlotWaterCheckBox
        function PlotWaterCheckBoxValueChanged(app, event)
            value = app.PlotWaterCheckBox.Value;
            app.plot_spectra();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MRSI spectra';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'spectra_MRSI.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MRI_image_UIAxes = uiaxes(app.UIFigure);
            zlabel(app.MRI_image_UIAxes, 'Z')
            app.MRI_image_UIAxes.XTick = [];
            app.MRI_image_UIAxes.YTick = [];
            app.MRSI_map_UIAxes = uiaxes(app.UIFigure);
            zlabel(app.MRSI_map_UIAxes, 'Z')
            app.MRSI_map_UIAxes.XTick = [];
            app.MRSI_map_UIAxes.YTick = [];
            colormap(app.MRSI_map_UIAxes, 'jet')
            app.MRSI_grid_UIAxes = uiaxes(app.UIFigure);
            zlabel(app.MRSI_grid_UIAxes, 'Z')
            app.MRSI_grid_UIAxes.XTick = [];
            app.MRSI_grid_UIAxes.YTick = [];
            colormap(app.MRSI_grid_UIAxes, 'jet')
            app.Spectra_UIAxes = uiaxes(app.UIFigure);
            xlabel(app.Spectra_UIAxes, '[ppm]')
            zlabel(app.Spectra_UIAxes, 'Z')
            app.Spectra_UIAxes.XLim = [0 5];
            app.Spectra_UIAxes.XDir = 'reverse';
            app.Panel = uipanel(app.UIFigure);
            app.ChangevoxelButton = uibutton(app.Panel, 'state');
            app.ChangevoxelButton.ValueChangedFcn = createCallbackFcn(app, @ChangevoxelButtonValueChanged, true);
            app.ChangevoxelButton.Text = 'Change voxel';
            app.PosXEditFieldLabel = uilabel(app.Panel);
            app.PosXEditFieldLabel.Text = 'Pos X';
            app.PosXEditField = uieditfield(app.Panel, 'numeric');
            app.PosXEditField.Limits = [1 Inf];
            app.PosXEditField.RoundFractionalValues = 'on';
            app.PosXEditField.ValueDisplayFormat = '%.0f';
            app.PosXEditField.ValueChangedFcn = createCallbackFcn(app, @Edit_position_ValueChanged, true);
            app.PosXEditField.Value = 1;
            app.PosYEditFieldLabel = uilabel(app.Panel);
            app.PosYEditFieldLabel.Text = 'Pos Y';
            app.PosYEditField = uieditfield(app.Panel, 'numeric');
            app.PosYEditField.Limits = [1 Inf];
            app.PosYEditField.RoundFractionalValues = 'on';
            app.PosYEditField.ValueDisplayFormat = '%.0f';
            app.PosYEditField.ValueChangedFcn = createCallbackFcn(app, @Edit_position_ValueChanged, true);
            app.PosYEditField.Value = 1;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Logo_MRS4Brain_toolbox_square_2.png');
            app.MRSI_ResidualsButton = uibutton(app.UIFigure, 'state');
            app.MRSI_ResidualsButton.ValueChangedFcn = createCallbackFcn(app, @MRSI_ResidualsButtonValueChanged, true);
            app.MRSI_ResidualsButton.Text = 'Residuals';
            app.MRSI_FitButton = uibutton(app.UIFigure, 'state');
            app.MRSI_FitButton.ValueChangedFcn = createCallbackFcn(app, @MRSI_FitButtonValueChanged, true);
            app.MRSI_FitButton.Text = 'Fit';
            app.MRSI_FitButton.Value = true;
            app.MRSI_DataButton = uibutton(app.UIFigure, 'state');
            app.MRSI_DataButton.ValueChangedFcn = createCallbackFcn(app, @MRSI_DataButtonValueChanged, true);
            app.MRSI_DataButton.Text = 'Data';
            app.MRSI_DataButton.Value = true;
            app.LCModelSNREditFieldLabel = uilabel(app.UIFigure);
            app.LCModelSNREditFieldLabel.Text = 'LCModel SNR';
            app.LCModelSNREditField = uieditfield(app.UIFigure, 'numeric');
            app.LCModelSNREditField.Editable = 'off';
            app.LCModelFWHMEditFieldLabel = uilabel(app.UIFigure);
            app.LCModelFWHMEditFieldLabel.Text = 'LCModel FWHM';
            app.LCModelFWHMEditField = uieditfield(app.UIFigure, 'numeric');
            app.LCModelFWHMEditField.Editable = 'off';
            app.SliceDropDownLabel = uilabel(app.UIFigure);
            app.SliceDropDownLabel.Text = 'Slice';
            app.MRSI_Slice_DropDown = uidropdown(app.UIFigure);
            app.MRSI_Slice_DropDown.Items = {'1'};
            app.MRSI_Slice_DropDown.ValueChangedFcn = createCallbackFcn(app, @MRSI_Slice_DropDownValueChanged, true);
            app.MRSI_Slice_DropDown.Value = '1';
            app.MRSI_BaselineButton = uibutton(app.UIFigure, 'state');
            app.MRSI_BaselineButton.ValueChangedFcn = createCallbackFcn(app, @MRSI_BaselineButtonValueChanged, true);
            app.MRSI_BaselineButton.Text = 'Baseline';
            app.OwnSNREditFieldLabel = uilabel(app.UIFigure);
            app.OwnSNREditFieldLabel.Text = 'Own SNR';
            app.OwnSNREditField = uieditfield(app.UIFigure, 'numeric');
            app.OwnSNREditField.Editable = 'off';
            app.OwnFWHMEditFieldLabel = uilabel(app.UIFigure);
            app.OwnFWHMEditFieldLabel.Text = 'Own FWHM';
            app.OwnFWHMEditField = uieditfield(app.UIFigure, 'numeric');
            app.OwnFWHMEditField.Editable = 'off';
            app.ScaleMaxEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleMaxEditFieldLabel.Text = 'Scale Max';
            app.ScaleMaxEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleMaxEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleMaxEditFieldValueChanged, true);
            app.ScaleMinEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleMinEditFieldLabel.Text = 'Scale Min';
            app.ScaleMinEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleMinEditField.ValueChangedFcn = createCallbackFcn(app, @ScaleMinEditFieldValueChanged, true);
            app.PlotRAWCheckBox = uicheckbox(app.UIFigure);
            app.PlotRAWCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotRAWCheckBoxValueChanged, true);
            app.PlotRAWCheckBox.Text = 'Plot RAW';
            app.PlotWaterCheckBox = uicheckbox(app.UIFigure);
            app.PlotWaterCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotWaterCheckBoxValueChanged, true);
            app.PlotWaterCheckBox.Enable = 'off';
            app.PlotWaterCheckBox.Text = 'Plot Water';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_spectra_MRSI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end