classdef preferences_MRSI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SDDEGP_EditField            matlab.ui.control.NumericEditField
        DEGZERLabel_4               matlab.ui.control.Label
        DEGPPM_EditField            matlab.ui.control.NumericEditField
        DEGZERLabel_3               matlab.ui.control.Label
        SDDEGZ_EditField            matlab.ui.control.NumericEditField
        DEGZERLabel_2               matlab.ui.control.Label
        DEGZER_EditField            matlab.ui.control.NumericEditField
        DEGZERLabel                 matlab.ui.control.Label
        NRATIOLabel                 matlab.ui.control.Label
        NRATIO_EditField            matlab.ui.control.NumericEditField
        WCONCEditFieldLabel         matlab.ui.control.Label
        CombinationLabel            matlab.ui.control.Label
        Panel_COMB                  matlab.ui.container.Panel
        NCOMBSpinner                matlab.ui.control.Spinner
        UITable_COMB                matlab.ui.control.Table
        NCOMBSpinnerLabel           matlab.ui.control.Label
        OmissionLabel               matlab.ui.control.Label
        Panel_OMIT                  matlab.ui.container.Panel
        NOMITSpinner                matlab.ui.control.Spinner
        NOMITSpinnerLabel           matlab.ui.control.Label
        UITable_OMIT                matlab.ui.control.Table
        UseLabel                    matlab.ui.control.Label
        Panel_USE                   matlab.ui.container.Panel
        NUSESpinner                 matlab.ui.control.Spinner
        NUSESpinnerLabel            matlab.ui.control.Label
        UITable_USE                 matlab.ui.control.Table
        RegistrationLabel           matlab.ui.control.Label
        MRI_registration_Panel      matlab.ui.container.Panel
        Template_Button             matlab.ui.control.Button
        Brain_mask_Button           matlab.ui.control.Button
        Anat_segmentation_Button    matlab.ui.control.Button
        Labels_Button               matlab.ui.control.Button
        LicenceLabel                matlab.ui.control.Label
        Owner_Key_Panel             matlab.ui.container.Panel
        KeyEditField                matlab.ui.control.NumericEditField
        KeyEditFieldLabel           matlab.ui.control.Label
        OwnerEditField              matlab.ui.control.EditField
        OwnerEditFieldLabel         matlab.ui.control.Label
        ConfignameEditFieldLabel_3  matlab.ui.control.Label
        Configname_MRSI_EditField   matlab.ui.control.EditField
        ConfigsDropDown             matlab.ui.control.DropDown
        ConfigsDropDownLabel        matlab.ui.control.Label
        Rel_conc_EditField          matlab.ui.control.NumericEditField
        RelativeconcentrationEditFieldLabel  matlab.ui.control.Label
        Rel_met_EditField           matlab.ui.control.EditField
        RelativemetaboliteEditFieldLabel  matlab.ui.control.Label
        DKNTMN_EditField            matlab.ui.control.NumericEditField
        DKNTMNEditFieldLabel        matlab.ui.control.Label
        PPMend_EditField            matlab.ui.control.NumericEditField
        PPMendEditFieldLabel        matlab.ui.control.Label
        PPMstart_EditField          matlab.ui.control.NumericEditField
        PPMstartEditFieldLabel      matlab.ui.control.Label
        WCONC_EditField             matlab.ui.control.NumericEditField
        LCModelpath_Button          matlab.ui.control.Button
        Basis_set_Button            matlab.ui.control.Button
        NSIMUL_CheckBox             matlab.ui.control.CheckBox
        VITRO_CheckBox              matlab.ui.control.CheckBox
        NRATIO_CheckBox             matlab.ui.control.CheckBox
        Config_MRSI_state_Lamp      matlab.ui.control.Lamp
        Config_MRSI_original_Lamp   matlab.ui.control.Label
        Config_MRSI_modified_Lamp   matlab.ui.control.Label
        Save_MRSI_config_Button     matlab.ui.control.Button
        Delete_MRSI_config_Button   matlab.ui.control.Button
        Toolbox_logo                matlab.ui.control.Image
        Preferences_logo            matlab.ui.control.Image
        Preferences_Label           matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp                     % Parent app (MRS4Brain toolbox mainapp)
        spec_param                  % Structure with all the MRSI parameters
        spec_cf                     % Current MRSI configuration
        reg_param                   % Structure with all the registration parameters (template for image segmentation)
        app_path                    % App pathname
        CHCOMB                      % Combination of metabolites
        CHOMIT                      % Omission of metabolites
        CHUSE1                      % Used metabolites
        configfiles_name            % MRSI Configuration filenames available
    end
    
    
    methods (Access = private)
        
        function load_spec_param(app)
            % MRSI parameters loading
            app.Configname_MRSI_EditField.Value = app.spec_param.Config_name;
            app.NSIMUL_CheckBox.Value = app.spec_param.NSIMUL;
            app.VITRO_CheckBox.Value = app.spec_param.VITRO;
            app.NRATIO_EditField.Value = app.spec_param.NRATIO;
            app.WCONC_EditField.Value = app.spec_param.WCONC;
            app.PPMstart_EditField.Value = app.spec_param.PPMstart;
            app.PPMend_EditField.Value = app.spec_param.PPMend;
            app.DKNTMN_EditField.Value = app.spec_param.DKNTMN;
            app.Rel_met_EditField.Value = app.spec_param.Rel_met;
            app.Rel_conc_EditField.Value = app.spec_param.Rel_conc;

            %Version 11 (03/13/2024) - Check if DegZero and DegPPM exist
            if ~isfield(app.spec_param,'DEGZER')
                app.spec_param.DEGZER = 0;
                app.DEGZER_EditField.Value = 0;
            end

            if ~isfield(app.spec_param,'SDDEGZ')
                app.spec_param.SDDEGZ = 999;
                app.SDDEGZ_EditField.Value = 999;
            end

            if ~isfield(app.spec_param,'DEGPPM')
                app.spec_param.DEGPPM = 0;
                app.DEGPPM_EditField.Value = 0;
            end

            if ~isfield(app.spec_param,'SDDEGP')
                app.spec_param.SDDEGP = 0;
                app.SDDEGP_EditField.Value = 0;
            end

            % COMB
            app.CHCOMB = app.spec_param.CHCOMB;
            app.NCOMBSpinner.Value = length(app.CHCOMB);
            indexs = num2cell((1:length(app.CHCOMB)).');
            app.UITable_COMB.Data = [indexs,app.CHCOMB];
            % OMIT
            app.CHOMIT = app.spec_param.CHOMIT;
            app.NOMITSpinner.Value = length(app.CHOMIT);
            indexs = num2cell((1:length(app.CHOMIT)).');
            app.UITable_OMIT.Data = [indexs,app.CHOMIT];
            % USE
            app.CHUSE1 = app.spec_param.CHUSE1;
            app.NUSESpinner.Value = length(app.CHUSE1);
            indexs = num2cell((1:length(app.CHUSE1)).');
            app.UITable_USE.Data = [indexs,app.CHUSE1];
            % OWNER & KEY
            app.OwnerEditField.Value = app.spec_param.OWNER;
            app.KeyEditField.Value = app.spec_param.KEY;
        end

        
        function check_configfiles(app)
            % MRSI Config
            src_config_path = fullfile(app.app_path,'Others','MRSI_config');
            src_config = dir(fullfile(src_config_path,'*.mat'));
            src_config_name = {src_config.name};
            app.configfiles_name = extractBefore(src_config_name,'.');
            app.ConfigsDropDown.Items = app.configfiles_name;
            if any(strcmp(app.ConfigsDropDown.Items,app.spec_param.Config_name))
                app.ConfigsDropDown.Value = app.spec_param.Config_name;
                temp = load(fullfile(app.app_path,'Others','MRSI_config', ...
                    [app.ConfigsDropDown.Value,'.mat']),'mrsi_param');
                app.spec_cf = temp.mrsi_param;
            else
                app.spec_cf = struct;
            end
            app.check_original_MRSI();
        end
        
        function check_original_MRSI(app)
            % Check if the current MRSI config correspond to the one saved
            if isequal(app.spec_param,app.spec_cf)
                app.Config_MRSI_state_Lamp.Color = [0,1,0];
                app.Config_MRSI_modified_Lamp.Visible = 'off';
                app.Config_MRSI_original_Lamp.Visible = 'on';
            else
                app.Config_MRSI_state_Lamp.Color = [1,0,0];
                app.Config_MRSI_modified_Lamp.Visible = 'on';
                app.Config_MRSI_original_Lamp.Visible = 'off';
            end
        end

    end
    
    methods (Access = public)
        
        function fill_lcmodel_extra_param(app)
            % Fill the LCModel parameters
            app.spec_param.CHCOMB = app.CHCOMB;
            app.spec_param.CHOMIT = app.CHOMIT;
            app.spec_param.CHUSE1 = app.CHUSE1;
            app.check_original_MRSI();
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
            [app.app_path,~] = fileparts(mfilename('fullpath'));
            % MRSI parameters (LCModel parameters)
            temp = load(fullfile(app.app_path,'Others','MRSI_parameters.mat'),'mrsi_param');
            app.spec_param = temp.mrsi_param;
            app.load_spec_param();
            app.check_configfiles();
            % MRI registration
            temp = load(fullfile(app.app_path,'Others','MRIReg_parameters.mat'),'mrireg_param');
            app.reg_param = temp.mrireg_param;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Update preferences in MainApp
            app.MainApp.mrsi_param = app.spec_param;
            app.MainApp.reg_param = app.reg_param;

            % Save the preferences in the files
            mrsi_param = app.spec_param;
            save(fullfile(app.app_path,'Others','MRSI_parameters.mat'),'mrsi_param');
            mrireg_param = app.reg_param;
            save(fullfile(app.app_path,'Others','MRIReg_parameters.mat'),'mrireg_param');

            % Close window
            delete(app)
        end

        % Button pushed function: LCModelpath_Button
        function LCModelpath_ButtonPushed(app, event)
            dir = uigetdir(app.spec_param.LCModel_path,'Choose LCModel exectuable path');
            figure(app.UIFigure)
            if dir == 0
                uialert(app.UIFigure,'No selected folder','LCModel path','Icon','warning')
                return
            end
            if isfolder(dir)
                app.spec_param.LCModel_path = dir;
                app.check_original_MRSI();
            end
        end

        % Button pushed function: Basis_set_Button
        function Basis_set_ButtonPushed(app, event)
            [file, path] = uigetfile({'*.BASIS;*.basis','Basis sets'}, ...
                "Select the basis set", ...
                app.spec_param.Basis_set);
            figure(app.UIFigure)
            if file ~= 0
                app.spec_param.Basis_set = fullfile(path,file);
                app.check_original_MRSI();
            end
        end

        % Value changed function: ConfigsDropDown
        function ConfigsDropDownValueChanged(app, event)
            src_config_path = fullfile(app.app_path,'Others','MRSI_config');
            config_filename = fullfile(src_config_path, ...
                [app.ConfigsDropDown.Value,'.mat']);
            temp = load(config_filename,'mrsi_param');
            app.spec_param = temp.mrsi_param;
            app.Config_MRSI_modified_Lamp.Visible = 'off';
            app.Config_MRSI_original_Lamp.Visible = 'on';
            app.Config_MRSI_state_Lamp.Color = [0,1,0];
            app.load_spec_param();
        end

        % Value changed function: DEGPPM_EditField, DEGZER_EditField, 
        % ...and 12 other components
        function Edit_LCModel_ValueChanged(app, event)
            % LCModel preferences update
            app.spec_param.NSIMUL = app.NSIMUL_CheckBox.Value;
            app.spec_param.VITRO = app.VITRO_CheckBox.Value;
            % app.spec_param.NRATIO = app.NRATIO_CheckBox.Value;
            app.spec_param.NRATIO = app.NRATIO_EditField.Value;
            app.spec_param.PPMstart = app.PPMstart_EditField.Value;
            app.spec_param.PPMend = app.PPMend_EditField.Value;
            app.spec_param.DKNTMN = app.DKNTMN_EditField.Value;
            app.spec_param.WCONC = app.WCONC_EditField.Value;
            app.spec_param.Rel_met = app.Rel_met_EditField.Value;
            app.spec_param.Rel_conc = app.Rel_conc_EditField.Value;
            app.spec_param.DEGZER = app.DEGZER_EditField.Value;
            app.spec_param.SDDEGZ = app.SDDEGZ_EditField.Value;
            app.spec_param.DEGPPM = app.DEGPPM_EditField.Value;
            app.spec_param.SDDEGP = app.SDDEGP_EditField.Value;
            app.check_original_MRSI();
        end

        % Button pushed function: Template_Button
        function Template_ButtonPushed(app, event)
            [file, path] = uigetfile({'*.nii.gz;*.nii','NiFTi images'}, ...
                "Select the anatomical template view", ...
                app.reg_param.template_anatomical);
            figure(app.UIFigure)
            if file ~= 0
                app.reg_param.template_anatomical = fullfile(path,file);
            end
        end

        % Button pushed function: Brain_mask_Button
        function Brain_mask_ButtonPushed(app, event)
            [file, path] = uigetfile({'*.nii.gz;*.nii','NiFTi images'}, ...
                "Select the template brain mask", ...
                app.reg_param.template_brain_mask);
            figure(app.UIFigure)
            if file ~= 0
                app.reg_param.template_brain_mask = fullfile(path,file);
            end
        end

        % Button pushed function: Anat_segmentation_Button
        function Anat_segmentation_ButtonPushed(app, event)
            [file, path] = uigetfile({'*.nii.gz;*.nii','NiFTi images'}, ...
                "Select the template segmentation with labels", ...
                app.reg_param.template_seg);
            figure(app.UIFigure)
            if file ~= 0
                app.reg_param.template_seg = fullfile(path,file);
            end
        end

        % Button pushed function: Labels_Button
        function Labels_ButtonPushed(app, event)
            [file, path] = uigetfile({'*.mat','Matlab file'}, ...
                "Select the file with labels", ...
                app.reg_param.labels_seg);
            figure(app.UIFigure)
            if file ~= 0
                app.reg_param.labels_seg = fullfile(path,file);
            end
        end

        % Value changed function: NCOMBSpinner
        function NCOMBSpinnerValueChanged(app, event)
            value = app.NCOMBSpinner.Value;
            if value == length(app.CHCOMB) + 1
                app.CHCOMB{end+1} = 'fill'; % add a new field with the 'fill' value
            elseif value == length(app.CHCOMB) - 1
                app.CHCOMB = app.CHCOMB(1:end-1); % remove the last field 
            else
                app.NCOMBSpinner.Value = length(app.CHCOMB);
            end
            indexs = num2cell((1:length(app.CHCOMB)).');
            app.UITable_COMB.Data = [indexs,app.CHCOMB];
            app.fill_lcmodel_extra_param()
        end

        % Value changed function: NOMITSpinner
        function NOMITSpinnerValueChanged(app, event)
            value = app.NOMITSpinner.Value;
            if value == length(app.CHOMIT) + 1
                app.CHOMIT{end+1} = 'fill'; % add a new field with the 'fill' value
            elseif value == length(app.CHOMIT) - 1
                app.CHOMIT = app.CHOMIT(1:end-1); % remove the last field 
            else
                app.NOMITSpinner.Value = length(app.CHOMIT);
            end
            indexs = num2cell((1:length(app.CHOMIT)).');
            app.UITable_OMIT.Data = [indexs,app.CHOMIT];
            app.fill_lcmodel_extra_param()
        end

        % Value changed function: NUSESpinner
        function NUSESpinnerValueChanged(app, event)
            value = app.NUSESpinner.Value;
            if value == length(app.CHUSE1) + 1
                app.CHUSE1{end+1} = 'fill'; % add a new field with the 'fill' value
            elseif value == length(app.CHUSE1) - 1
                app.CHUSE1 = app.CHUSE1(1:end-1); % remove the last field 
            else
                app.NUSESpinner.Value = length(app.CHUSE1);
            end
            indexs = num2cell((1:length(app.CHUSE1)).');
            app.UITable_USE.Data = [indexs,app.CHUSE1];
            app.fill_lcmodel_extra_param()
        end

        % Cell edit callback: UITable_COMB, UITable_OMIT, UITable_USE
        function UITable_extra_param_CellEdit(app, event)
            app.CHCOMB = app.UITable_COMB.Data(:,2);
            app.CHOMIT = app.UITable_OMIT.Data(:,2);
            app.CHUSE1 = app.UITable_USE.Data(:,2);
            app.fill_lcmodel_extra_param()
        end

        % Value changed function: KeyEditField, OwnerEditField
        function Licence_ValueChanged(app, event)
            app.spec_param.OWNER = app.OwnerEditField.Value;
            app.spec_param.KEY = app.KeyEditField.Value;
            app.check_original_MRSI()
        end

        % Button pushed function: Save_MRSI_config_Button
        function Save_config_ButtonPushed(app, event)
            ok = 1;
            if isempty(app.Configname_MRSI_EditField.Value)
                ok = 0;
                uialert(app.UIFigure,'Please insert a configfile name before saving', ...
                    'Save MRSI configuration','Icon','warning')
            end
            if ok
                src_config_path = fullfile(app.app_path,'Others','MRSI_config');
                save_config_filename = fullfile(src_config_path, ...
                    [app.Configname_MRSI_EditField.Value,'.mat']);
                if isfile(save_config_filename)
                    select = uiconfirm(app.UIFigure,'Do you want to overwrite an existing configuration ?', ...
                        'Save configuration');
                    if strcmp(select,'OK')
                        app.spec_param.Config_name = app.Configname_MRSI_EditField.Value;
                        mrsi_param = app.spec_param;
                        save(save_config_filename,'mrsi_param');
                        app.check_configfiles();
                    end
                else
                    app.spec_param.Config_name = app.Configname_MRSI_EditField.Value;
                    mrsi_param = app.spec_param;
                    save(save_config_filename,'mrsi_param');
                    app.check_configfiles();
                end
            end
        end

        % Button pushed function: Delete_MRSI_config_Button
        function Delete_MRSI_config_ButtonPushed(app, event)
            select = uiconfirm(app.UIFigure,'Do you really want to delete the chosen configuration ?', ...
                'Delete configuration');
            if strcmp(select,'OK')
                src_config_path = fullfile(app.app_path,'Others','MRSI_config');
                config_filename = fullfile(src_config_path, ...
                    [app.ConfigsDropDown.Value,'.mat']);
                delete(config_filename);
                if length(app.configfiles_name) > 1
                    new_mrsi_param = fullfile(src_config_path,[app.configfiles_name{1},'.mat']);
                    if ~isfile(new_mrsi_param)
                        new_mrsi_param = fullfile(src_config_path,[app.configfiles_name{2},'.mat']);
                    end
                    temp = load(new_mrsi_param,'mrsi_param');
                    app.spec_param = temp.mrsi_param;
                    app.Config_MRSI_modified_Lamp.Visible = 'off';
                    app.Config_MRSI_original_Lamp.Visible = 'on';
                    app.Config_MRSI_state_Lamp.Color = [0,1,0];
                    app.load_spec_param();
                    app.check_configfiles();
                else
                    uialert(app.UIFigure,'No more existing configs','Delete configuration')
                    app.ConfigsDropDown.Items = {''};
                end
            end
        end

        % Callback function
        function WCONC_EditFieldValueChanged(app, event)
            value = app.WCONC_EditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MRSI parameters';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'preferences_icon.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Preferences_Label = uilabel(app.UIFigure);
            app.Preferences_Label.Text = 'MRSI parameters';
            app.Preferences_logo = uiimage(app.UIFigure);
            app.Preferences_logo.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'preferences_icon.png');
            app.Toolbox_logo = uiimage(app.UIFigure);
            app.Toolbox_logo.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Logo_MRS4Brain_toolbox_square_2.png');
            app.Delete_MRSI_config_Button = uibutton(app.UIFigure, 'push');
            app.Delete_MRSI_config_Button.ButtonPushedFcn = createCallbackFcn(app, @Delete_MRSI_config_ButtonPushed, true);
            app.Delete_MRSI_config_Button.Text = 'Delete';
            app.Save_MRSI_config_Button = uibutton(app.UIFigure, 'push');
            app.Save_MRSI_config_Button.ButtonPushedFcn = createCallbackFcn(app, @Save_config_ButtonPushed, true);
            app.Save_MRSI_config_Button.Text = 'Save';
            app.Config_MRSI_modified_Lamp = uilabel(app.UIFigure);
            app.Config_MRSI_modified_Lamp.Visible = 'off';
            app.Config_MRSI_modified_Lamp.Text = 'Modified';
            app.Config_MRSI_original_Lamp = uilabel(app.UIFigure);
            app.Config_MRSI_original_Lamp.Text = 'Original';
            app.Config_MRSI_state_Lamp = uilamp(app.UIFigure);
            app.NRATIO_CheckBox = uicheckbox(app.UIFigure);
            app.NRATIO_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.NRATIO_CheckBox.Text = 'NRATIO';
            app.NRATIO_CheckBox.Value = true;
            app.VITRO_CheckBox = uicheckbox(app.UIFigure);
            app.VITRO_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.VITRO_CheckBox.Text = 'VITRO';
            app.NSIMUL_CheckBox = uicheckbox(app.UIFigure);
            app.NSIMUL_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.NSIMUL_CheckBox.Text = 'NSIMUL';
            app.Basis_set_Button = uibutton(app.UIFigure, 'push');
            app.Basis_set_Button.ButtonPushedFcn = createCallbackFcn(app, @Basis_set_ButtonPushed, true);
            app.Basis_set_Button.Text = 'Basis set';
            app.LCModelpath_Button = uibutton(app.UIFigure, 'push');
            app.LCModelpath_Button.ButtonPushedFcn = createCallbackFcn(app, @LCModelpath_ButtonPushed, true);
            app.LCModelpath_Button.Tooltip = {'Location of the LCModel progam in the current device'};
            app.LCModelpath_Button.Text = 'LCModel path';
            app.WCONC_EditField = uieditfield(app.UIFigure, 'numeric');
            app.WCONC_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.WCONC_EditField.Value = 811;
            app.PPMstartEditFieldLabel = uilabel(app.UIFigure);
            app.PPMstartEditFieldLabel.Text = 'PPM start';
            app.PPMstart_EditField = uieditfield(app.UIFigure, 'numeric');
            app.PPMstart_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.PPMstart_EditField.Value = 1.8;
            app.PPMendEditFieldLabel = uilabel(app.UIFigure);
            app.PPMendEditFieldLabel.Text = 'PPM end';
            app.PPMend_EditField = uieditfield(app.UIFigure, 'numeric');
            app.PPMend_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.PPMend_EditField.Value = 4;
            app.DKNTMNEditFieldLabel = uilabel(app.UIFigure);
            app.DKNTMNEditFieldLabel.Text = 'DKNTMN';
            app.DKNTMN_EditField = uieditfield(app.UIFigure, 'numeric');
            app.DKNTMN_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.DKNTMN_EditField.Value = 0.25;
            app.RelativemetaboliteEditFieldLabel = uilabel(app.UIFigure);
            app.RelativemetaboliteEditFieldLabel.Text = 'Relative metabolite';
            app.Rel_met_EditField = uieditfield(app.UIFigure, 'text');
            app.Rel_met_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.Rel_met_EditField.Value = 'Cr+PCr';
            app.RelativeconcentrationEditFieldLabel = uilabel(app.UIFigure);
            app.RelativeconcentrationEditFieldLabel.Text = 'Relative concentration';
            app.Rel_conc_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Rel_conc_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.Rel_conc_EditField.Value = 8;
            app.ConfigsDropDownLabel = uilabel(app.UIFigure);
            app.ConfigsDropDownLabel.Text = 'Configs';
            app.ConfigsDropDown = uidropdown(app.UIFigure);
            app.ConfigsDropDown.Items = {'MRSI_config1', 'MRSI_config2'};
            app.ConfigsDropDown.ValueChangedFcn = createCallbackFcn(app, @ConfigsDropDownValueChanged, true);
            app.ConfigsDropDown.Value = 'MRSI_config1';
            app.Configname_MRSI_EditField = uieditfield(app.UIFigure, 'text');
            app.Configname_MRSI_EditField.Value = 'MRSI_config';
            app.ConfignameEditFieldLabel_3 = uilabel(app.UIFigure);
            app.ConfignameEditFieldLabel_3.Text = 'Config name';
            app.Owner_Key_Panel = uipanel(app.UIFigure);
            app.OwnerEditFieldLabel = uilabel(app.Owner_Key_Panel);
            app.OwnerEditFieldLabel.Text = 'Owner';
            app.OwnerEditField = uieditfield(app.Owner_Key_Panel, 'text');
            app.OwnerEditField.ValueChangedFcn = createCallbackFcn(app, @Licence_ValueChanged, true);
            app.OwnerEditField.Value = 'Center for biomedical imaging, Lausanne';
            app.KeyEditFieldLabel = uilabel(app.Owner_Key_Panel);
            app.KeyEditFieldLabel.Text = 'Key';
            app.KeyEditField = uieditfield(app.Owner_Key_Panel, 'numeric');
            app.KeyEditField.ValueDisplayFormat = '%d';
            app.KeyEditField.ValueChangedFcn = createCallbackFcn(app, @Licence_ValueChanged, true);
            app.KeyEditField.Value = 210387309;
            app.LicenceLabel = uilabel(app.UIFigure);
            app.LicenceLabel.Text = 'Licence';
            app.MRI_registration_Panel = uipanel(app.UIFigure);
            app.Labels_Button = uibutton(app.MRI_registration_Panel, 'push');
            app.Labels_Button.ButtonPushedFcn = createCallbackFcn(app, @Labels_ButtonPushed, true);
            app.Labels_Button.Text = 'Segmentation labels';
            app.Anat_segmentation_Button = uibutton(app.MRI_registration_Panel, 'push');
            app.Anat_segmentation_Button.ButtonPushedFcn = createCallbackFcn(app, @Anat_segmentation_ButtonPushed, true);
            app.Anat_segmentation_Button.Text = 'Anatomical segmentation';
            app.Brain_mask_Button = uibutton(app.MRI_registration_Panel, 'push');
            app.Brain_mask_Button.ButtonPushedFcn = createCallbackFcn(app, @Brain_mask_ButtonPushed, true);
            app.Brain_mask_Button.Text = 'Template Brain mask';
            app.Template_Button = uibutton(app.MRI_registration_Panel, 'push');
            app.Template_Button.ButtonPushedFcn = createCallbackFcn(app, @Template_ButtonPushed, true);
            app.Template_Button.Text = 'Template with skull';
            app.RegistrationLabel = uilabel(app.UIFigure);
            app.RegistrationLabel.Text = 'Registration';
            app.Panel_USE = uipanel(app.UIFigure);
            app.UITable_USE = uitable(app.Panel_USE);
            app.UITable_USE.ColumnName = {'Index'; 'Used'};
            app.UITable_USE.RowName = {};
            app.UITable_USE.ColumnEditable = [false true];
            app.UITable_USE.CellEditCallback = createCallbackFcn(app, @UITable_extra_param_CellEdit, true);
            app.NUSESpinnerLabel = uilabel(app.Panel_USE);
            app.NUSESpinnerLabel.Text = 'NUSE';
            app.NUSESpinner = uispinner(app.Panel_USE);
            app.NUSESpinner.Limits = [0 Inf];
            app.NUSESpinner.RoundFractionalValues = 'on';
            app.NUSESpinner.ValueChangedFcn = createCallbackFcn(app, @NUSESpinnerValueChanged, true);
            app.UseLabel = uilabel(app.UIFigure);
            app.UseLabel.Text = 'Use';
            app.Panel_OMIT = uipanel(app.UIFigure);
            app.UITable_OMIT = uitable(app.Panel_OMIT);
            app.UITable_OMIT.ColumnName = {'Index'; 'Omitted'};
            app.UITable_OMIT.RowName = {};
            app.UITable_OMIT.ColumnEditable = [false true];
            app.UITable_OMIT.CellEditCallback = createCallbackFcn(app, @UITable_extra_param_CellEdit, true);
            app.NOMITSpinnerLabel = uilabel(app.Panel_OMIT);
            app.NOMITSpinnerLabel.Text = 'NOMIT';
            app.NOMITSpinner = uispinner(app.Panel_OMIT);
            app.NOMITSpinner.Limits = [0 Inf];
            app.NOMITSpinner.RoundFractionalValues = 'on';
            app.NOMITSpinner.ValueChangedFcn = createCallbackFcn(app, @NOMITSpinnerValueChanged, true);
            app.OmissionLabel = uilabel(app.UIFigure);
            app.OmissionLabel.Text = 'Omission';
            app.Panel_COMB = uipanel(app.UIFigure);
            app.NCOMBSpinnerLabel = uilabel(app.Panel_COMB);
            app.NCOMBSpinnerLabel.Text = 'NCOMB';
            app.UITable_COMB = uitable(app.Panel_COMB);
            app.UITable_COMB.ColumnName = {'Index'; 'Combination'};
            app.UITable_COMB.RowName = {};
            app.UITable_COMB.ColumnEditable = [false true];
            app.UITable_COMB.CellEditCallback = createCallbackFcn(app, @UITable_extra_param_CellEdit, true);
            app.UITable_COMB.Multiselect = 'off';
            app.NCOMBSpinner = uispinner(app.Panel_COMB);
            app.NCOMBSpinner.Limits = [0 Inf];
            app.NCOMBSpinner.RoundFractionalValues = 'on';
            app.NCOMBSpinner.ValueChangedFcn = createCallbackFcn(app, @NCOMBSpinnerValueChanged, true);
            app.CombinationLabel = uilabel(app.UIFigure);
            app.CombinationLabel.Text = 'Combination';
            app.WCONCEditFieldLabel = uilabel(app.UIFigure);
            app.WCONCEditFieldLabel.Text = 'WCONC';
            app.NRATIO_EditField = uieditfield(app.UIFigure, 'numeric');
            app.NRATIO_EditField.Limits = [0 Inf];
            app.NRATIO_EditField.ValueDisplayFormat = '%.0f';
            app.NRATIO_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.NRATIO_EditField.Value = 12;
            app.NRATIOLabel = uilabel(app.UIFigure);
            app.NRATIOLabel.Text = 'NRATIO';
            app.DEGZERLabel = uilabel(app.UIFigure);
            app.DEGZERLabel.Text = 'DEGZER';
            app.DEGZER_EditField = uieditfield(app.UIFigure, 'numeric');
            app.DEGZER_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.DEGZERLabel_2 = uilabel(app.UIFigure);
            app.DEGZERLabel_2.Text = 'SDDEGZ';
            app.SDDEGZ_EditField = uieditfield(app.UIFigure, 'numeric');
            app.SDDEGZ_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.SDDEGZ_EditField.Value = 999;
            app.DEGZERLabel_3 = uilabel(app.UIFigure);
            app.DEGZERLabel_3.Text = 'DEGPPM';
            app.DEGPPM_EditField = uieditfield(app.UIFigure, 'numeric');
            app.DEGPPM_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.DEGZERLabel_4 = uilabel(app.UIFigure);
            app.DEGZERLabel_4.Text = 'SDDEGP';
            app.SDDEGP_EditField = uieditfield(app.UIFigure, 'numeric');
            app.SDDEGP_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = preferences_MRSI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end