classdef preferences_SVS_DWS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SDDEGP_EditField               matlab.ui.control.NumericEditField
        DEGZERLabel_4                  matlab.ui.control.Label
        DEGPPM_EditField               matlab.ui.control.NumericEditField
        DEGZERLabel_3                  matlab.ui.control.Label
        SDDEGZ_EditField               matlab.ui.control.NumericEditField
        DEGZERLabel_2                  matlab.ui.control.Label
        DEGZER_EditField               matlab.ui.control.NumericEditField
        DEGZERLabel                    matlab.ui.control.Label
        Toolbox_logo                   matlab.ui.control.Image
        NRATIOLabel                    matlab.ui.control.Label
        NRATIO_EditField               matlab.ui.control.NumericEditField
        WCONCEditFieldLabel            matlab.ui.control.Label
        PreprocessingLabel             matlab.ui.control.Label
        Preprocessing_Panel            matlab.ui.container.Panel
        FidA_smallvoxel_CheckBox       matlab.ui.control.CheckBox
        FidAstepsLabel                 matlab.ui.control.Label
        FidA_align_avg_CheckBox        matlab.ui.control.CheckBox
        FidA_rm_badavg_CheckBox        matlab.ui.control.CheckBox
        ISISButton                     matlab.ui.control.StateButton
        Rejection_threshold_EditField  matlab.ui.control.NumericEditField
        RejectionthresholdEditFieldLabel  matlab.ui.control.Label
        Maximum_time_EditField         matlab.ui.control.NumericEditField
        MaximumtimeEditFieldLabel      matlab.ui.control.Label
        FrequencyrangeppmLabel         matlab.ui.control.Label
        max_ppm_EditField              matlab.ui.control.NumericEditField
        maxEditFieldLabel              matlab.ui.control.Label
        min_ppm_EditField              matlab.ui.control.NumericEditField
        minEditFieldLabel              matlab.ui.control.Label
        LineBroadeningEditField        matlab.ui.control.NumericEditField
        LineBroadeningEditFieldLabel   matlab.ui.control.Label
        Preferences_logo               matlab.ui.control.Image
        Preferences_Label              matlab.ui.control.Label
        LCModelpath_Button             matlab.ui.control.Button
        Basis_set_Button               matlab.ui.control.Button
        Spec_ConfigsDropDown           matlab.ui.control.DropDown
        ConfigsLabel                   matlab.ui.control.Label
        Rel_conc_EditField             matlab.ui.control.NumericEditField
        RelativeconcentrationEditFieldLabel  matlab.ui.control.Label
        Rel_met_EditField              matlab.ui.control.EditField
        RelativemetaboliteEditFieldLabel  matlab.ui.control.Label
        DKNTMN_EditField               matlab.ui.control.NumericEditField
        DKNTMNEditFieldLabel           matlab.ui.control.Label
        PPMend_EditField               matlab.ui.control.NumericEditField
        PPMendEditFieldLabel           matlab.ui.control.Label
        PPMstart_EditField             matlab.ui.control.NumericEditField
        PPMstartEditFieldLabel         matlab.ui.control.Label
        ConfignameEditFieldLabel       matlab.ui.control.Label
        WCONC_EditField                matlab.ui.control.NumericEditField
        Spec_Configname_EditField      matlab.ui.control.EditField
        NSIMUL_CheckBox                matlab.ui.control.CheckBox
        VITRO_CheckBox                 matlab.ui.control.CheckBox
        NRATIO_CheckBox                matlab.ui.control.CheckBox
        Spec_Config_state_Lamp         matlab.ui.control.Lamp
        Spec_Config_original_Lamp      matlab.ui.control.Label
        Spec_Config_modified_Lamp      matlab.ui.control.Label
        Save_spec_Config_Button        matlab.ui.control.Button
        Delete_spec_Config_Button      matlab.ui.control.Button
        LicenceLabel                   matlab.ui.control.Label
        Owner_Key_Panel                matlab.ui.container.Panel
        KeyEditField                   matlab.ui.control.NumericEditField
        KeyEditFieldLabel              matlab.ui.control.Label
        OwnerEditField                 matlab.ui.control.EditField
        OwnerEditFieldLabel            matlab.ui.control.Label
        CombinationLabel               matlab.ui.control.Label
        Panel_COMB                     matlab.ui.container.Panel
        UITable_COMB                   matlab.ui.control.Table
        NCOMBSpinner                   matlab.ui.control.Spinner
        NCOMBSpinnerLabel              matlab.ui.control.Label
        OmissionLabel                  matlab.ui.control.Label
        Panel_OMIT                     matlab.ui.container.Panel
        NOMITSpinner                   matlab.ui.control.Spinner
        NOMITSpinnerLabel              matlab.ui.control.Label
        UITable_OMIT                   matlab.ui.control.Table
        UseLabel                       matlab.ui.control.Label
        Panel_USE                      matlab.ui.container.Panel
        NUSESpinner                    matlab.ui.control.Spinner
        NUSESpinnerLabel               matlab.ui.control.Label
        UITable_USE                    matlab.ui.control.Table
    end

    
    properties (Access = private)
        MainApp                     % Link to parent app
        app_path                    % Path of the preferences_SVS_DWS app
        spec_type                   % Type of spectroscopy (SVS or DWS)
        spec_param                  % SVS/DWS parameters struct
        spec_cf                     % SVS/DWS parameters config files
        CHCOMB                      % Combination of metabolites
        CHOMIT                      % Omission of metabolites
        CHUSE1                      % Used metabolites
        spec_configfiles_name       % SVS/DWS config filenames 
    end
    
    methods (Access = private)

        function load_spec_param(app)
            % Preprocessing preferences loading
            app.ISISButton.Value = app.spec_param.ISIS_bool;
            app.LineBroadeningEditField.Value = app.spec_param.LBall;
            app.min_ppm_EditField.Value = app.spec_param.minppm;
            app.max_ppm_EditField.Value = app.spec_param.maxppm;
            app.Maximum_time_EditField.Value = app.spec_param.t_max;
            app.Rejection_threshold_EditField.Value = app.spec_param.sd_thresh;
            % LCModel preferences loading
            app.Spec_Configname_EditField.Value = app.spec_param.Config_name;
            app.NSIMUL_CheckBox.Value = app.spec_param.NSIMUL;
            app.VITRO_CheckBox.Value = app.spec_param.VITRO;
            % app.NRATIO_CheckBox.Value = app.spec_param.NRATIO;
            app.PPMstart_EditField.Value = app.spec_param.PPMstart;
            app.PPMend_EditField.Value = app.spec_param.PPMend;
            app.DKNTMN_EditField.Value = app.spec_param.DKNTMN;
            app.WCONC_EditField.Value = app.spec_param.WCONC;
            app.Rel_met_EditField.Value = app.spec_param.Rel_met;
            app.Rel_conc_EditField.Value = app.spec_param.Rel_conc;

            %Version 11 (03/13/2024) - Check if DegZero and DegPPM exist
            if ~isfield(app.spec_param,'DEGZER')
                app.spec_param.DEGZER = 0;
                app.DEGZER_EditField.Value = 0;
            end

            if ~isfield(app.spec_param,'SDDEGZ')
                app.spec_param.SDDEGZ = 999;
                app.SDDEGZ_EditField.Value = 999;
            end

            if ~isfield(app.spec_param,'DEGPPM')
                app.spec_param.DEGPPM = 0;
                app.DEGPPM_EditField.Value = 0;
            end

            if ~isfield(app.spec_param,'SDDEGP')
                app.spec_param.SDDEGP = 0;
                app.SDDEGP_EditField.Value = 0;
            end

            % COMB
            app.CHCOMB = app.spec_param.CHCOMB;
            app.NCOMBSpinner.Value = length(app.CHCOMB);
            indexs = num2cell((1:length(app.CHCOMB)).');
            app.UITable_COMB.Data = [indexs,app.CHCOMB];
            % OMIT
            app.CHOMIT = app.spec_param.CHOMIT;
            app.NOMITSpinner.Value = length(app.CHOMIT);
            indexs = num2cell((1:length(app.CHOMIT)).');
            app.UITable_OMIT.Data = [indexs,app.CHOMIT];
            % USE
            app.CHUSE1 = app.spec_param.CHUSE1;
            app.NUSESpinner.Value = length(app.CHUSE1);
            indexs = num2cell((1:length(app.CHUSE1)).');
            app.UITable_USE.Data = [indexs,app.CHUSE1];
            % OWNER & KEY
            app.OwnerEditField.Value = app.spec_param.OWNER;
            app.KeyEditField.Value = app.spec_param.KEY;
            % FidA steps
            app.FidA_align_avg_CheckBox.Value = app.spec_param.FidA_align_avg;
            app.FidA_rm_badavg_CheckBox.Value = app.spec_param.FidA_rm_badavg;
        end


        function check_spec_configfiles(app)
            % SVS or DWS Configs
            if strcmp(app.spec_type,'SVS')
                src_config_path = fullfile(app.app_path,'Others','SVS_config');
            elseif strcmp(app.spec_type,'DWS')
                src_config_path = fullfile(app.app_path,'Others','DWS_config');
            end
            src_config = dir(fullfile(src_config_path,'*.mat'));
            src_config_name = {src_config.name};
            app.spec_configfiles_name = extractBefore(src_config_name,'.');
            app.Spec_ConfigsDropDown.Items = app.spec_configfiles_name;
            if any(strcmp(app.Spec_ConfigsDropDown.Items,app.spec_param.Config_name))
                app.Spec_ConfigsDropDown.Value = app.spec_param.Config_name;
                if strcmp(app.spec_type,'SVS')
                    temp = load(fullfile(app.app_path,'Others','SVS_config', ...
                        [app.Spec_ConfigsDropDown.Value,'.mat']),'svs_param');
                    app.spec_cf = temp.svs_param;
                elseif strcmp(app.spec_type,'DWS')
                    temp = load(fullfile(app.app_path,'Others','DWS_config', ...
                        [app.Spec_ConfigsDropDown.Value,'.mat']),'dws_param');
                    app.spec_cf = temp.dws_param;
                end
            else
                app.spec_cf = struct;
            end
            app.check_original_spec_param();
        end

        
        function check_original_spec_param(app)
            % Check if current SVS config correspond to the one saved
            if isequal(app.spec_param,app.spec_cf)
                app.Spec_Config_state_Lamp.Color = [0,1,0];
                app.Spec_Config_modified_Lamp.Visible = 'off';
                app.Spec_Config_original_Lamp.Visible = 'on';
            else
                app.Spec_Config_state_Lamp.Color = [1,0,0];
                app.Spec_Config_modified_Lamp.Visible = 'on';
                app.Spec_Config_original_Lamp.Visible = 'off';
            end
        end
        

        function fill_lcmodel_extra_param(app)
            % Fill the LCModel parameters
            app.spec_param.CHCOMB = app.CHCOMB;
            app.spec_param.CHOMIT = app.CHOMIT;
            app.spec_param.CHUSE1 = app.CHUSE1;
            app.check_original_spec_param();
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, type)
            app.MainApp = mainapp;
            [app.app_path,~] = fileparts(mfilename('fullpath'));
            app.spec_type = type;
            disp(type)
            % SVS / DWS preferences
            if strcmp(app.spec_type,'SVS')
                temp = load(fullfile(app.app_path,'Others','SVS_parameters.mat'),'svs_param');
                app.spec_param = temp.svs_param;
            elseif strcmp(app.spec_type,'DWS')
                temp = load(fullfile(app.app_path,'Others','DWS_parameters.mat'),'dws_param');
                app.spec_param = temp.dws_param;
            end
            app.load_spec_param();
            app.check_spec_configfiles();
        end

        % Value changed function: Spec_ConfigsDropDown
        function Spec_ConfigsDropDownValueChanged(app, event)
            if strcmp(app.spec_type,'SVS')
                src_config_path = fullfile(app.app_path,'Others','SVS_config');
                config_filename = fullfile(src_config_path, ...
                    [app.Spec_ConfigsDropDown.Value,'.mat']);
                temp = load(config_filename,'svs_param');
                app.spec_param = temp.svs_param;
            elseif strcmp(app.spec_type,'DWS')
                src_config_path = fullfile(app.app_path,'Others','DWS_config');
                config_filename = fullfile(src_config_path, ...
                    [app.Spec_ConfigsDropDown.Value,'.mat']);
                temp = load(config_filename,'dws_param');
                app.spec_param = temp.dws_param;
            end
            app.Spec_Config_modified_Lamp.Visible = 'off';
            app.Spec_Config_original_Lamp.Visible = 'on';
            app.Spec_Config_state_Lamp.Color = [0,1,0];
            app.load_spec_param();
        end

        % Value changed function: FidA_align_avg_CheckBox, 
        % ...and 8 other components
        function Preprocessing_ValueChanged(app, event)
            app.spec_param.ISIS_bool = app.ISISButton.Value;
            app.spec_param.LBall = app.LineBroadeningEditField.Value;
            app.spec_param.minppm = app.min_ppm_EditField.Value;
            app.spec_param.maxppm = app.max_ppm_EditField.Value;
            app.spec_param.t_max = app.Maximum_time_EditField.Value;
            app.spec_param.sd_thresh = app.Rejection_threshold_EditField.Value;
            app.spec_param.FidA_align_avg = app.FidA_align_avg_CheckBox.Value;
            app.spec_param.FidA_rm_badavg = app.FidA_rm_badavg_CheckBox.Value;
            app.spec_param.FidA_smallvoxel = app.FidA_smallvoxel_CheckBox.Value;
            app.check_original_spec_param();
        end

        % Button pushed function: LCModelpath_Button
        function LCModelpath_ButtonPushed(app, event)
            dir = uigetdir(app.spec_param.LCModel_path,'Choose LCModel exectuable path');
            figure(app.UIFigure)
            if dir == 0
                uialert(app.UIFigure,'No selected folder','LCModel path','Icon','warning')
                return
            end
            if isfolder(dir)
                app.spec_param.LCModel_path = dir;
                app.check_original_spec_param();
            end
        end

        % Button pushed function: Basis_set_Button
        function Basis_set_ButtonPushed(app, event)
            [file, path] = uigetfile({'*.BASIS;*.basis','Basis sets'}, ...
                "Select the basis set", ...
                app.spec_param.Basis_set);
            figure(app.UIFigure)
            if file ~= 0
                app.spec_param.Basis_set = fullfile(path,file);
                app.check_original_spec_param();
            end
        end

        % Value changed function: DEGPPM_EditField, DEGZER_EditField, 
        % ...and 12 other components
        function Edit_LCModel_ValueChanged(app, event)
            % LCModel preferences update
            app.spec_param.NSIMUL = app.NSIMUL_CheckBox.Value;
            app.spec_param.VITRO = app.VITRO_CheckBox.Value;
            % app.spec_param.NRATIO = app.NRATIO_CheckBox.Value;
            app.spec_param.NRATIO = app.NRATIO_EditField.Value;
            app.spec_param.PPMstart = app.PPMstart_EditField.Value;
            app.spec_param.PPMend = app.PPMend_EditField.Value;
            app.spec_param.DKNTMN = app.DKNTMN_EditField.Value;
            app.spec_param.Rel_met = app.Rel_met_EditField.Value;
            app.spec_param.Rel_conc = app.Rel_conc_EditField.Value;
            app.spec_param.WCONC = app.WCONC_EditField.Value;
            app.spec_param.DEGZER = app.DEGZER_EditField.Value;
            app.spec_param.SDDEGZ = app.SDDEGZ_EditField.Value;
            app.spec_param.DEGPPM = app.DEGPPM_EditField.Value;
            app.spec_param.SDDEGP = app.SDDEGP_EditField.Value;
            app.check_original_spec_param();
        end

        % Button pushed function: Save_spec_Config_Button
        function Save_spec_Config_ButtonPushed(app, event)
            ok = 1;
            if isempty(app.Spec_Configname_EditField.Value)
                ok = 0;
                uialert(app.UIFigure,'Please insert a configfile name before saving', ...
                    'Save SVS/DWS configuration','Icon','warning')
            end
            if or(or(any(strcmp(app.CHCOMB,'fill')), ...
                    any(strcmp(app.CHOMIT,'fill'))), ...
                    any(strcmp(app.CHUSE1,'fill')))
                ok = 0;
                uialert(app.UIFigure,{'Please fill the cells before saving', ...
                    '(Unless it won''t be saved)'}, ...
                    'Save SVS/DWS configuration','Icon','warning')
            end
            if ok
                % SVS or DWS Configs
                if strcmp(app.spec_type,'SVS')
                    src_config_path = fullfile(app.app_path,'Others','SVS_config');
                elseif strcmp(app.spec_type,'DWS')
                    src_config_path = fullfile(app.app_path,'Others','DWS_config');
                end
                save_config_filename = fullfile(src_config_path, ...
                    [app.Spec_Configname_EditField.Value,'.mat']);
                if isfile(save_config_filename)
                    select = uiconfirm(app.UIFigure,'Do you want to overwrite an existing configuration ?', ...
                        'Save configuration');
                    if strcmp(select,'OK')
                        ok_save = 1;
                    end
                else
                    ok_save = 1;
                end
                if ok_save
                    app.spec_param.Config_name = app.Spec_Configname_EditField.Value;
                    if strcmp(app.spec_type,'SVS')
                        svs_param = app.spec_param;
                        save(save_config_filename,'svs_param');
                    elseif strcmp(app.spec_type,'DWS')
                        dws_param = app.spec_param;
                        save(save_config_filename,'dws_param');
                    end
                    app.check_spec_configfiles();
                end
            end
        end

        % Button pushed function: Delete_spec_Config_Button
        function Delete_spec_Config_ButtonPushed(app, event)
            select = uiconfirm(app.UIFigure,'Do you really want to delete the chosen configuration ?', ...
                'Delete configuration');
            if strcmp(select,'OK')
                % SVS or DWS Configs
                if strcmp(app.spec_type,'SVS')
                    src_config_path = fullfile(app.app_path,'Others','SVS_config');
                elseif strcmp(app.spec_type,'DWS')
                    src_config_path = fullfile(app.app_path,'Others','DWS_config');
                end
                config_filename = fullfile(src_config_path, ...
                    [app.Spec_ConfigsDropDown.Value,'.mat']);
                delete(config_filename);
                if length(app.spec_configfiles_name) > 1
                    new_spec_param = fullfile(src_config_path,[app.spec_configfiles_name{1},'.mat']);
                    if ~isfile(new_spec_param)
                        new_spec_param = fullfile(src_config_path,[app.spec_configfiles_name{2},'.mat']);
                    end
                    if strcmp(app.spec_type,'SVS')
                        temp = load(new_spec_param,'svs_param');
                        app.spec_param = temp.svs_param;
                    elseif strcmp(app.spec_type,'DWS')
                        temp = load(new_spec_param,'dws_param');
                        app.spec_param = temp.dws_param;
                    end
                    app.Spec_Config_modified_Lamp.Visible = 'off';
                    app.Spec_Config_original_Lamp.Visible = 'on';
                    app.Spec_Config_state_Lamp.Color = [0,1,0];
                    app.load_spec_param();
                    app.check_spec_configfiles();
                else
                    uialert(app.UIFigure,'No more existing configs','Delete configuration')
                    app.Spec_ConfigsDropDown.Items = {''};
                end
            end
        end

        % Value changed function: NCOMBSpinner
        function NCOMBSpinnerValueChanged(app, event)
            value = app.NCOMBSpinner.Value;
            if value == length(app.CHCOMB) + 1
                app.CHCOMB{end+1} = 'fill'; % add a new field with the 'fill' value
            elseif value == length(app.CHCOMB) - 1
                app.CHCOMB = app.CHCOMB(1:end-1); % remove the last field 
            else
                app.NCOMBSpinner.Value = length(app.CHCOMB);
            end
            indexs = num2cell((1:length(app.CHCOMB)).');
            app.UITable_COMB.Data = [indexs,app.CHCOMB];
            app.fill_lcmodel_extra_param()
        end

        % Value changed function: NOMITSpinner
        function NOMITSpinnerValueChanged(app, event)
            value = app.NOMITSpinner.Value;
            if value == length(app.CHOMIT) + 1
                app.CHOMIT{end+1} = 'fill'; % add a new field with the 'fill' value
            elseif value == length(app.CHOMIT) - 1
                app.CHOMIT = app.CHOMIT(1:end-1); % remove the last field 
            else
                app.NOMITSpinner.Value = length(app.CHOMIT);
            end
            indexs = num2cell((1:length(app.CHOMIT)).');
            app.UITable_OMIT.Data = [indexs,app.CHOMIT];
            app.fill_lcmodel_extra_param()
        end

        % Value changed function: NUSESpinner
        function NUSESpinnerValueChanged(app, event)
            value = app.NUSESpinner.Value;
            if value == length(app.CHUSE1) + 1
                app.CHUSE1{end+1} = 'fill'; % add a new field with the 'fill' value
            elseif value == length(app.CHUSE1) - 1
                app.CHUSE1 = app.CHUSE1(1:end-1); % remove the last field 
            else
                app.NUSESpinner.Value = length(app.CHUSE1);
            end
            indexs = num2cell((1:length(app.CHUSE1)).');
            app.UITable_USE.Data = [indexs,app.CHUSE1];
            app.fill_lcmodel_extra_param()
        end

        % Cell edit callback: UITable_COMB, UITable_OMIT, UITable_USE
        function UITable_extraparam_CellEdit(app, event)
            app.CHCOMB = app.UITable_COMB.Data(:,2);
            app.CHOMIT = app.UITable_OMIT.Data(:,2);
            app.CHUSE1 = app.UITable_USE.Data(:,2);
            app.fill_lcmodel_extra_param()
        end

        % Value changed function: KeyEditField, OwnerEditField
        function OwnerEditFieldValueChanged(app, event)
            app.spec_param.OWNER = app.OwnerEditField.Value;
            app.spec_param.KEY = app.KeyEditField.Value;
            app.check_original_spec_param()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if strcmp(app.spec_type,'SVS')
                % Update preferences in MainApp
                app.MainApp.svs_param = app.spec_param;
                % Save the preferences in the files
                svs_param = app.spec_param;
                save(fullfile(app.app_path,'Others','SVS_parameters.mat'),'svs_param');
            elseif strcmp(app.spec_type,'DWS')
                % Update preferences in MainApp
                app.MainApp.dws_param = app.spec_param;
                % Save the preferences in the files
                dws_param = app.spec_param;
                save(fullfile(app.app_path,'Others','DWS_parameters.mat'),'dws_param');
            end

            % Close window
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SVS/DWS preferences';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'preferences_icon.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel_USE = uipanel(app.UIFigure);
            app.UITable_USE = uitable(app.Panel_USE);
            app.UITable_USE.ColumnName = {'Index'; 'Used'};
            app.UITable_USE.RowName = {};
            app.UITable_USE.ColumnEditable = [false true];
            app.UITable_USE.CellEditCallback = createCallbackFcn(app, @UITable_extraparam_CellEdit, true);
            app.NUSESpinnerLabel = uilabel(app.Panel_USE);
            app.NUSESpinnerLabel.Text = 'NUSE';
            app.NUSESpinner = uispinner(app.Panel_USE);
            app.NUSESpinner.Limits = [0 Inf];
            app.NUSESpinner.RoundFractionalValues = 'on';
            app.NUSESpinner.ValueChangedFcn = createCallbackFcn(app, @NUSESpinnerValueChanged, true);
            app.UseLabel = uilabel(app.UIFigure);
            app.UseLabel.Text = 'Use';
            app.Panel_OMIT = uipanel(app.UIFigure);
            app.UITable_OMIT = uitable(app.Panel_OMIT);
            app.UITable_OMIT.ColumnName = {'Index'; 'Omitted'};
            app.UITable_OMIT.RowName = {};
            app.UITable_OMIT.ColumnEditable = [false true];
            app.UITable_OMIT.CellEditCallback = createCallbackFcn(app, @UITable_extraparam_CellEdit, true);
            app.NOMITSpinnerLabel = uilabel(app.Panel_OMIT);
            app.NOMITSpinnerLabel.Text = 'NOMIT';
            app.NOMITSpinner = uispinner(app.Panel_OMIT);
            app.NOMITSpinner.Limits = [0 Inf];
            app.NOMITSpinner.RoundFractionalValues = 'on';
            app.NOMITSpinner.ValueChangedFcn = createCallbackFcn(app, @NOMITSpinnerValueChanged, true);
            app.OmissionLabel = uilabel(app.UIFigure);
            app.OmissionLabel.Text = 'Omission';
            app.Panel_COMB = uipanel(app.UIFigure);
            app.NCOMBSpinnerLabel = uilabel(app.Panel_COMB);
            app.NCOMBSpinnerLabel.Text = 'NCOMB';
            app.NCOMBSpinner = uispinner(app.Panel_COMB);
            app.NCOMBSpinner.Limits = [0 Inf];
            app.NCOMBSpinner.RoundFractionalValues = 'on';
            app.NCOMBSpinner.ValueChangedFcn = createCallbackFcn(app, @NCOMBSpinnerValueChanged, true);
            app.UITable_COMB = uitable(app.Panel_COMB);
            app.UITable_COMB.ColumnName = {'Index'; 'Combination'};
            app.UITable_COMB.RowName = {};
            app.UITable_COMB.ColumnEditable = [false true];
            app.UITable_COMB.CellEditCallback = createCallbackFcn(app, @UITable_extraparam_CellEdit, true);
            app.UITable_COMB.Multiselect = 'off';
            app.CombinationLabel = uilabel(app.UIFigure);
            app.CombinationLabel.Text = 'Combination';
            app.Owner_Key_Panel = uipanel(app.UIFigure);
            app.OwnerEditFieldLabel = uilabel(app.Owner_Key_Panel);
            app.OwnerEditFieldLabel.Text = 'Owner';
            app.OwnerEditField = uieditfield(app.Owner_Key_Panel, 'text');
            app.OwnerEditField.ValueChangedFcn = createCallbackFcn(app, @OwnerEditFieldValueChanged, true);
            app.OwnerEditField.Value = 'Center for biomedical imaging, Lausanne';
            app.KeyEditFieldLabel = uilabel(app.Owner_Key_Panel);
            app.KeyEditFieldLabel.Text = 'Key';
            app.KeyEditField = uieditfield(app.Owner_Key_Panel, 'numeric');
            app.KeyEditField.ValueDisplayFormat = '%d';
            app.KeyEditField.ValueChangedFcn = createCallbackFcn(app, @OwnerEditFieldValueChanged, true);
            app.KeyEditField.Value = 210387309;
            app.LicenceLabel = uilabel(app.UIFigure);
            app.LicenceLabel.Text = 'Licence';
            app.Delete_spec_Config_Button = uibutton(app.UIFigure, 'push');
            app.Delete_spec_Config_Button.ButtonPushedFcn = createCallbackFcn(app, @Delete_spec_Config_ButtonPushed, true);
            app.Delete_spec_Config_Button.Text = 'Delete';
            app.Save_spec_Config_Button = uibutton(app.UIFigure, 'push');
            app.Save_spec_Config_Button.ButtonPushedFcn = createCallbackFcn(app, @Save_spec_Config_ButtonPushed, true);
            app.Save_spec_Config_Button.Text = 'Save';
            app.Spec_Config_modified_Lamp = uilabel(app.UIFigure);
            app.Spec_Config_modified_Lamp.Visible = 'off';
            app.Spec_Config_modified_Lamp.Text = 'Modified';
            app.Spec_Config_original_Lamp = uilabel(app.UIFigure);
            app.Spec_Config_original_Lamp.Text = 'Original';
            app.Spec_Config_state_Lamp = uilamp(app.UIFigure);
            app.NRATIO_CheckBox = uicheckbox(app.UIFigure);
            app.NRATIO_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.NRATIO_CheckBox.Text = 'NRATIO';
            app.NRATIO_CheckBox.Value = true;
            app.VITRO_CheckBox = uicheckbox(app.UIFigure);
            app.VITRO_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.VITRO_CheckBox.Text = 'VITRO';
            app.NSIMUL_CheckBox = uicheckbox(app.UIFigure);
            app.NSIMUL_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.NSIMUL_CheckBox.Text = 'NSIMUL';
            app.Spec_Configname_EditField = uieditfield(app.UIFigure, 'text');
            app.Spec_Configname_EditField.Value = 'SVS_config';
            app.WCONC_EditField = uieditfield(app.UIFigure, 'numeric');
            app.WCONC_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.WCONC_EditField.Value = 811;
            app.ConfignameEditFieldLabel = uilabel(app.UIFigure);
            app.ConfignameEditFieldLabel.Text = 'Config name';
            app.PPMstartEditFieldLabel = uilabel(app.UIFigure);
            app.PPMstartEditFieldLabel.Text = 'PPM start';
            app.PPMstart_EditField = uieditfield(app.UIFigure, 'numeric');
            app.PPMstart_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.PPMstart_EditField.Value = 1.8;
            app.PPMendEditFieldLabel = uilabel(app.UIFigure);
            app.PPMendEditFieldLabel.Text = 'PPM end';
            app.PPMend_EditField = uieditfield(app.UIFigure, 'numeric');
            app.PPMend_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.PPMend_EditField.Value = 4;
            app.DKNTMNEditFieldLabel = uilabel(app.UIFigure);
            app.DKNTMNEditFieldLabel.Text = 'DKNTMN';
            app.DKNTMN_EditField = uieditfield(app.UIFigure, 'numeric');
            app.DKNTMN_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.DKNTMN_EditField.Value = 0.25;
            app.RelativemetaboliteEditFieldLabel = uilabel(app.UIFigure);
            app.RelativemetaboliteEditFieldLabel.Text = 'Relative metabolite';
            app.Rel_met_EditField = uieditfield(app.UIFigure, 'text');
            app.Rel_met_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.Rel_met_EditField.Value = 'Cr+PCr';
            app.RelativeconcentrationEditFieldLabel = uilabel(app.UIFigure);
            app.RelativeconcentrationEditFieldLabel.Text = 'Relative concentration';
            app.Rel_conc_EditField = uieditfield(app.UIFigure, 'numeric');
            app.Rel_conc_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.Rel_conc_EditField.Value = 8;
            app.ConfigsLabel = uilabel(app.UIFigure);
            app.ConfigsLabel.Text = 'Configs';
            app.Spec_ConfigsDropDown = uidropdown(app.UIFigure);
            app.Spec_ConfigsDropDown.Items = {'SVS_config1', 'SVS_config2'};
            app.Spec_ConfigsDropDown.ValueChangedFcn = createCallbackFcn(app, @Spec_ConfigsDropDownValueChanged, true);
            app.Spec_ConfigsDropDown.Value = 'SVS_config1';
            app.Basis_set_Button = uibutton(app.UIFigure, 'push');
            app.Basis_set_Button.ButtonPushedFcn = createCallbackFcn(app, @Basis_set_ButtonPushed, true);
            app.Basis_set_Button.Text = 'Basis set';
            app.LCModelpath_Button = uibutton(app.UIFigure, 'push');
            app.LCModelpath_Button.ButtonPushedFcn = createCallbackFcn(app, @LCModelpath_ButtonPushed, true);
            app.LCModelpath_Button.Tooltip = {'Location of the LCModel progam in the current device'};
            app.LCModelpath_Button.Text = 'LCModel path';
            app.Preferences_Label = uilabel(app.UIFigure);
            app.Preferences_Label.Text = 'Preferences SVS/DWS';
            app.Preferences_logo = uiimage(app.UIFigure);
            app.Preferences_logo.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'preferences_icon.png');
            app.Preprocessing_Panel = uipanel(app.UIFigure);
            app.LineBroadeningEditFieldLabel = uilabel(app.Preprocessing_Panel);
            app.LineBroadeningEditFieldLabel.Text = 'Line Broadening';
            app.LineBroadeningEditField = uieditfield(app.Preprocessing_Panel, 'numeric');
            app.LineBroadeningEditField.ValueChangedFcn = createCallbackFcn(app, @Preprocessing_ValueChanged, true);
            app.LineBroadeningEditField.Value = 12;
            app.minEditFieldLabel = uilabel(app.Preprocessing_Panel);
            app.minEditFieldLabel.Text = 'min';
            app.min_ppm_EditField = uieditfield(app.Preprocessing_Panel, 'numeric');
            app.min_ppm_EditField.ValueChangedFcn = createCallbackFcn(app, @Preprocessing_ValueChanged, true);
            app.min_ppm_EditField.Value = 7;
            app.maxEditFieldLabel = uilabel(app.Preprocessing_Panel);
            app.maxEditFieldLabel.Text = 'max';
            app.max_ppm_EditField = uieditfield(app.Preprocessing_Panel, 'numeric');
            app.max_ppm_EditField.ValueChangedFcn = createCallbackFcn(app, @Preprocessing_ValueChanged, true);
            app.max_ppm_EditField.Value = 8;
            app.FrequencyrangeppmLabel = uilabel(app.Preprocessing_Panel);
            app.FrequencyrangeppmLabel.Text = 'Frequency range [ppm] :';
            app.MaximumtimeEditFieldLabel = uilabel(app.Preprocessing_Panel);
            app.MaximumtimeEditFieldLabel.Text = 'Maximum time';
            app.Maximum_time_EditField = uieditfield(app.Preprocessing_Panel, 'numeric');
            app.Maximum_time_EditField.Limits = [0 Inf];
            app.Maximum_time_EditField.ValueChangedFcn = createCallbackFcn(app, @Preprocessing_ValueChanged, true);
            app.Maximum_time_EditField.Value = 0.5;
            app.RejectionthresholdEditFieldLabel = uilabel(app.Preprocessing_Panel);
            app.RejectionthresholdEditFieldLabel.Text = 'Rejection threshold';
            app.Rejection_threshold_EditField = uieditfield(app.Preprocessing_Panel, 'numeric');
            app.Rejection_threshold_EditField.ValueChangedFcn = createCallbackFcn(app, @Preprocessing_ValueChanged, true);
            app.Rejection_threshold_EditField.Value = 1.5;
            app.ISISButton = uibutton(app.Preprocessing_Panel, 'state');
            app.ISISButton.ValueChangedFcn = createCallbackFcn(app, @Preprocessing_ValueChanged, true);
            app.ISISButton.Text = 'ISIS';
            app.FidA_rm_badavg_CheckBox = uicheckbox(app.Preprocessing_Panel);
            app.FidA_rm_badavg_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Preprocessing_ValueChanged, true);
            app.FidA_rm_badavg_CheckBox.Text = 'Outliers removal';
            app.FidA_rm_badavg_CheckBox.Value = true;
            app.FidA_align_avg_CheckBox = uicheckbox(app.Preprocessing_Panel);
            app.FidA_align_avg_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Preprocessing_ValueChanged, true);
            app.FidA_align_avg_CheckBox.Text = 'Align averages';
            app.FidA_align_avg_CheckBox.Value = true;
            app.FidAstepsLabel = uilabel(app.Preprocessing_Panel);
            app.FidAstepsLabel.Text = 'Fid-A steps :';
            app.FidA_smallvoxel_CheckBox = uicheckbox(app.Preprocessing_Panel);
            app.FidA_smallvoxel_CheckBox.ValueChangedFcn = createCallbackFcn(app, @Preprocessing_ValueChanged, true);
            app.FidA_smallvoxel_CheckBox.Text = 'Small Voxel';
            app.FidA_smallvoxel_CheckBox.Value = true;
            app.PreprocessingLabel = uilabel(app.UIFigure);
            app.PreprocessingLabel.Text = 'Pre processing';
            app.WCONCEditFieldLabel = uilabel(app.UIFigure);
            app.WCONCEditFieldLabel.Text = 'WCONC';
            app.NRATIO_EditField = uieditfield(app.UIFigure, 'numeric');
            app.NRATIO_EditField.Limits = [0 Inf];
            app.NRATIO_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.NRATIO_EditField.Value = 12;
            app.NRATIOLabel = uilabel(app.UIFigure);
            app.NRATIOLabel.Text = 'NRATIO';
            app.Toolbox_logo = uiimage(app.UIFigure);
            app.Toolbox_logo.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Logo_MRS4Brain_toolbox_square_2.png');
            app.DEGZERLabel = uilabel(app.UIFigure);
            app.DEGZERLabel.Text = 'DEGZER';
            app.DEGZER_EditField = uieditfield(app.UIFigure, 'numeric');
            app.DEGZER_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.DEGZERLabel_2 = uilabel(app.UIFigure);
            app.DEGZERLabel_2.Text = 'SDDEGZ';
            app.SDDEGZ_EditField = uieditfield(app.UIFigure, 'numeric');
            app.SDDEGZ_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.DEGZERLabel_3 = uilabel(app.UIFigure);
            app.DEGZERLabel_3.Text = 'DEGPPM';
            app.DEGPPM_EditField = uieditfield(app.UIFigure, 'numeric');
            app.DEGPPM_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            app.DEGZERLabel_4 = uilabel(app.UIFigure);
            app.DEGZERLabel_4.Text = 'SDDEGP';
            app.SDDEGP_EditField = uieditfield(app.UIFigure, 'numeric');
            app.SDDEGP_EditField.ValueChangedFcn = createCallbackFcn(app, @Edit_LCModel_ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = preferences_SVS_DWS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end