classdef statistics_MRSI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Display_Panel                 matlab.ui.container.Panel
        Avg_Single_Button             matlab.ui.control.StateButton
        Save_foldername_EditField     matlab.ui.control.EditField
        SavefoldernameEditFieldLabel  matlab.ui.control.Label
        Save_folder_Button            matlab.ui.control.Button
        Data_results_DropDown         matlab.ui.control.DropDown
        Metabolites_data_DropDown     matlab.ui.control.DropDown
        Metabolites_results_DropDown  matlab.ui.control.DropDown
        Factor_DropDown               matlab.ui.control.DropDown
        UITable_data                  matlab.ui.control.Table
        UITable_results               matlab.ui.control.Table
        UITable_results_anova_2ways   matlab.ui.control.Table
        Save_figure_Button            matlab.ui.control.Button
        Save_results_Button           matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        Parameters_Panel              matlab.ui.container.Panel
        Statistics_logo               matlab.ui.control.Image
        Image_toolbox                 matlab.ui.control.Image
        Data_specific_Label           matlab.ui.control.Label
        Data_specific_Panel           matlab.ui.container.Panel
        Select_experiments_Button     matlab.ui.control.Button
        ConcentrationsDropDownLabel   matlab.ui.control.Label
        ExperimentDropDown            matlab.ui.control.DropDown
        SliceDropDown                 matlab.ui.control.DropDown
        UITable_exp                   matlab.ui.control.Table
        Metabolite_list_Label         matlab.ui.control.Label
        SliceDropDownLabel            matlab.ui.control.Label
        ExperimentDropDownLabel       matlab.ui.control.Label
        UITable_met                   matlab.ui.control.Table
        ConcentrationsDropDown        matlab.ui.control.DropDown
        MeanEditFieldLabel            matlab.ui.control.Label
        p_value_sided_Button          matlab.ui.control.StateButton
        pvalueEditField               matlab.ui.control.NumericEditField
        pvalueEditFieldLabel          matlab.ui.control.Label
        MeanEditField                 matlab.ui.control.NumericEditField
        CalculateButton               matlab.ui.control.Button
        Preferences_Label             matlab.ui.control.Label
    end

    
    properties (Access = private)
        MainApp                 % MRS4Brain MainApp
        AnovaApp                % Preparation of ANOVA 2 ways
        save_dir                % Directory of saved statistics
        save_foldername         % Foldername of saved statistics
        result_dir              % Directory of the studies folders
        studies_struct          % Studies found in the result directory with the number of slices available
        current_folder          % Current study folder
        current_fileList        % Current list of statistics available
        t_mrsi_data             % Table to select the files wanted
        statistics_struct       % Compilation of used statistics
        t_metabolites           % Table to select the metabolites wanted
        metab_list              % List of studied metabolites
        metab_idxs              % Indicies of the studied metabolites in the statistics_struct property
        relative_conc_bool = 1  % T or F to show concentration relative to a metabolite (T) or absolute (F)
        T_test_results = 0      % T or F to verify if the results have been computed (T-test)
        ANOVA_1way_results = 0  % T or F to verify if the results have been computed (ANOVA 1 way)
        ANOVA_2ways_results = 0 % T or F to verify if the results have been computed (ANOVA 2 ways)
        Stat_results            % Statistics applied results
    end

    properties (Access = public)
        group_factor_A          % Group number for factor A
        group_factor_B          % Group number for factor B
        name_factor_A           % Name of factor A
        name_factor_B           % Name of factor B
        names_group_factor_A    % Name of the groups for factor A
        names_group_factor_B    % Name of the groups for factor B
        ok_ANOVA                % ANOVA preparation done bool
    end

    methods (Access = private)
        %% Data management 

        function checknfill_studies(app)
            % Create the studies structure
            dir_struct = dir(app.result_dir);
            newvalues = zeros(length(dir_struct),1);
            temp = num2cell(newvalues);
            [dir_struct.N_slices] = temp{:};
            dir_struct = rmfield(dir_struct, {'date', 'bytes', 'datenum'});

            selected_studies = 1:length(dir_struct);
            for i = 1:length(dir_struct)
                if isfolder(fullfile(dir_struct(i).folder,dir_struct(i).name,'Slice_N1'))
                    selected_studies(i) = 0;
                    ok = 1;
                    slice = 1;
                    while ok
                        if isfolder(fullfile(dir_struct(i).folder,dir_struct(i).name, ...
                                ['Slice_N', num2str(slice + 1)]))
                            slice = slice + 1;
                        else
                            ok = 0;
                        end
                    end
                    dir_struct(i).N_slices = slice;
                end
            end
            selected_studies = nonzeros(selected_studies);
            dir_struct_new = dir_struct;
            dir_struct_new(selected_studies) = [];
            app.studies_struct = dir_struct_new; % properties initialization

            app.ExperimentDropDown.Items = {app.studies_struct.name};
            app.SliceDropDown.Items = string(1:app.studies_struct(1).N_slices);
            app.current_folder = fullfile(app.studies_struct(1).folder, ...
                app.studies_struct(1).name,'Slice_N1','Results');

            fileList = dir(fullfile(app.current_folder,'*.mat'));
            fileList = {fileList.name}.';

            for i = 1:length(fileList)
                [~,y,~] = fileparts(fileList{i});
                fileList{i} = y;
            end
            fileList = setdiff(fileList, {'metab_map'});
            app.current_fileList = fileList;

            State = false(length(fileList),1);
            app.t_mrsi_data = table(State,fileList,'VariableNames',{'On/Off','MRSI data'});
            app.UITable_exp.Data = app.t_mrsi_data;
        end
        
        function update_mrsidata_table(app)
            % Update the saved MRSI statistics saved 
            idx_study = find(strcmp(app.ExperimentDropDown.Items,app.ExperimentDropDown.Value));
            app.current_folder = fullfile(app.studies_struct(idx_study).folder, ...
                app.studies_struct(idx_study).name,['Slice_N' app.SliceDropDown.Value],'Results');

            fileList = dir(fullfile(app.current_folder,'*.mat'));
            fileList = {fileList.name}.';
            for i = 1:length(fileList)
                [~,y,~] = fileparts(fileList{i});
                fileList{i} = y;
            end
            fileList = setdiff(fileList, {'metab_map'});
            app.current_fileList = fileList;

            State = false(length(fileList),1);
            app.t_mrsi_data = table(State,fileList,'VariableNames',{'On/Off','MRSI data'});
            app.check_in_stat();
        end
        
        % Check data in statistics structure
        function check_in_stat(app)
            % Check the data in the Statistics structure
            channel_folder = find(strcmp({app.statistics_struct.folder},app.current_folder));
            for ch = channel_folder
                channel_fileList = strcmp(app.current_fileList,app.statistics_struct(ch).name);
                app.t_mrsi_data{channel_fileList,1} = true;
            end
            app.UITable_exp.Data = app.t_mrsi_data;
        end
        
        % Modify the calculate button with text corresponding to available
        % stats
        function calculate_button(app)
            % Calculate Button for the different types of Statistics
            app.CalculateButton.Enable = "on";
            app.MeanEditField.Visible = "off";
            app.MeanEditFieldLabel.Visible = "off";
            if length(app.statistics_struct) < 1
                app.CalculateButton.Enable = "off";
                app.CalculateButton.Text = "Calculate";
            elseif length(app.statistics_struct) == 1
                app.CalculateButton.Text = "Student T-test";
                app.MeanEditField.Visible = "on";
                app.MeanEditFieldLabel.Visible = "on";
            elseif length(app.statistics_struct) == 2
                app.CalculateButton.Text = "Student T-test";
            else
                app.CalculateButton.Text = "ANOVA stats";
            end
        end

        function met_conc = check_similar_struct(app,met_conc)
            if length(app.statistics_struct) > 1
                met_conc2 = met_conc;
                mn1 = [met_conc.met_names].';
                mn2 = app.statistics_struct(1).met_names;
                for k = 1:length(mn2)
                    idx_tmp = (find(strcmp(mn2(k),mn1)));
                    idx_mn1_2 = idx_tmp(1);
                    met_conc2(k).met_names = mn2(k);
                    if idx_mn1_2 == 0
                        met_conc2(k).concs_rel = [];
                        met_conc2(k).mean_conc_rel = 0;
                        met_conc2(k).std_conc_rel = 0;
                        met_conc2(k).concs_abs = [];
                        met_conc2(k).mean_conc_abs = 0;
                        met_conc2(k).std_conc_abs = 0;
                        met_conc2(k).n_voxels = 0;
                        met_conc2(k).masks = false*met_conc2(1).masks;
                    else
                        met_conc2(k).concs_rel = met_conc(idx_mn1_2).concs_rel;
                        met_conc2(k).mean_conc_rel = met_conc(idx_mn1_2).mean_conc_rel;
                        met_conc2(k).std_conc_rel = met_conc(idx_mn1_2).std_conc_rel;
                        met_conc2(k).concs_abs = met_conc(idx_mn1_2).concs_abs;
                        met_conc2(k).mean_conc_abs = met_conc(idx_mn1_2).mean_conc_abs;
                        met_conc2(k).std_conc_abs = met_conc(idx_mn1_2).std_conc_abs;
                        met_conc2(k).n_voxels = met_conc(idx_mn1_2).n_voxels;
                        met_conc2(k).masks = met_conc(idx_mn1_2).masks;
                    end
                end
                if length(mn1) > length(mn2)
                    met_conc2(length(mn2)+1:end) = [];
                end
                met_conc = met_conc2;
            end
        end
        
        %% Student T-test computation

        function msg = ttest_compute(app)
            % Student T-test
            msg = {''};
            if length(app.statistics_struct) == 1

            elseif length(app.statistics_struct) == 2
                try
                    t = zeros(length(app.metab_idxs),2); % t-test value
                    p_value = zeros(length(app.metab_idxs),2); % p-values
                    mean_all = zeros(length(app.metab_idxs),2); % mean of the mean
                    mean_conc_rel = [app.statistics_struct.mean_conc_rel];
                    mean_conc_abs = [app.statistics_struct.mean_conc_abs];
                    std_conc_rel = [app.statistics_struct.std_conc_rel];
                    std_conc_abs = [app.statistics_struct.std_conc_abs];
                    mean_data = zeros(length(app.metab_idxs),2,2);
                    std_data = zeros(length(app.metab_idxs),2,2);
                    std_all = zeros(length(app.metab_idxs),2);
                    std_all(:,1) = std(mean_conc_rel(app.metab_idxs,:),0,2);
                    std_all(:,2) = std(mean_conc_abs(app.metab_idxs,:),0,2);
                    N = sum([app.statistics_struct.n_elements],2);
                    df = app.statistics_struct(1).n_elements + app.statistics_struct(2).n_elements - 2;
                    for j = 1:length(app.metab_idxs)
                        idx_met = app.metab_idxs(j);
                        % Relative concentration
                        s1 = app.statistics_struct(1).std_conc_rel(idx_met)^2 / ...
                            app.statistics_struct(1).n_elements(j);
                        s2 = app.statistics_struct(2).std_conc_rel(idx_met)^2 / ...
                            app.statistics_struct(2).n_elements(idx_met);
                        t(j,1) = (app.statistics_struct(1).mean_conc_rel(idx_met) - ...
                            app.statistics_struct(2).mean_conc_rel(idx_met)) / ...
                            sqrt(s1 + s2);
                        p_value(j,1) = (1 + app.p_value_sided_Button.Value) * ...
                            (1 - tcdf(abs(t(j,1)),df(idx_met)));
                        mean_data(j,:,1) = mean_conc_rel(idx_met,:);
                        std_data(j,:,1) = std_conc_rel(idx_met,:);
                        % Absolute concentration
                        s1 = app.statistics_struct(1).std_conc_abs(idx_met)^2 / ...
                            app.statistics_struct(1).n_elements(idx_met);
                        s2 = app.statistics_struct(2).std_conc_abs(idx_met)^2 / ...
                            app.statistics_struct(2).n_elements(idx_met);
                        t(j,2) = (app.statistics_struct(1).mean_conc_abs(idx_met) - ...
                            app.statistics_struct(2).mean_conc_abs(idx_met)) / ...
                            sqrt(s1 + s2);
                        p_value(j,2) = (1 + app.p_value_sided_Button.Value) * ...
                            (1 - tcdf(abs(t(j,2)),df(idx_met)));
                        mean_data(j,:,2) = mean_conc_abs(idx_met,:);
                        std_data(j,:,2) = std_conc_abs(idx_met,:);
                        for i = 1:length(app.statistics_struct)
                            % Relative concentration
                            mean_all(j,1) = mean_all(j,1) + ...
                                app.statistics_struct(i).mean_conc_rel(idx_met) * ...
                                app.statistics_struct(i).n_elements(idx_met);
                            % Absolute concentration
                            mean_all(j,2) = mean_all(j,2) + ...
                                app.statistics_struct(i).mean_conc_abs(idx_met) * ...
                                app.statistics_struct(i).n_elements(idx_met);
                        end
                    end
                    mean_all = mean_all ./ N(app.metab_idxs);
                catch ME
                    msg = {'Error while applying Student T-test : \n',ME.message};
                end
                Student_T_test = struct('met_names',[],'mean_all',[], ...
                    'std_all',[],'mean_data',[],'std_data',[],'T_test',[],'P_value',[]);
                Student_T_test(1) = [];
                for k = 1:length(app.metab_idxs)
                    Student_T_test(k).met_names = app.metab_list{k};
                    Student_T_test(k).mean_all = mean_all(k,:);
                    Student_T_test(k).std_all = std_all(k,:);
                    Student_T_test(k).mean_data = squeeze(mean_data(k,:,:));
                    Student_T_test(k).std_data = squeeze(std_data(k,:,:));
                    Student_T_test(k).T_test = t(k,:);
                    Student_T_test(k).P_value = p_value(k,:);
                end
                assignin("base","Student_T_test",Student_T_test)
                app.Stat_results = Student_T_test;
                app.T_test_results = 1;
                app.Metabolites_results_DropDown.Items = app.metab_list;
                app.results_table();
                app.plot_results();
            end
        end
        
        %% ANOVA 1 way computation

        function msg = anova_1way_compute(app)
            % ANOVA One-way
            msg = {''};
            if length(unique(app.group_factor_B)) > 1
                temp_grp_B = ones(length(app.group_factor_B),1);
                app.group_factor_A = app.group_factor_B;
                app.group_factor_B = temp_grp_B;
                temp_name_factor_A = app.name_factor_B;
                app.name_factor_A = app.name_factor_B;
                app.name_factor_B = temp_name_factor_A;
                app.names_group_factor_A = app.names_group_factor_B;
                app.names_group_factor_B = app.name_factor_B;
            end
            try
                mean_all = zeros(length(app.metab_idxs),2);
                N = sum([app.statistics_struct.n_elements],2);
                dfn = (length(app.statistics_struct) - 1);
                dfd = (N - length(app.statistics_struct));
                for i = 1:length(app.statistics_struct)
                    for j = 1:length(app.metab_idxs)
                        idx_met = app.metab_idxs(j);
                        mean_all(j,1) = mean_all(j,1) + ...
                            app.statistics_struct(i).mean_conc_rel(idx_met) * ...
                            app.statistics_struct(i).n_elements(idx_met);
                        mean_all(j,2) = mean_all(j,2) + ...
                            app.statistics_struct(i).mean_conc_abs(idx_met) * ...
                            app.statistics_struct(i).n_elements(idx_met);
                    end
                end
                mean_all = mean_all ./ N(app.metab_idxs);
                mean_conc_rel = [app.statistics_struct.mean_conc_rel];
                mean_conc_abs = [app.statistics_struct.mean_conc_abs];
                std_conc_rel = [app.statistics_struct.std_conc_rel];
                std_conc_abs = [app.statistics_struct.std_conc_abs];
                std_all = zeros(length(app.metab_idxs),2);
                std_all(:,1) = std(mean_conc_rel(app.metab_idxs,:),0,2);
                std_all(:,2) = std(mean_conc_abs(app.metab_idxs,:),0,2);
                % Factor A
                nb_groups_A = length(unique(app.group_factor_A));
                mean_factorA = zeros(nb_groups_A,length(app.metab_idxs),2);
                std_factorA = zeros(nb_groups_A,length(app.metab_idxs),2);
                nb_groups_B = length(app.group_factor_B) / nb_groups_A;
                data_mean_factorA = zeros(nb_groups_A,nb_groups_B,length(app.metab_idxs),2);
                data_std_factorA = zeros(nb_groups_A,nb_groups_B,length(app.metab_idxs),2);
                for i = 1:nb_groups_A
                    idx_comp = find(app.group_factor_A == i);
                    [~,idx_sort] = sort(app.group_factor_B(idx_comp));
                    for j = 1:length(app.metab_idxs)
                        idx_met = app.metab_idxs(j);
                        data_mean_factorA(i,:,j,1) = mean_conc_rel(idx_met,idx_comp(idx_sort));
                        data_mean_factorA(i,:,j,2) = mean_conc_abs(idx_met,idx_comp(idx_sort));
                        mean_factorA(i,j,1) = mean(mean_conc_rel(idx_met,idx_comp));
                        mean_factorA(i,j,2) = mean(mean_conc_abs(idx_met,idx_comp));
                        data_std_factorA(i,:,j,1) = std_conc_rel(idx_met,idx_comp(idx_sort));
                        data_std_factorA(i,:,j,2) = std_conc_abs(idx_met,idx_comp(idx_sort));
                        std_factorA(i,j,1) = std(mean_conc_rel(idx_met,idx_comp));
                        std_factorA(i,j,2) = std(mean_conc_abs(idx_met,idx_comp));
                    end
                end
                SST = zeros(length(app.metab_idxs),2);
                SSE = zeros(length(app.metab_idxs),2);
                for i = 1:length(app.statistics_struct)
                    for j = 1:length(app.metab_idxs)
                        idx_met = app.metab_idxs(j);
                        SST(j,1) = SST(j,1) + app.statistics_struct(i).n_elements(idx_met) * ...
                            (app.statistics_struct(i).mean_conc_rel(idx_met) - mean_all(j,1)).^2;
                        SSE(j,1) = SSE(j,1) + (app.statistics_struct(i).n_elements(idx_met) - 1) * ...
                            app.statistics_struct(i).std_conc_rel(idx_met).^2;
                        SST(j,2) = SST(j,2) + app.statistics_struct(i).n_elements(idx_met) * ...
                            (app.statistics_struct(i).mean_conc_abs(idx_met) - mean_all(j,2)).^2;
                        SSE(j,2) = SSE(j,2) + (app.statistics_struct(i).n_elements(idx_met) - 1) * ...
                            app.statistics_struct(i).std_conc_abs(idx_met).^2;
                    end
                end
                MST = SST ./ dfn;
                MSE = SSE ./ dfd(app.metab_idxs);
                F = MST ./ MSE;
                p_value = zeros(size(F));
                for j = 1:length(app.metab_idxs)
                    idx_met = app.metab_idxs(j);
                    p_value(j,:) = (1 - fcdf(F(j,:),dfn,dfd(idx_met)));
                end
                ANOVA_1way = struct('met_names',[],'mean_all',[],'std_all',[], ...
                    'data_mean_FactorA',[],'data_std_FactorA',[],...
                    'mean_FactorA',[],'std_FactorA',[],'MST',[],'MSE',[],'F_test',[],'P_value',[]);
                ANOVA_1way(1) = [];
                for k = 1:length(app.metab_idxs)
                    ANOVA_1way(k).met_names = app.metab_list{k};
                    ANOVA_1way(k).mean_all = mean_all(k,:);
                    ANOVA_1way(k).std_all = std_all(k,:);
                    if nb_groups_A == 1
                        ANOVA_1way(k).mean_FactorA = squeeze(mean_factorA(:,k,:)).';
                        ANOVA_1way(k).std_FactorA = squeeze(std_factorA(:,k,:)).';
                        ANOVA_1way(k).data_mean_FactorA = squeeze(data_mean_factorA(:,:,k,:)).';
                        ANOVA_1way(k).data_std_FactorA = squeeze(data_std_factorA(:,:,k,:)).';
                    else
                        ANOVA_1way(k).mean_FactorA = squeeze(mean_factorA(:,k,:));
                        ANOVA_1way(k).std_FactorA = squeeze(std_factorA(:,k,:));
                        ANOVA_1way(k).data_mean_FactorA = squeeze(data_mean_factorA(:,:,k,:));
                        ANOVA_1way(k).data_std_FactorA = squeeze(data_std_factorA(:,:,k,:));
                    end
                    ANOVA_1way(k).MST = MST(k,:);
                    ANOVA_1way(k).MSE = MSE(k,:);
                    ANOVA_1way(k).F_test = F(k,:);
                    ANOVA_1way(k).P_value = p_value(k,:);
                end
                assignin("base","ANOVA_1way",ANOVA_1way)
                app.Stat_results = ANOVA_1way;
                app.Metabolites_results_DropDown.Items = app.metab_list;
                app.ANOVA_1way_results = 1;
                app.results_table()
                app.plot_results()
            catch ME
                msg = {'Error while applying ANOVA 1-way : \n',ME.message};
            end
        end

        %% ANOVA 2 ways computation
        
        function msg = anova_2way_compute(app)
            % ANOVA Two-ways
            msg = {''};
            % try
                n_e = [app.statistics_struct.n_elements];
                N = sum(n_e,2);
                nb_groups_A = length(unique(app.group_factor_A));
                nb_groups_B = length(unique(app.group_factor_B));
                % Degrees of freedom
                dfA = nb_groups_A - 1; dfB = nb_groups_B - 1;
                dfI = dfA * dfB; dfW = N - length(app.statistics_struct);
                % Sum of squares
                SS = zeros(length(app.statistics_struct),length(app.metab_idxs),2); % Sum of square of each stat
                SSAB = zeros(length(app.metab_idxs),2); % Sum of square interaction
                sumX = zeros(length(app.statistics_struct),length(app.metab_idxs),2); % (sum of X)^2 / n
                mean_all = zeros(length(app.metab_idxs),2);
                for j = 1:length(app.metab_idxs)    % loop through the metabolites
                    idx_met = app.metab_idxs(j);    % indices of metabolites of interest
                    for i = 1:length(app.statistics_struct) % loop through the data
                        % Relative concentration
                        SS(i,j,1) = (app.statistics_struct(i).n_elements(idx_met) - 1) * ...
                            app.statistics_struct(i).std_conc_rel(idx_met)^2;
                        sumX(i,j,1) = app.statistics_struct(i).n_elements(idx_met) * ...
                            app.statistics_struct(i).mean_conc_rel(idx_met);
                        SSAB(j,1) = SSAB(j,1) + sumX(i,j,1).^2 / app.statistics_struct(i).n_elements(idx_met);
                        mean_all(j,1) = mean_all(j,1) + app.statistics_struct(i).mean_conc_rel(idx_met) * ...
                            app.statistics_struct(i).n_elements(idx_met);
                        % Absolute concentration
                        SS(i,j,2) = (app.statistics_struct(i).n_elements(idx_met) - 1) * ...
                            app.statistics_struct(i).std_conc_abs(idx_met)^2;
                        sumX(i,j,2) = app.statistics_struct(i).n_elements(idx_met) * ...
                            app.statistics_struct(i).mean_conc_abs(idx_met);
                        SSAB(j,2) = SSAB(j,2) + sumX(i,j,2).^2 / app.statistics_struct(i).n_elements(idx_met);
                        mean_all(j,2) = mean_all(j,2) + app.statistics_struct(i).mean_conc_abs(idx_met) * ...
                            app.statistics_struct(i).n_elements(idx_met);
                    end
                end
                mean_all = mean_all ./ N(app.metab_idxs);
                SSW = squeeze(sum(SS,1)); % Sum of square within
                SS_tot = squeeze(sum(sumX,1).^2) ./ N(app.metab_idxs);
                mean_conc_rel = [app.statistics_struct.mean_conc_rel];
                mean_conc_abs = [app.statistics_struct.mean_conc_abs];
                std_conc_rel = [app.statistics_struct.std_conc_rel];
                std_conc_abs = [app.statistics_struct.std_conc_abs];
                std_all = zeros(length(app.metab_idxs),2);
                std_all(:,1) = std(mean_conc_rel(app.metab_idxs,:),0,2);
                std_all(:,2) = std(mean_conc_abs(app.metab_idxs,:),0,2);
                % Factor A
                SSA = zeros(length(app.metab_idxs),2);
                mean_factorA = zeros(nb_groups_A,length(app.metab_idxs),2);
                std_factorA = zeros(nb_groups_A,length(app.metab_idxs),2);
                data_mean_factorA = zeros(nb_groups_A,length(app.group_factor_A)/nb_groups_A,length(app.metab_idxs),2);
                data_std_factorA = zeros(nb_groups_A,length(app.group_factor_A)/nb_groups_A,length(app.metab_idxs),2);
                for i = 1:nb_groups_A
                    idx_comp = find(app.group_factor_A == i);
                    [~,idx_sort] = sort(app.group_factor_B(idx_comp));
                    for j = 1:length(app.metab_idxs)
                        idx_met = app.metab_idxs(j);
                        data_mean_factorA(i,:,j,1) = mean_conc_rel(idx_met,idx_comp(idx_sort));
                        data_mean_factorA(i,:,j,2) = mean_conc_abs(idx_met,idx_comp(idx_sort));
                        mean_factorA(i,j,1) = mean(mean_conc_rel(idx_met,idx_comp));
                        mean_factorA(i,j,2) = mean(mean_conc_abs(idx_met,idx_comp));
                        data_std_factorA(i,:,j,1) = std_conc_rel(idx_met,idx_comp(idx_sort));
                        data_std_factorA(i,:,j,2) = std_conc_abs(idx_met,idx_comp(idx_sort));
                        std_factorA(i,j,1) = std(mean_conc_rel(idx_met,idx_comp));
                        std_factorA(i,j,2) = std(mean_conc_abs(idx_met,idx_comp));
                        SSA(j,1) = SSA(j,1) + sum(sumX(idx_comp,j,1),1)^2 / squeeze(sum(n_e(idx_met,idx_comp)));
                        SSA(j,2) = SSA(j,2) + sum(sumX(idx_comp,j,2),1)^2 / squeeze(sum(n_e(idx_met,idx_comp)));
                    end
                end
                % Factor B
                SSB = zeros(length(app.metab_idxs),2);
                mean_factorB = zeros(nb_groups_B,length(app.metab_idxs),2);
                std_factorB = zeros(nb_groups_B,length(app.metab_idxs),2);
                data_mean_factorB = zeros(nb_groups_B,length(app.group_factor_B)/nb_groups_B,length(app.metab_idxs),2);
                data_std_factorB = zeros(nb_groups_B,length(app.group_factor_B)/nb_groups_B,length(app.metab_idxs),2);
                for i = 1:nb_groups_B
                    idx_comp = find(app.group_factor_B == i);
                    [~,idx_sort] = sort(app.group_factor_A(idx_comp));
                    for j = 1:length(app.metab_idxs)
                        idx_met = app.metab_idxs(j);
                        data_mean_factorB(i,:,j,1) = mean_conc_rel(idx_met,idx_comp(idx_sort));
                        data_mean_factorB(i,:,j,2) = mean_conc_abs(idx_met,idx_comp(idx_sort));
                        mean_factorB(i,j,1) = mean(mean_conc_rel(idx_met,idx_comp));
                        mean_factorB(i,j,2) = mean(mean_conc_abs(idx_met,idx_comp));
                        data_std_factorB(i,:,j,1) = std_conc_rel(idx_met,idx_comp(idx_sort));
                        data_std_factorB(i,:,j,2) = std_conc_abs(idx_met,idx_comp(idx_sort));
                        std_factorB(i,j,1) = std(mean_conc_rel(idx_met,idx_comp));
                        std_factorB(i,j,2) = std(mean_conc_abs(idx_met,idx_comp));
                        SSB(j,1) = SSB(j,1) + sum(sumX(idx_comp,j,1),1)^2 / squeeze(sum(n_e(idx_met,idx_comp)));
                        SSB(j,2) = SSB(j,2) + sum(sumX(idx_comp,j,2),1)^2 / squeeze(sum(n_e(idx_met,idx_comp)));
                    end
                end
                % Sum of squares of factor A, B and AB
                SSAB = SSAB - SSA - SSB + SS_tot;
                SSA = SSA - SS_tot; SSB = SSB - SS_tot;
                % Mean squares of factor A, B and AB + within groups
                MSA = SSA ./ dfA; MSB = SSB ./ dfB;
                MSAB = SSAB ./ dfI; MSW = SSW ./ dfW(app.metab_idxs);
                % F-value of factor A, B and AB
                FA = MSA ./ MSW; FB = MSB ./ MSW; FAB = MSAB ./ MSW;
                % P-value of factor A, B and AB
                p_value = zeros(3,length(app.metab_idxs),2);
                for j = 1:length(app.metab_idxs)
                    idx_met = app.metab_idxs(j);
                    p_value(1,j,:) = (1 - fcdf(abs(FA(j,:)),dfA,dfW(idx_met)));
                    p_value(2,j,:) = (1 - fcdf(abs(FB(j,:)),dfB,dfW(idx_met)));
                    p_value(3,j,:) = (1 - fcdf(abs(FAB(j,:)),dfI,dfW(idx_met)));
                end
                F(1,:,:) = FA; F(2,:,:) = FB; F(3,:,:) = FAB;
                % ANOVA 2 ways results structure
                ANOVA_2way = struct('met_names',[],'data_mean_FactorA',[],'data_std_FactorA',[],...
                    'data_mean_FactorB',[],'data_std_FactorB',[],'mean_FactorA',[],'mean_FactorB',[], ...
                    'std_FactorA',[],'std_FactorB',[],'mean_all',[],'std_all',[],...
                    'MSA',[],'MSB',[],'MSAB',[],'MSW',[],'F_test',[],'P_value',[]);
                % ANOVA_2way(1) = [];
                for k = 1:length(app.metab_idxs)
                    ANOVA_2way(k).met_names = app.metab_list{k};
                    if nb_groups_A == 1
                        ANOVA_2way(k).mean_FactorA = squeeze(mean_factorA(:,k,:)).';
                        ANOVA_2way(k).std_FactorA = squeeze(std_factorA(:,k,:)).';
                        ANOVA_2way(k).data_mean_FactorA = squeeze(data_mean_factorA(:,:,k,:)).';
                        ANOVA_2way(k).data_std_FactorA = squeeze(data_std_factorA(:,:,k,:)).';
                    else
                        ANOVA_2way(k).mean_FactorA = squeeze(mean_factorA(:,k,:));
                        ANOVA_2way(k).std_FactorA = squeeze(std_factorA(:,k,:));
                        ANOVA_2way(k).data_mean_FactorA = squeeze(data_mean_factorA(:,:,k,:));
                        ANOVA_2way(k).data_std_FactorA = squeeze(data_std_factorA(:,:,k,:));
                    end
                    if nb_groups_B == 1
                        ANOVA_2way(k).mean_FactorB = squeeze(mean_factorB(:,k,:)).';
                        ANOVA_2way(k).std_FactorB = squeeze(std_factorB(:,k,:)).';
                        ANOVA_2way(k).data_mean_FactorB = squeeze(data_mean_factorB(:,:,k,:)).';
                        ANOVA_2way(k).data_std_FactorB = squeeze(data_std_factorB(:,:,k,:)).';
                    else
                        ANOVA_2way(k).mean_FactorB = squeeze(mean_factorB(:,k,:));
                        ANOVA_2way(k).std_FactorB = squeeze(std_factorB(:,k,:));
                        ANOVA_2way(k).data_mean_FactorB = squeeze(data_mean_factorB(:,:,k,:));
                        ANOVA_2way(k).data_std_FactorB = squeeze(data_std_factorB(:,:,k,:));
                    end
                    ANOVA_2way(k).mean_all = squeeze(mean_all(k,:));
                    ANOVA_2way(k).std_all = squeeze(std_all(k,:));
                    ANOVA_2way(k).MSA = MSA(k,:);
                    ANOVA_2way(k).MSB = MSB(k,:);
                    ANOVA_2way(k).MSAB = MSAB(k,:);
                    ANOVA_2way(k).MSW = MSW(k,:);
                    ANOVA_2way(k).F_test = squeeze(F(:,k,:));
                    ANOVA_2way(k).P_value = squeeze(p_value(:,k,:));
                end
                assignin("base","ANOVA_2way",ANOVA_2way)
                app.Stat_results = ANOVA_2way;
                app.Metabolites_results_DropDown.Items = app.metab_list;
                app.ANOVA_2ways_results = 1;
                app.results_table()
                app.plot_results()
            % catch ME
            %     msg = {'Error while applying ANOVA 2 ways : ',ME.message};
            % end
        end
        
        %% Data table and display
        
        function data_table(app)
            idx_met = find(strcmp(app.Metabolites_data_DropDown.Items,app.Metabolites_data_DropDown.Value));
            idx_met = idx_met(1);
            if app.relative_conc_bool
                mean_conc_t = [app.statistics_struct.mean_conc_rel].';
                std_conc_t = [app.statistics_struct.std_conc_rel].';
            else
                mean_conc_t = [app.statistics_struct.mean_conc_abs].';
                std_conc_t = [app.statistics_struct.std_conc_abs].';
            end
            t_data = table({app.statistics_struct.study_name}.', ...
                {app.statistics_struct.region}.',mean_conc_t(:,idx_met), ...
                std_conc_t(:,idx_met), ...
                'VariableNames',{'Study','Region','Mean','Std'});
            app.UITable_data.Data = t_data;
        end

        function plot_data(app)
            if ~isempty(app.statistics_struct)
                idx_met = find(strcmp(app.Metabolites_data_DropDown.Items,app.Metabolites_data_DropDown.Value));
                idx_met = idx_met(1);
                if app.relative_conc_bool
                    mean_values = [app.statistics_struct.mean_conc_rel];
                    mean_values = mean_values(idx_met,:);
                    std_values = [app.statistics_struct.std_conc_rel];
                    std_values = std_values(idx_met,:);
                else
                    mean_values = [app.statistics_struct.mean_conc_abs];
                    mean_values = mean_values(idx_met,:);
                    std_values = [app.statistics_struct.std_conc_abs];
                    std_values = std_values(idx_met,:);
                end
                N_data = length(mean_values);
                index = (1:2:2*N_data).';
                data_list = cellstr(num2str((1:N_data)', 'Data %d'));
                bar(app.UIAxes,index,mean_values,0.5,'grouped','FaceColor',[200,225,231]/255)
                hold(app.UIAxes,"on")
                errorbar(app.UIAxes,index,mean_values,std_values,'x','CapSize',15,'LineWidth',2,'Color',[0 .5 .5])
                set(app.UIAxes,'xtick',index,'xticklabel',data_list)
                app.UIAxes.XAxis.FontWeight = 'bold';
                app.UIAxes.YAxis.FontWeight = 'bold';
                if N_data == 1
                    xlim(app.UIAxes,[0,2])
                else
                    xlim(app.UIAxes,[-1,2*N_data+1])
                end
                if max(mean_values)+max(std_values) < 1
                    ylim(app.UIAxes,[0,max(mean_values)+max(std_values)+1e-8])
                else
                    ylim(app.UIAxes,[0,max(mean_values)+max(std_values)+1])
                end
                title(app.UIAxes,['Data : ' app.Metabolites_data_DropDown.Value])
                hold(app.UIAxes,"off")
            else
                cla(app.UIAxes)
                title(app.UIAxes,'Data & Results')
            end
        end

        %% Results table and display
        
        % Display results table for the 3 different stats
        function results_table(app)
            idx_conc = 1;
            if ~app.relative_conc_bool
                idx_conc = 2;
            end
            % ANOVA 2 ways statistics
            if app.ANOVA_2ways_results
                idx_met = find(strcmp(app.Metabolites_results_DropDown.Items,app.Metabolites_results_DropDown.Value));
                idx_met = idx_met(1);
                mean_FactorA = app.Stat_results(idx_met).mean_FactorA(:,idx_conc).';
                mean_FactorB = app.Stat_results(idx_met).mean_FactorB(:,idx_conc).';
                Means = {strjoin(arrayfun(@(x) num2str(x,'%6.2f'),mean_FactorA,'UniformOutput',false),' | '); ...
                    strjoin(arrayfun(@(x) num2str(x,'%6.2f'),mean_FactorB,'UniformOutput',false),' | '); ...
                    num2str(app.Stat_results(idx_met).mean_all(idx_conc),'%6.2f')};
                F_value = num2str(app.Stat_results(idx_met).F_test(:,idx_conc),'%6.2f');
                P_value = app.Stat_results(idx_met).P_value(:,idx_conc);
                t_results = table(Means, F_value, P_value,'VariableNames',{'Mean','F-value','P_value'});
                app.UITable_results_anova_2ways.Data = t_results;
                app.UITable_results_anova_2ways.RowName = {'Factor A','Factor B','Interaction'};
                app.UITable_results_anova_2ways.ColumnName = {'Mean'; 'F-value'; 'P-value'};
                row1 = find(P_value < app.pvalueEditField.Value);
                row2 = find(P_value >= app.pvalueEditField.Value);
                row3 = find(isnan(P_value));
                if ~isempty(app.UITable_results_anova_2ways.StyleConfigurations)
                    app.UITable_results_anova_2ways.removeStyle(1:size(app.UITable_results_anova_2ways.StyleConfigurations,1))
                end
                if ~isempty(row1)
                    s1 = uistyle("BackgroundColor",[0.7 1 0.7]);
                    addStyle(app.UITable_results_anova_2ways,s1,"cell",[row1,3*ones(length(row1),1)]);
                end
                if ~isempty(row2)
                    s2 = uistyle("BackgroundColor",[1 0.6 0.6]);
                    addStyle(app.UITable_results_anova_2ways,s2,"cell",[row2,3*ones(length(row2),1)]);
                end
                if ~isempty(row3)
                    s3 = uistyle("BackgroundColor",[0.5 0.5 0.5]);
                    addStyle(app.UITable_results_anova_2ways,s3,"cell",[row3,3*ones(length(row3),1)]);
                end
            % ANOVA 1 way statistics and Student T-test statistics
            elseif or(app.ANOVA_1way_results,app.T_test_results)
                P_value = [app.Stat_results.P_value].'; P_value = P_value(idx_conc:2:end);
                if app.T_test_results
                    mean_all = [app.Stat_results.mean_all].';
                    T_test = [app.Stat_results.T_test].'; T_test = T_test(idx_conc:2:end);
                    t_results = table({app.Stat_results.met_names}.',mean_all(idx_conc:2:end), ...
                        T_test,P_value,'VariableNames',{'Metabolites','Mean','T test','P-value'});
                    app.UITable_results.ColumnName = {'Metabolites'; 'Mean'; 'T test'; 'P-value'};
                elseif app.ANOVA_1way_results
                    mean_FA = [app.Stat_results.mean_FactorA].';
                    mean_FA = join(arrayfun(@(x) num2str(x,'%6.2f'),...
                        mean_FA(idx_conc:2:end,:),'UniformOutput',false),' | ');
                    F_test = [app.Stat_results.F_test].'; F_test = F_test(idx_conc:2:end);
                    t_results = table({app.Stat_results.met_names}.',mean_FA, ...
                        F_test,P_value,'VariableNames',{'Metabolites','Mean','F test','P-value'});
                    app.UITable_results.ColumnName = {'Metabolites'; 'Mean'; 'F test'; 'P-value'};
                end
                app.UITable_results.Data = t_results;
                row1 = find(P_value < app.pvalueEditField.Value);
                row2 = find(P_value >= app.pvalueEditField.Value);
                row3 = find(isnan(P_value));
                if ~isempty(app.UITable_results.StyleConfigurations)
                    app.UITable_results.removeStyle(1:size(app.UITable_results.StyleConfigurations,1))
                end
                if ~isempty(row1)
                    s1 = uistyle("BackgroundColor",[0.7 1 0.7]);
                    addStyle(app.UITable_results,s1,"cell",[row1,4*ones(length(row1),1)]);
                end
                if ~isempty(row2)
                    s2 = uistyle("BackgroundColor",[1 0.6 0.6]);
                    addStyle(app.UITable_results,s2,"cell",[row2,4*ones(length(row2),1)]);
                end
                if ~isempty(row3)
                    s3 = uistyle("BackgroundColor",[0.5 0.5 0.5]);
                    addStyle(app.UITable_results,s3,"cell",[row3,4*ones(length(row3),1)]);
                end
            end
        end
        
        % Display the figure results
        function plot_results(app)
            idx_conc = 1;
            if ~app.relative_conc_bool
                idx_conc = 2;
            end
            idx_met = find(strcmp(app.Metabolites_results_DropDown.Items,app.Metabolites_results_DropDown.Value));
            if app.ANOVA_2ways_results
                if strcmp(app.Factor_DropDown.Value,'Factor A')
                    if app.Avg_Single_Button.Value
                        mean = app.Stat_results(idx_met).mean_FactorA(:,idx_conc);
                        std = app.Stat_results(idx_met).std_FactorA(:,idx_conc);
                    else
                        mean = app.Stat_results(idx_met).data_mean_FactorA(:,:,idx_conc);
                        std = app.Stat_results(idx_met).data_std_FactorA(:,:,idx_conc);
                    end
                    N_groups = size(mean,1);
                    grp_list = app.names_group_factor_A;
                    titlename = [app.Metabolites_results_DropDown.Value ', Factor A : ' app.name_factor_A];
                else
                    if app.Avg_Single_Button.Value
                        mean = app.Stat_results(idx_met).mean_FactorB(:,idx_conc);
                        std = app.Stat_results(idx_met).std_FactorB(:,idx_conc);
                    else
                        mean = app.Stat_results(idx_met).data_mean_FactorB(:,:,idx_conc);
                        std = app.Stat_results(idx_met).data_std_FactorB(:,:,idx_conc);
                    end
                    N_groups = size(mean,1);
                    grp_list = app.names_group_factor_B;
                    titlename = [app.Metabolites_results_DropDown.Value ', Factor B : ' app.name_factor_B];
                end
            elseif app.ANOVA_1way_results
                if app.Avg_Single_Button.Value
                    mean = app.Stat_results(idx_met).mean_FactorA(:,idx_conc);
                    std = app.Stat_results(idx_met).std_FactorA(:,idx_conc);
                else
                    mean = app.Stat_results(idx_met).data_mean_FactorA(:,:,idx_conc);
                    std = app.Stat_results(idx_met).data_std_FactorA(:,:,idx_conc);
                end
                N_groups = size(mean,1);
                grp_list = app.names_group_factor_A;
                titlename = [app.Metabolites_results_DropDown.Value ', Factor A : ' app.name_factor_A];
            elseif app.T_test_results
                if app.Avg_Single_Button.Value
                    mean = app.Stat_results(idx_met).mean_all(idx_conc);
                    std = app.Stat_results(idx_met).std_all(idx_conc);
                else
                    mean = app.Stat_results(idx_met).mean_data(:,idx_conc).';
                    std = app.Stat_results(idx_met).std_data(:,idx_conc).';
                end
                titlename = [app.Metabolites_results_DropDown.Value ', Student T-test'];
                N_groups = 1;
            end

            cla(app.UIAxes) % Clear current axis and plots

            % Index & Groups
            if N_groups == 1
                index = (1:1).';
                grp_list = {'T_test'};
            else
                index = (1:2:2*N_groups).';
            end

            % Plot bar 
            grp_list = regexprep(grp_list,'_',' ');
            b = bar(app.UIAxes,index,mean,0.5,'grouped','FaceColor',[200,225,231]/255);
            hold(app.UIAxes,"on") 

            % Error bars
            % Calculate the number of groups and number of bars in each group
            [ngroups,nbars] = size(mean);
            % Get the x coordinate of the bars
            x = nan(nbars, ngroups);
            for i = 1:nbars
                x(i,:) = b(i).XEndPoints;
            end
            e = errorbar(app.UIAxes,x.',mean,std,'x','CapSize',15,'LineWidth',2,'Color',[0 .5 .5]);

            % Axis and display settings
            if nbars == 1
                b.BarWidth = 0.5;
                e.CapSize = 25;
            end
            set(app.UIAxes,'xtick',index,'xticklabel',grp_list)
            app.UIAxes.XAxis.FontWeight = 'bold';
            app.UIAxes.YAxis.FontWeight = 'bold';
            if ngroups > 2
                app.UIAxes.XAxis.FontSize = 8;
            else
                app.UIAxes.XAxis.FontSize = 12;
            end
            if N_groups == 1
                xlim(app.UIAxes,[0.5,1.5])
            else
                xlim(app.UIAxes,[-0.5,2*N_groups+0.5])
            end
            if max(mean)+max(std) < 1
                ylim(app.UIAxes,[0,max(max(mean))+max(max(std))+1e-8])
            elseif and(max(mean) + max(std) > 1,~isnan(max(mean) + max(std)))
                ylim(app.UIAxes,[0,max(max(mean))+max(max(std))+1])
            end
            title(app.UIAxes,titlename)
            hold(app.UIAxes,"off")
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, result_dir)
            if nargin < 2
                [app_path,~] = fileparts(mfilename('fullpath'));
                app.result_dir = app_path;
            end
            
            % Structure to do statistics
            stat_struct = struct('folder',[],'study_name',[],'name',[],'region',[],'side',[],'slice_n',[], ...
                'met_names',[],'mean_conc_rel',[],'std_conc_rel',[],'mean_conc_abs',[],'std_conc_abs',[],'n_elements',[]);
            stat_struct(1) = [];
            app.statistics_struct = stat_struct;
        end

        % Button pushed function: Select_experiments_Button
        function Select_experiments_ButtonPushed(app, event)
            % Choose a Study name folder that already exist
            d = uigetdir(app.result_dir,"Choose a directory");
            figure(app.UIFigure)
            if d ~= 0
                if isfolder(d)
                    app.result_dir = d;
                    app.checknfill_studies();
                end
            end
        end

        % Value changed function: ExperimentDropDown
        function Experiment_DropDownValueChanged(app, event)
            % Study and slice selection to update MRSI data table
            idx_study = find(contains(app.ExperimentDropDown.Items,app.ExperimentDropDown.Value));
            app.SliceDropDown.Items = string(1:app.studies_struct(idx_study).N_slices);
            app.update_mrsidata_table();
        end

        % Value changed function: SliceDropDown
        function Slice_DropDownValueChanged(app, event)
            % Slice selection update
            app.update_mrsidata_table();
        end

        % Cell edit callback: UITable_exp
        function UITable_exp_CellEdit(app, event)
            idx_file = event.Indices(1);
            app.t_mrsi_data = app.UITable_exp.Data;
            if table2array(app.t_mrsi_data(idx_file,1)) % add in the stat_struct
                l = length(app.statistics_struct);
                % Fill metadata
                app.statistics_struct(l+1).folder = app.current_folder;
                app.statistics_struct(l+1).name = char(table2array(app.t_mrsi_data(idx_file,2)));
                app.statistics_struct(l+1).study_name = app.ExperimentDropDown.Value;
                app.statistics_struct(l+1).slice_n = str2double(app.SliceDropDown.Value);
                % Load statistics
                temp_load = load(fullfile(app.current_folder,[app.statistics_struct(l+1).name,'.mat']));
                met_conc = temp_load.results.met_conc;
                app.statistics_struct(l+1).region = temp_load.results.region;
                app.statistics_struct(l+1).side = temp_load.results.side;
                met_conc = app.check_similar_struct(met_conc);
                app.statistics_struct(l+1).met_names = [met_conc.met_names].';
                app.statistics_struct(l+1).mean_conc_rel = [met_conc.mean_conc_rel].';
                app.statistics_struct(l+1).std_conc_rel = [met_conc.std_conc_rel].';
                app.statistics_struct(l+1).mean_conc_abs = [met_conc.mean_conc_abs].';
                app.statistics_struct(l+1).std_conc_abs = [met_conc.std_conc_abs].';
                app.statistics_struct(l+1).n_elements = [met_conc.n_voxels].';
            else % remove from the stat_struct
                name_remove = char(table2array(app.t_mrsi_data(idx_file,2)));
                idx_stat = find(and(strcmp({app.statistics_struct.folder},app.current_folder), ...
                    strcmp({app.statistics_struct.name},name_remove)));
                app.statistics_struct(idx_stat) = [];
            end
            assignin("base",'stat_struct',app.statistics_struct)

            if and(~isempty(app.statistics_struct),length(app.statistics_struct) == 1)
                met_names = app.statistics_struct(1).met_names;
                State = false(length(met_names),1);

                app.t_metabolites = table(State,met_names,'VariableNames', ...
                    {'On/Off','Metabolites'});
                app.UITable_met.Data = app.t_metabolites;
                app.Metabolites_data_DropDown.Items = met_names;
            elseif isempty(app.statistics_struct)
                app.UITable_met.Data = [];
                app.t_metabolites = [];
                app.Metabolites_data_DropDown.Items = "Met";
            end
            app.calculate_button();
            app.plot_data();
            if ~isempty(app.statistics_struct)
                % Fill the data table
                if app.relative_conc_bool
                    mean_conc_t = [app.statistics_struct.mean_conc_rel].';
                    std_conc_t = [app.statistics_struct.std_conc_rel].';
                else
                    mean_conc_t = [app.statistics_struct.mean_conc_abs].';
                    std_conc_t = [app.statistics_struct.std_conc_abs].';
                end
                t_data = table({app.statistics_struct.study_name}.', ...
                    {app.statistics_struct.region}.',mean_conc_t(:,1), ...
                    std_conc_t(:,1), ...
                    'VariableNames',{'Study','Region','Mean','Std'});
                app.UITable_data.Data = t_data;
            end
        end

        % Cell edit callback: UITable_met
        function UITable_met_CellEdit(app, event)
            app.t_metabolites = app.UITable_met.Data;
            app.metab_idxs = find(app.t_metabolites{:,1});
            app.metab_list = app.t_metabolites{app.metab_idxs,2};
        end

        % Value changed function: ConcentrationsDropDown
        function ConcentrationsDropDownValueChanged(app, event)
            value = app.ConcentrationsDropDown.Value;
            if strcmp(value,'Relative')
                app.relative_conc_bool = true;
            else
                app.relative_conc_bool = false;
            end
            if strcmp(app.Data_results_DropDown.Value,'Data')
                app.data_table()
            else
                app.results_table()
                app.plot_results()
            end
        end

        % Button pushed function: CalculateButton
        function Calculate_ButtonPushed(app, event)
            if and(length(app.statistics_struct) >= 1, ~isempty(app.metab_idxs))
                app.ANOVA_1way_results = 0;
                app.ANOVA_2ways_results = 0;
                app.T_test_results = 0;
                if or(length(app.statistics_struct) == 1, length(app.statistics_struct) == 2)
                    msg = app.ttest_compute();
                    if any(cellfun(@isempty,msg))
                        app.T_test_results = 1;
                        app.UITable_results.Visible = 'on';
                        app.UITable_results_anova_2ways.Visible = 'off';
                        app.Metabolites_results_DropDown.Visible = 'on';
                        app.Factor_DropDown.Visible = 'off';
                    end
                else
                    d = uiprogressdlg(app.UIFigure,"Title",'ANOVA statistics','Message',  ...
                        'Preparation of groups for each factor','Icon','none','Indeterminate','on');
                    app.AnovaApp = anova_prep_MRSI(app,app.statistics_struct,d);
                    waitfor(app.AnovaApp)
                    if app.ok_ANOVA
                        if or(length(unique(app.group_factor_A)) == 1,length(unique(app.group_factor_B)) == 1)
                            msg = app.anova_1way_compute();
                            if any(cellfun(@isempty,msg))
                                app.ANOVA_1way_results = 1;
                                app.UITable_results.Visible = 'on';
                                app.UITable_results_anova_2ways.Visible = 'off';
                                app.Metabolites_results_DropDown.Visible = 'on';
                                app.Factor_DropDown.Visible = 'off';
                            end
                        else
                            msg = app.anova_2way_compute();
                            if any(cellfun(@isempty,msg))
                                app.ANOVA_2ways_results = 1;
                                app.UITable_results.Visible = 'off';
                                app.UITable_results_anova_2ways.Visible = 'on';
                                app.Metabolites_results_DropDown.Visible = 'on';
                                app.Factor_DropDown.Visible = 'on';
                            end
                        end
                    else
                        app.UITable_results.Visible = 'off';
                        app.UITable_results_anova_2ways.Visible = 'off';
                        app.Metabolites_results_DropDown.Visible = 'off';
                        app.Factor_DropDown.Visible = 'off';
                        app.UITable_data.Visible = 'on';
                        app.Metabolites_data_DropDown.Visible = 'on';
                        app.Data_results_DropDown.Value = 'Data';
                        return
                    end
                end
                if any(cellfun(@isempty,msg))
                    app.Save_figure_Button.Enable = 'on';
                    app.Save_results_Button.Enable = 'on';
                    app.UITable_data.Visible = 'off';
                    app.Avg_Single_Button.Visible = 'on';
                    app.Metabolites_data_DropDown.Visible = 'off';
                    app.Data_results_DropDown.Value = 'Results';
                else
                    app.ANOVA_2ways_results = 0;
                    app.T_test_results = 0;
                    app.UITable_results.Visible = 'off';
                    app.UITable_results_anova_2ways.Visible = 'off';
                    app.Metabolites_results_DropDown.Visible = 'off';
                    app.Factor_DropDown.Visible = 'off';
                    app.UITable_data.Visible = 'on';
                    app.Avg_Single_Button.Visible = 'off';
                    app.Metabolites_data_DropDown.Visible = 'on';
                    app.Data_results_DropDown.Value = 'Data';
                end
            end
        end

        % Value changed function: p_value_sided_Button
        function p_value_sided_ButtonValueChanged(app, event)
            value = app.p_value_sided_Button.Value;
            if value
                app.p_value_sided_Button.Text = 'Two-sided p-value';
            else
                app.p_value_sided_Button.Text = 'One-sided p-value';
            end
        end

        % Value changed function: Data_results_DropDown
        function Data_results_DropDownValueChanged(app, event)
            value = app.Data_results_DropDown.Value;
            app.Save_figure_Button.Enable = 'off';
            app.Save_results_Button.Enable = 'off';
            if strcmp('Data',value) % RAW data
                app.UITable_data.Visible = 'on';
                app.UITable_results.Visible = 'off';
                app.UITable_results_anova_2ways.Visible = 'off';
                app.Metabolites_data_DropDown.Visible = 'on';
                app.Metabolites_results_DropDown.Visible = 'off';
                app.Factor_DropDown.Visible = 'off';
                app.Avg_Single_Button.Visible = 'off';
                app.plot_data();
            elseif and(strcmp('Results',value),or(app.T_test_results,app.ANOVA_1way_results)) % T-test, ANOVA 1 way
                app.UITable_data.Visible = 'off';
                app.UITable_results.Visible = 'on';
                app.UITable_results_anova_2ways.Visible = 'off';
                app.Metabolites_data_DropDown.Visible = 'off';
                app.Metabolites_results_DropDown.Visible = 'off';
                app.Factor_DropDown.Visible = 'off';
                app.Save_figure_Button.Enable = 'on';
                app.Save_results_Button.Enable = 'on';
                app.Avg_Single_Button.Visible = 'on';
                app.plot_results();
            elseif and(strcmp('Results',value),app.ANOVA_2ways_results) % ANOVA 2 ways
                app.UITable_data.Visible = 'off';
                app.UITable_results.Visible = 'off';
                app.UITable_results_anova_2ways.Visible = 'on';
                app.Metabolites_data_DropDown.Visible = 'off';
                app.Metabolites_results_DropDown.Visible = 'on';
                app.Factor_DropDown.Visible = 'on';
                app.Save_figure_Button.Enable = 'on';
                app.Save_results_Button.Enable = 'on';
                app.Avg_Single_Button.Visible = 'on';
                app.plot_results();
            else
                app.Data_results_DropDown.Value = 'Data';
                app.UITable_data.Visible = 'on';
                app.UITable_results.Visible = 'off';
                app.UITable_results_anova_2ways.Visible = 'off';
                app.Metabolites_data_DropDown.Visible = 'on';
                app.Metabolites_results_DropDown.Visible = 'off';
                app.Factor_DropDown.Visible = 'off';
                app.Avg_Single_Button.Visible = 'off';
                app.plot_data();
            end
        end

        % Value changed function: Metabolites_data_DropDown
        function Metabolites_data_DropDownValueChanged(app, event)
            app.data_table();
            app.plot_data();
        end

        % Value changed function: Metabolites_results_DropDown
        function Metabolites_results_DropDownValueChanged(app, event)
            app.results_table();
            app.plot_results();
        end

        % Value changed function: Factor_DropDown
        function Factor_DropDownValueChanged(app, event)
            app.plot_results()
        end

        % Button pushed function: Save_figure_Button
        function Save_figure_ButtonPushed(app, event)
            if and(~isempty(app.save_dir),~isempty(app.save_foldername))
                if ~isfolder(fullfile(app.save_dir,app.save_foldername))
                    mkdir(fullfile(app.save_dir,app.save_foldername))
                end
                d = uiprogressdlg(app.UIFigure,"Title",'Save image','Message', ...
                    'Saving current displayed image in the result folder','Indeterminate','on');
                idx_conc = 1;
                if ~app.relative_conc_bool
                    idx_conc = 2;
                end
                idx_met = find(strcmp(app.Metabolites_results_DropDown.Items,app.Metabolites_results_DropDown.Value));
                % Load mean and std for each statistics
                if app.ANOVA_2ways_results
                    if strcmp(app.Factor_DropDown.Value,'Factor A')
                        if app.Avg_Single_Button.Value
                            mean = app.Stat_results(idx_met).mean_FactorA(:,idx_conc);
                            std = app.Stat_results(idx_met).std_FactorA(:,idx_conc);
                        else
                            mean = app.Stat_results(idx_met).data_mean_FactorA(:,:,idx_conc);
                            std = app.Stat_results(idx_met).data_std_FactorA(:,:,idx_conc);
                        end
                        N_groups = size(mean,1);
                        grp_list = app.names_group_factor_A;
                        titlename = [app.Metabolites_results_DropDown.Value ', Factor A : ' app.name_factor_A];
                    else
                        if app.Avg_Single_Button.Value
                            mean = app.Stat_results(idx_met).mean_FactorB(:,idx_conc);
                            std = app.Stat_results(idx_met).std_FactorB(:,idx_conc);
                        else
                            mean = app.Stat_results(idx_met).data_mean_FactorB(:,:,idx_conc);
                            std = app.Stat_results(idx_met).data_std_FactorB(:,:,idx_conc);
                        end
                        N_groups = size(mean,1);
                        grp_list = app.names_group_factor_B;
                        titlename = [app.Metabolites_results_DropDown.Value ', Factor B : ' app.name_factor_B];
                    end
                elseif app.ANOVA_1way_results
                    if app.Avg_Single_Button.Value
                        mean = app.Stat_results(idx_met).mean_FactorA(:,idx_conc);
                        std = app.Stat_results(idx_met).std_FactorA(:,idx_conc);
                    else
                        mean = app.Stat_results(idx_met).data_mean_FactorA(:,:,idx_conc);
                        std = app.Stat_results(idx_met).data_std_FactorA(:,:,idx_conc);
                    end
                    N_groups = size(mean,1);
                    grp_list = app.names_group_factor_A;
                    titlename = [app.Metabolites_results_DropDown.Value ', Factor A : ' app.name_factor_A];
                elseif app.T_test_results
                    if app.Avg_Single_Button.Value
                        mean = app.Stat_results(idx_met).mean_all(idx_conc);
                        std = app.Stat_results(idx_met).std_all(idx_conc);
                    else
                        mean = app.Stat_results(idx_met).mean_data(:,idx_conc).';
                        std = app.Stat_results(idx_met).std_data(:,idx_conc).';
                    end
                    titlename = [app.Metabolites_results_DropDown.Value ', Student T-test'];
                    N_groups = 1;
                end

                fs = figure('Visible','off'); % on to be visible
                figure(app.UIFigure)
                ax = axes(fs);

                % Index & Groups
                if N_groups == 1
                    index = (1:1).';
                    grp_list = {'T_test'};
                else
                    index = (1:2:2*N_groups).';
                end

                % Plot bar 
                grp_list = regexprep(grp_list,'_',' ');
                b = bar(ax,index,mean,0.5,'grouped','FaceColor',[200,225,231]/255);

                hold(ax,"on")
                % Error bars
                % Calculate the number of groups and number of bars in each group
                [ngroups,nbars] = size(mean);
                % Get the x coordinate of the bars
                x = nan(nbars, ngroups);
                for i = 1:nbars
                    x(i,:) = b(i).XEndPoints;
                end
                e = errorbar(ax,x.',mean,std,'x','CapSize',15,'LineWidth',2,'Color',[0 .5 .5]);

                % Axis and display settings
                if nbars == 1
                    b.BarWidth = 0.5;
                    e.CapSize = 25;
                end
                set(ax,'xtick',index,'xticklabel',grp_list)
                ax.XAxis.FontWeight = 'bold';
                ax.YAxis.FontWeight = 'bold';
                ax.PlotBoxAspectRatio = [1,0.65,0.65];
                if ngroups > 2
                    ax.XAxis.FontSize = 8;
                else
                    ax.XAxis.FontSize = 12;
                end
                if N_groups == 1
                    xlim(ax,[0.5,1.5])
                else
                    xlim(ax,[-0.5,2*N_groups+0.5])
                end
                if max(mean)+max(std) < 1
                    ylim(ax,[0,max(max(mean))+max(max(std))+1e-8])
                elseif and(max(mean) + max(std) > 1,~isnan(max(mean) + max(std)))
                    ylim(ax,[0,max(max(mean))+max(max(std))+1])
                end
                title(ax,titlename)
                hold(ax,"off")

                % Save figures
                if app.T_test_results
                    savename = ['T_test_',app.Metabolites_results_DropDown.Value];
                elseif app.ANOVA_1way_results
                    savename = ['ANOVA_1way_',app.Metabolites_results_DropDown.Value];
                elseif app.ANOVA_2ways_results
                    if strcmp(app.Factor_DropDown.Value,'Factor A')
                        savename = ['ANOVA_2ways_FA_',app.name_factor_A,'_',app.Metabolites_results_DropDown.Value];
                    else
                        savename = ['ANOVA_2ways_FB_',app.name_factor_B,'_',app.Metabolites_results_DropDown.Value];
                    end
                end
                if isfile(fullfile(app.save_dir,app.save_foldername,[savename,'.fig']))
                    i = 2;
                    while isfile(fullfile(app.save_dir,app.save_foldername,[savename,'_',num2str(i),'.fig']))
                        i = i + 1;
                    end
                    savename = [savename,'_',num2str(i)];
                end
                filename = fullfile(app.save_dir,app.save_foldername,[savename,'.png']);
                exportgraphics(fs,filename,'Resolution',600);
                filename = fullfile(app.save_dir,app.save_foldername,[savename,'.fig']);
                set(fs,'CreateFcn','set(gcbo,''Visible'',''on'')'); 
                savefig(fs,filename);
                close(fs)
                figure(app.UIFigure)
                close(d)
            else
                msg = {'Please enter a saving foldername and a directory'};
                uialert(app.UIFigure,msg,'Save figure','Icon','warning')
            end
        end

        % Button pushed function: Save_folder_Button
        function Save_folder_ButtonPushed(app, event)
            % Choose a directory for the result folder
            dir = uigetdir("Choose a directory");
            figure(app.UIFigure)
            if dir ~= 0
                if isfolder(dir)
                    app.save_dir = dir;
                end
            end
        end

        % Value changed function: Save_foldername_EditField
        function Save_foldername_EditFieldValueChanged(app, event)
            value = app.Save_foldername_EditField.Value;
            app.save_foldername = value;
        end

        % Button pushed function: Save_results_Button
        function Save_results_ButtonPushed(app, event)
            if and(~isempty(app.save_dir),~isempty(app.save_foldername))
                if ~isfolder(fullfile(app.save_dir,app.save_foldername))
                    mkdir(fullfile(app.save_dir,app.save_foldername))
                end
                if app.T_test_results
                    savename = 'T_test_stats';
                elseif app.ANOVA_1way_results
                    savename = 'ANOVA_1way_stats';
                elseif app.ANOVA_2ways_results
                    savename = 'ANOVA_2ways_stats';
                end
                if isfile(fullfile(app.save_dir,app.save_foldername,[savename,'.mat']))
                    i = 2;
                    while isfile(fullfile(app.save_dir,app.save_foldername,[savename,'_',num2str(i),'.mat']))
                        i = i + 1;
                    end
                    savename = [savename,'_',num2str(i)];
                end
                filename = fullfile(app.save_dir,app.save_foldername,[savename,'.mat']);
                stats = app.Stat_results;
                save(filename,'stats')
            else
                msg = {'Please enter a saving foldername and a directory'};
                uialert(app.UIFigure,msg,'Save figure','Icon','warning')
            end
        end

        % Value changed function: Avg_Single_Button
        function Avg_Single_ButtonValueChanged(app, event)
            if app.Avg_Single_Button.Value
                app.Avg_Single_Button.Text = 'Averaged';
            else
                app.Avg_Single_Button.Text = 'Single';
            end
            app.plot_results();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MRSI Statistics';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Stats.png');
            app.Parameters_Panel = uipanel(app.UIFigure);
            app.Preferences_Label = uilabel(app.Parameters_Panel);
            app.Preferences_Label.Text = 'Statistics MRSI';
            app.CalculateButton = uibutton(app.Parameters_Panel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @Calculate_ButtonPushed, true);
            app.CalculateButton.Enable = 'off';
            app.CalculateButton.Text = 'Calculate';
            app.MeanEditField = uieditfield(app.Parameters_Panel, 'numeric');
            app.MeanEditField.Limits = [0 Inf];
            app.MeanEditField.Visible = 'off';
            app.pvalueEditFieldLabel = uilabel(app.Parameters_Panel);
            app.pvalueEditFieldLabel.Text = 'p-value';
            app.pvalueEditField = uieditfield(app.Parameters_Panel, 'numeric');
            app.pvalueEditField.Value = 0.05;
            app.p_value_sided_Button = uibutton(app.Parameters_Panel, 'state');
            app.p_value_sided_Button.ValueChangedFcn = createCallbackFcn(app, @p_value_sided_ButtonValueChanged, true);
            app.p_value_sided_Button.Text = 'Two-sided p-value';
            app.p_value_sided_Button.Value = true;
            app.MeanEditFieldLabel = uilabel(app.Parameters_Panel);
            app.MeanEditFieldLabel.Visible = 'off';
            app.MeanEditFieldLabel.Text = 'Mean';
            app.Data_specific_Panel = uipanel(app.Parameters_Panel);
            app.ConcentrationsDropDown = uidropdown(app.Data_specific_Panel);
            app.ConcentrationsDropDown.Items = {'Relative', 'Absolute'};
            app.ConcentrationsDropDown.ValueChangedFcn = createCallbackFcn(app, @ConcentrationsDropDownValueChanged, true);
            app.ConcentrationsDropDown.Value = 'Relative';
            app.UITable_met = uitable(app.Data_specific_Panel);
            app.UITable_met.ColumnName = {'On/Off'; 'Metabolites'};
            app.UITable_met.RowName = {};
            app.UITable_met.SelectionType = 'row';
            app.UITable_met.ColumnEditable = true;
            app.UITable_met.CellEditCallback = createCallbackFcn(app, @UITable_met_CellEdit, true);
            app.UITable_met.Multiselect = 'off';
            app.ExperimentDropDownLabel = uilabel(app.Data_specific_Panel);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.SliceDropDownLabel = uilabel(app.Data_specific_Panel);
            app.SliceDropDownLabel.Text = 'Slice';
            app.Metabolite_list_Label = uilabel(app.Data_specific_Panel);
            app.Metabolite_list_Label.Text = 'Metabolite list';
            app.UITable_exp = uitable(app.Data_specific_Panel);
            app.UITable_exp.ColumnName = {'On/Off'; 'MRSI data'};
            app.UITable_exp.RowName = {};
            app.UITable_exp.SelectionType = 'row';
            app.UITable_exp.ColumnEditable = [true false];
            app.UITable_exp.CellEditCallback = createCallbackFcn(app, @UITable_exp_CellEdit, true);
            app.UITable_exp.Multiselect = 'off';
            app.SliceDropDown = uidropdown(app.Data_specific_Panel);
            app.SliceDropDown.Items = {'1'};
            app.SliceDropDown.ValueChangedFcn = createCallbackFcn(app, @Slice_DropDownValueChanged, true);
            app.SliceDropDown.Value = '1';
            app.ExperimentDropDown = uidropdown(app.Data_specific_Panel);
            app.ExperimentDropDown.Items = {};
            app.ExperimentDropDown.ValueChangedFcn = createCallbackFcn(app, @Experiment_DropDownValueChanged, true);
            app.ExperimentDropDown.Value = {};
            app.ConcentrationsDropDownLabel = uilabel(app.Data_specific_Panel);
            app.ConcentrationsDropDownLabel.Text = 'Concentrations';
            app.Select_experiments_Button = uibutton(app.Data_specific_Panel, 'push');
            app.Select_experiments_Button.ButtonPushedFcn = createCallbackFcn(app, @Select_experiments_ButtonPushed, true);
            app.Select_experiments_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'dir_icon.png');
            app.Select_experiments_Button.Text = 'Select result folders';
            app.Data_specific_Label = uilabel(app.Parameters_Panel);
            app.Data_specific_Label.Text = 'Data specific';
            app.Image_toolbox = uiimage(app.Parameters_Panel);
            app.Image_toolbox.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Logo_MRS4Brain_toolbox_square_2.png');
            app.Statistics_logo = uiimage(app.Parameters_Panel);
            app.Statistics_logo.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Stats.png');
            app.Display_Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Display_Panel);
            title(app.UIAxes, 'Data & Results')
            ylabel(app.UIAxes, 'Concentration')
            app.UIAxes.XTickLabel = '';
            app.Save_results_Button = uibutton(app.Display_Panel, 'push');
            app.Save_results_Button.ButtonPushedFcn = createCallbackFcn(app, @Save_results_ButtonPushed, true);
            app.Save_results_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'save.png');
            app.Save_results_Button.Enable = 'off';
            app.Save_results_Button.Text = 'Save results';
            app.Save_figure_Button = uibutton(app.Display_Panel, 'push');
            app.Save_figure_Button.ButtonPushedFcn = createCallbackFcn(app, @Save_figure_ButtonPushed, true);
            app.Save_figure_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'save.png');
            app.Save_figure_Button.Enable = 'off';
            app.Save_figure_Button.Text = 'Save figure';
            app.UITable_results_anova_2ways = uitable(app.Display_Panel);
            app.UITable_results_anova_2ways.ColumnName = {'Mean'; 'F'; 'P-value'};
            app.UITable_results_anova_2ways.RowName = {'Factor A'; 'Factor B'; 'Interaction A&B'};
            app.UITable_results = uitable(app.Display_Panel);
            app.UITable_results.ColumnName = {'Metabolites'; 'Mean'; 'T or F test'; 'P-value'};
            app.UITable_results.RowName = {};
            app.UITable_data = uitable(app.Display_Panel);
            app.UITable_data.ColumnName = {'Study'; 'Region'; 'Mean'; 'Std'};
            app.UITable_data.RowName = {};
            app.UITable_data.SelectionType = 'row';
            app.Factor_DropDown = uidropdown(app.Display_Panel);
            app.Factor_DropDown.Items = {'Factor A', 'Factor B'};
            app.Factor_DropDown.ValueChangedFcn = createCallbackFcn(app, @Factor_DropDownValueChanged, true);
            app.Factor_DropDown.Visible = 'off';
            app.Factor_DropDown.Value = 'Factor A';
            app.Metabolites_results_DropDown = uidropdown(app.Display_Panel);
            app.Metabolites_results_DropDown.Items = {'Met'};
            app.Metabolites_results_DropDown.ValueChangedFcn = createCallbackFcn(app, @Metabolites_results_DropDownValueChanged, true);
            app.Metabolites_results_DropDown.Visible = 'off';
            app.Metabolites_results_DropDown.Value = 'Met';
            app.Metabolites_data_DropDown = uidropdown(app.Display_Panel);
            app.Metabolites_data_DropDown.Items = {'Met'};
            app.Metabolites_data_DropDown.ValueChangedFcn = createCallbackFcn(app, @Metabolites_data_DropDownValueChanged, true);
            app.Metabolites_data_DropDown.Value = 'Met';
            app.Data_results_DropDown = uidropdown(app.Display_Panel);
            app.Data_results_DropDown.Items = {'Data', 'Results'};
            app.Data_results_DropDown.ValueChangedFcn = createCallbackFcn(app, @Data_results_DropDownValueChanged, true);
            app.Data_results_DropDown.Value = 'Data';
            app.Save_folder_Button = uibutton(app.Display_Panel, 'push');
            app.Save_folder_Button.ButtonPushedFcn = createCallbackFcn(app, @Save_folder_ButtonPushed, true);
            app.Save_folder_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'dir_icon.png');
            app.Save_folder_Button.Text = '';
            app.SavefoldernameEditFieldLabel = uilabel(app.Display_Panel);
            app.SavefoldernameEditFieldLabel.Text = 'Save folder name';
            app.Save_foldername_EditField = uieditfield(app.Display_Panel, 'text');
            app.Save_foldername_EditField.ValueChangedFcn = createCallbackFcn(app, @Save_foldername_EditFieldValueChanged, true);
            app.Avg_Single_Button = uibutton(app.Display_Panel, 'state');
            app.Avg_Single_Button.ValueChangedFcn = createCallbackFcn(app, @Avg_Single_ButtonValueChanged, true);
            app.Avg_Single_Button.Visible = 'off';
            app.Avg_Single_Button.Text = 'Averaged';
            app.Avg_Single_Button.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = statistics_MRSI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end