classdef volumetry_table_MRSI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        Save_Panel              matlab.ui.container.Panel
        SaveButton              matlab.ui.control.Button
        SavenameEditField       matlab.ui.control.EditField
        SavenameEditFieldLabel  matlab.ui.control.Label
        VolumetrytableLabel     matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
    end

    
    properties (Access = private)
        MainApp             % Parent app (MRS4Brain toolbox main app)
        table_volumetry     % Table of volumetry of each brain regions
        save_dir            % Directory of the saved table
    end
    
    methods (Access = private)
        
        function fill_table(app,Volumetry)
            % Function to fill the volumetry table with the image
            % segmentation found, using the labels
            vol_table_data = cell(length(Volumetry),4);
            for i = 1:length(Volumetry)
                vol_table_data{i,1} = char(Volumetry(i).Name);
                vol_table_data{i,2} = num2str(round(squeeze(sum(Volumetry(i).Left))*100)/100);
                vol_table_data{i,3} = num2str(round(squeeze(sum(Volumetry(i).Right))*100)/100);
                vol_table_data{i,4} = num2str(round(squeeze(sum(Volumetry(i).LR))*100)/100);
            end
%             assignin("base","vol_table_data",vol_table_data) % Check
            app.UITable.Data = vol_table_data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, Volumetry, save_dir)
            app.MainApp = mainapp;
            app.table_volumetry = Volumetry;
            app.save_dir = save_dir;
            app.fill_table(Volumetry);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            if isempty(app.SavenameEditField.Value)
                uialert(app.UIFigure,'Please choose a name first', ...
                    'Concentration table','Icon','warning')
                return
            end
            Volumetry = app.table_volumetry;
            save_filename = fullfile(app.save_dir,[app.SavenameEditField.Value,'.mat']);
            save(save_filename,"Volumetry"); % Save the Volumetry table in the save directory
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Brain volumetry';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'brain_volumetry.png');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Brain region'; 'Left'; 'Right'; 'L+R'};
            app.UITable.RowName = {};
            app.VolumetrytableLabel = uilabel(app.UIFigure);
            app.VolumetrytableLabel.Text = 'Volumetry table';
            app.Save_Panel = uipanel(app.UIFigure);
            app.SavenameEditFieldLabel = uilabel(app.Save_Panel);
            app.SavenameEditFieldLabel.Text = 'Save name';
            app.SavenameEditField = uieditfield(app.Save_Panel, 'text');
            app.SaveButton = uibutton(app.Save_Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Logo_MRS4Brain_toolbox_square_2.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = volumetry_table_MRSI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end