classdef voxel_selection_MRSI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        Panel                   matlab.ui.container.Panel
        SavenameEditField       matlab.ui.control.EditField
        SavenameEditFieldLabel  matlab.ui.control.Label
        Update_Button           matlab.ui.control.Button
        PosYEditField           matlab.ui.control.NumericEditField
        PosYEditFieldLabel      matlab.ui.control.Label
        PosXEditField           matlab.ui.control.NumericEditField
        PosXEditFieldLabel      matlab.ui.control.Label
        IndividualLabel         matlab.ui.control.Label
        ImportmaskButton        matlab.ui.control.Button
        ZoneLabel               matlab.ui.control.Label
        SavemaskButton          matlab.ui.control.Button
        Remove_zone_Button      matlab.ui.control.Button
        Select_zone_Button      matlab.ui.control.Button
        ChangevoxelButton       matlab.ui.control.StateButton
        MRSI_grid_UIAxes        matlab.ui.control.UIAxes
        MRI_image_UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        MainApp                 % Parent app (MRS4Brain toolbox mainapp)
        image_slice             % Current image size
        current_mask            % Current mask applied on MRSI voxels
        MatSize                 % MRSI Matrix Size
        slice_num               % Number of the current slice
        name_region             % Name of the studied brain region
        masks_folder            % Folder of the masks
        name_mask               % Name of the mask 
        pos_saved = [1,1]       % Saved position
    end
    
    
    methods (Access = private)
        
        function display_grid(app)
            % Axis settings
            cla(app.MRI_image_UIAxes)
            cla(app.MRSI_grid_UIAxes)

            % plot Background image slice
            if app.MainApp.mri_headprone
                im_MRI = imshow(app.image_slice(end:-1:1,:),'Parent',app.MRI_image_UIAxes); % existed --> Toi activated this
            else
                im_MRI = imshow(app.image_slice,'Parent',app.MRI_image_UIAxes);
            end
            im_MRI.AlphaData = 1; % change this value to change the background image transparency
            axis(app.MRI_image_UIAxes,'equal')

            % To flip the current mask, except whole brain mask at 9.4T: --> Toi
            if strcmpi(app.MainApp.MRSI_RegionDropDown.Value,'Whole Brain')==0 & app.MainApp.mri_headprone==1
                app.current_mask = app.current_mask(end:-1:1,:);
            end

            % plot Current mask
            xrange = [1 app.MatSize(1)]; % imagesc only needs the endpoints
            yrange = [1 app.MatSize(2)];
            dx = diff(xrange)/(app.MatSize(2)-1);
            dy = diff(yrange)/(app.MatSize(1)-1);
            xg = linspace(xrange(1)-dx/2,xrange(2)+dx/2,app.MatSize(2)+1);
            yg = linspace(yrange(1)-dy/2,yrange(2)+dy/2,app.MatSize(1)+1);
            im_MRSI = imagesc(app.MRSI_grid_UIAxes,app.current_mask);
            hold(app.MRSI_grid_UIAxes,'on')
            hm = mesh(app.MRSI_grid_UIAxes,xg,yg,zeros(app.MatSize + 1));
            hm.FaceColor = 'none';
            hm.EdgeColor = 'k';
            % im_MRSI.AlphaData = 0.2; % change this value to change the foreground image transparency
            im_MRSI.AlphaData = app.current_mask>0; % change this value to change the foreground image transparency
            axis(app.MRSI_grid_UIAxes,'equal')
            % Colormaps
            colormap(app.MRI_image_UIAxes,'gray')
            colormap(app.MRSI_grid_UIAxes,'jet')
        end
        
        function update_mask(app,B,select)
            % Method to update the mask with the changes applied
            if nargin < 3
                select = 0;
            end
            N_changes = size(B,1);
            for i = 1:N_changes
                if and(B(i,1) <= app.MatSize(1),B(i,2) <= app.MatSize(2))
                    if select == 1 % Adding voxels
                        app.current_mask(B(i,1),B(i,2)) = 1;
                    elseif select == 2 % Removing voxels
                        app.current_mask(B(i,1),B(i,2)) = 0;
                    else % Change voxel state
                        app.current_mask(B(i,1),B(i,2)) = 1 - app.current_mask(B(i,1),B(i,2));
                    end
                    app.pos_saved = B(i,:);
                end
            end
            app.PosXEditField.Value = app.MatSize(1) + 1 - app.pos_saved(1);
            app.PosYEditField.Value = app.pos_saved(2);
            app.display_grid();
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, image_slice, mask, slice_num, name_region)
            % app properties
            app.MainApp = mainapp;
            app.image_slice = image_slice;
            if app.MainApp.mri_headprone % existed --> Toi activated this
                app.current_mask = mask(end:-1:1,:);
            else
                app.current_mask = mask;
            end
            app.slice_num = slice_num;
            app.name_region = name_region;
            app.MatSize = size(mask);
            app.name_mask = [app.name_region];
            app.SavenameEditField.Value = app.name_mask;

            app.masks_folder = fullfile(app.MainApp.result_dir, ...
                app.MainApp.study_name,['Slice_N',app.slice_num],'Results','Masks');

            % app position
            mainapp_corner_tr = app.MainApp.UIFigure.Position(3:4) + ...
                app.MainApp.UIFigure.Position(1:2);
            currentapp_corner_tr = app.UIFigure.Position(3:4);
            app.UIFigure.Position(1:2) = mainapp_corner_tr - currentapp_corner_tr;
            
            app.display_grid();

            disableDefaultInteractivity(app.MRI_image_UIAxes)
            disableDefaultInteractivity(app.MRSI_grid_UIAxes)
        end

        % Value changed function: ChangevoxelButton
        function ChangevoxelButtonValueChanged(app, event)
            while app.ChangevoxelButton.Value
                p = drawpoint('Parent',app.MRSI_grid_UIAxes,'Deletable',1,'Visible','off');
                if app.ChangevoxelButton.Value
                    x = p.Position(1); y = p.Position(2);
                    delete(p)
                    B = floor([y,x] + [0.5,0.5]);
                    app.update_mask(B);
                end
            end
        end

        % Button pushed function: Select_zone_Button
        function Select_zone_ButtonPushed(app, event)
            t = findobj(app.MRSI_grid_UIAxes,'Type','images.roi.Point');
            delete(t)
            r1 = drawrectangle('Color',[1 0 0], ...
                'Parent',app.MRSI_grid_UIAxes);
            P = floor(r1.Position + [0.5,0.5,0.5,0.5]);
            delete(r1)
            [X,Y] = meshgrid(P(2):P(2)+P(4),P(1):P(1)+P(3));
            X = X(:); Y = Y(:);
            B = [X,Y];
            app.update_mask(B,1);
        end

        % Button pushed function: Remove_zone_Button
        function Remove_zone_ButtonPushed(app, event)
            t = findobj(app.MRSI_grid_UIAxes,'Type','images.roi.Point');
            delete(t)
            r1 = drawrectangle('Color',[0 0 1], ...
                'Parent',app.MRSI_grid_UIAxes);
            P = floor(r1.Position + [0.5,0.5,0.5,0.5]);
            delete(r1)
            [X,Y] = meshgrid(P(2):P(2)+P(4),P(1):P(1)+P(3));
            X = X(:); Y = Y(:);
            B = [X,Y];
            app.update_mask(B,2);
        end

        % Button pushed function: SavemaskButton
        function SavemaskButtonPushed(app, event)
            app.MainApp.current_mask = app.current_mask;
            app.MainApp.MRSI_Voxel_selection_Button.Enable = 'on';
            app.MainApp.original_region = 0;
            app.MainApp.display_MRSI();
            name_file = fullfile(app.masks_folder,[app.name_mask,'.mat']);
            save_new = 1;
            if ~isfolder(app.masks_folder)
                mkdir(app.masks_folder)
            end
            if isfile(name_file)
                temp = load(name_file);
                if temp.mask == app.current_mask
                    save_new = 0;
                end
            end
            if save_new
                mask = app.current_mask;
                save(name_file,'mask');
            end
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            selection = uiconfirm(app.UIFigure,'Would you like to save your mask before closing ?', ...
                'Voxel selection','Options',{'Yes','No','Cancel'},'DefaultOption',1,'CancelOption',3);
            switch selection
                case 'Yes'
                    if app.MainApp.mri_headprone % existed --> Toi activated this
                        app.MainApp.current_mask = app.current_mask(end:-1:1,:);
                    else
                        app.MainApp.current_mask = app.current_mask;
                    end
                    app.MainApp.MRSI_Voxel_selection_Button.Enable = 'on';
                    app.MainApp.original_region = 0;
                    app.MainApp.display_MRSI();
                    name_file = fullfile(app.masks_folder,[app.name_region,'.mat']);
                    save_new = 1;
                    if ~isfolder(app.masks_folder)
                        mkdir(app.masks_folder)
                    end
                    if isfile(name_file)
                        temp = load(name_file);
                        if temp.mask == app.current_mask
                            save_new = 0;
                        end
                    end
                    if save_new
                        mask = app.current_mask;
                        save(name_file,'mask');
                    end
                    delete(app)
                case 'No'
                    if app.MainApp.mri_headprone % existed --> Toi activated this
                        app.MainApp.current_mask = app.current_mask(end:-1:1,:);
                    else
                        app.MainApp.current_mask = app.current_mask;
                    end
                    app.MainApp.MRSI_Voxel_selection_Button.Enable = 'on';
                    app.MainApp.original_region = 0;
                    app.MainApp.display_MRSI();
                    delete(app)
            end
        end

        % Button pushed function: ImportmaskButton
        function ImportmaskButtonPushed(app, event)
            folder_results = fullfile(app.MainApp.result_dir, ...
                app.MainApp.study_name,['Slice_N',app.slice_num],'Results');
            if isfolder(fullfile(folder_results,'Masks'))
                folder_results = fullfile(folder_results,'Masks');
            end
            [file, path] = uigetfile({'*.mat','Mask'}, ...
                "Select the appropriate mask", ...
                folder_results);
            figure(app.MainApp.UIFigure)
            figure(app.UIFigure)
            ok = 0;
            if file ~= 0
                mask_file = fullfile(path,file);
                temp = load(mask_file);
                fieldnames_masks = fieldnames(temp);
                mask_imp = temp.(fieldnames_masks{1});
                if and(isstruct(mask_imp),isfield(mask_imp,'mask'))
                    if size(mask_imp.mask) == size(app.current_mask)
                        mask = mask_imp.mask;
                        ok = 1;
                    else
                        uialert(app.UIFigure,['The size of the mask do not ' ...
                            'correspond to the MRSI data studied'],'Import error')
                    end
                elseif ismatrix(mask_imp)
                    if size(mask_imp) == size(app.current_mask)
                        mask = mask_imp;
                        ok = 1;
                    else
                        uialert(app.UIFigure,['The size of the mask do not ' ...
                            'correspond to the MRSI data studied'],'Import error')
                    end
                else
                    uialert(app.UIFigure,'The selected file do not have a mask in its data','Import error')
                end
                if ok
                    selection = uiconfirm(app.UIFigure,'Would you like to remove the current mask or combine with the new mask ?', ...
                        'Voxel selection','Options',{'Remove','Combine'},'DefaultOption',1);
                    if strcmp(selection,'Remove')
                        app.current_mask = mask;
                        app.display_grid();
                    else
                        app.current_mask = logical(double(app.current_mask) + double(mask));
                        app.display_grid();
                    end
                end
            end
        end

        % Button pushed function: Update_Button
        function Edit_position_ValueChanged(app, event)
            Px = app.PosXEditField.Value;
            Py = app.PosYEditField.Value;
            B_temp = [app.MatSize(1) + 1 - Px,Py];
            if and(B_temp(1) <= app.MatSize(1),B_temp(2) <= app.MatSize(2))
                app.update_mask(B_temp);
            else
                app.PosXEditField.Value = app.pos_saved(1);
                app.PosYEditField.Value = app.pos_saved(2);
            end
        end

        % Value changed function: PosXEditField
        function PosXEditFieldValueChanged(app, event)
            value = app.PosXEditField.Value;
            if value > app.MatSize(1)
                app.PosXEditField.Value = app.pos_saved(1);
            end
        end

        % Value changed function: PosYEditField
        function PosYEditFieldValueChanged(app, event)
            value = app.PosYEditField.Value;
            if value > app.MatSize(2)
                app.PosYEditField.Value = app.pos_saved(2);
            end
        end

        % Value changed function: SavenameEditField
        function SavenameEditFieldValueChanged(app, event)
            app.name_mask = app.SavenameEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MRS4Brain Toolbox voxel selection';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'voxel_selection_new.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MRI_image_UIAxes = uiaxes(app.UIFigure);
            zlabel(app.MRI_image_UIAxes, 'Z')
            app.MRI_image_UIAxes.XTick = [];
            app.MRI_image_UIAxes.YTick = [];
            app.MRSI_grid_UIAxes = uiaxes(app.UIFigure);
            zlabel(app.MRSI_grid_UIAxes, 'Z')
            app.MRSI_grid_UIAxes.XTick = [];
            app.MRSI_grid_UIAxes.YTick = [];
            colormap(app.MRSI_grid_UIAxes, 'jet')
            app.Panel = uipanel(app.UIFigure);
            app.ChangevoxelButton = uibutton(app.Panel, 'state');
            app.ChangevoxelButton.ValueChangedFcn = createCallbackFcn(app, @ChangevoxelButtonValueChanged, true);
            app.ChangevoxelButton.Text = 'Change voxel';
            app.Select_zone_Button = uibutton(app.Panel, 'push');
            app.Select_zone_Button.ButtonPushedFcn = createCallbackFcn(app, @Select_zone_ButtonPushed, true);
            app.Select_zone_Button.Text = 'Select';
            app.Remove_zone_Button = uibutton(app.Panel, 'push');
            app.Remove_zone_Button.ButtonPushedFcn = createCallbackFcn(app, @Remove_zone_ButtonPushed, true);
            app.Remove_zone_Button.Text = 'Remove';
            app.SavemaskButton = uibutton(app.Panel, 'push');
            app.SavemaskButton.ButtonPushedFcn = createCallbackFcn(app, @SavemaskButtonPushed, true);
            app.SavemaskButton.Text = 'Save mask';
            app.ZoneLabel = uilabel(app.Panel);
            app.ZoneLabel.Text = 'Zone';
            app.ImportmaskButton = uibutton(app.Panel, 'push');
            app.ImportmaskButton.ButtonPushedFcn = createCallbackFcn(app, @ImportmaskButtonPushed, true);
            app.ImportmaskButton.Text = 'Import mask';
            app.IndividualLabel = uilabel(app.Panel);
            app.IndividualLabel.Text = 'Individual';
            app.PosXEditFieldLabel = uilabel(app.Panel);
            app.PosXEditFieldLabel.Text = 'Pos X';
            app.PosXEditField = uieditfield(app.Panel, 'numeric');
            app.PosXEditField.Limits = [1 Inf];
            app.PosXEditField.RoundFractionalValues = 'on';
            app.PosXEditField.ValueDisplayFormat = '%.0f';
            app.PosXEditField.ValueChangedFcn = createCallbackFcn(app, @PosXEditFieldValueChanged, true);
            app.PosXEditField.Value = 1;
            app.PosYEditFieldLabel = uilabel(app.Panel);
            app.PosYEditFieldLabel.Text = 'Pos Y';
            app.PosYEditField = uieditfield(app.Panel, 'numeric');
            app.PosYEditField.Limits = [1 Inf];
            app.PosYEditField.RoundFractionalValues = 'on';
            app.PosYEditField.ValueDisplayFormat = '%.0f';
            app.PosYEditField.ValueChangedFcn = createCallbackFcn(app, @PosYEditFieldValueChanged, true);
            app.PosYEditField.Value = 1;
            app.Update_Button = uibutton(app.Panel, 'push');
            app.Update_Button.ButtonPushedFcn = createCallbackFcn(app, @Edit_position_ValueChanged, true);
            app.Update_Button.Icon = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'update.png');
            app.Update_Button.Text = '';
            app.SavenameEditFieldLabel = uilabel(app.Panel);
            app.SavenameEditFieldLabel.Text = 'Save name';
            app.SavenameEditField = uieditfield(app.Panel, 'text');
            app.SavenameEditField.ValueChangedFcn = createCallbackFcn(app, @SavenameEditFieldValueChanged, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Others', 'Logos_icons', 'Logo_MRS4Brain_toolbox_square_2.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = voxel_selection_MRSI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end